classdef visualCategorization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RefreshtrialsallsubsButton      matlab.ui.control.Button
        TEPRButtonGroup                 matlab.ui.container.ButtonGroup
        PercentageButton                matlab.ui.control.RadioButton
        ZscoreButton                    matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        Switch                          matlab.ui.control.Switch
        FULLANALYSISButton              matlab.ui.control.Button
        RTlockCheckBox                  matlab.ui.control.CheckBox
        EXPORTTOCSVButton               matlab.ui.control.Button
        RefreshmediansallsubsButton     matlab.ui.control.Button
        ResetinterpolationButton        matlab.ui.control.Button
        ProbetheappButton               matlab.ui.control.Button
        RunINTRAgroupanalysiswithselectedtrialsButton  matlab.ui.control.Button
        RefreshtrialsselectedsubButton  matlab.ui.control.Button
        RefreshmedianselectedsubButton  matlab.ui.control.Button
        RunINTERgroupanalysiswithselectedtrialsButton  matlab.ui.control.Button
        FilteringparametersButton       matlab.ui.control.Button
        ProcessingmethodButtonGroup     matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        DefaultButton                   matlab.ui.control.RadioButton
        NoprocessingButton              matlab.ui.control.RadioButton
        MultiselectCheckBox             matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        LeftxdiameterTab                matlab.ui.container.Tab
        UIAxes_LDX_2_processed          matlab.ui.control.UIAxes
        UIAxes_LDX_1_processed          matlab.ui.control.UIAxes
        UIAxes_LDX_1_raw                matlab.ui.control.UIAxes
        UIAxes_LDX_2_raw                matlab.ui.control.UIAxes
        LeftydiameterTab                matlab.ui.container.Tab
        UIAxes_LDY_2_processed          matlab.ui.control.UIAxes
        UIAxes_LDY_1_processed          matlab.ui.control.UIAxes
        UIAxes_LDY_1_raw                matlab.ui.control.UIAxes
        UIAxes_LDY_2_raw                matlab.ui.control.UIAxes
        RightxdiameterTab               matlab.ui.container.Tab
        UIAxes_RDX_2_processed          matlab.ui.control.UIAxes
        UIAxes_RDX_1_processed          matlab.ui.control.UIAxes
        UIAxes_RDX_1_raw                matlab.ui.control.UIAxes
        UIAxes_RDX_2_raw                matlab.ui.control.UIAxes
        RightydiameterTab               matlab.ui.container.Tab
        UIAxes_RDY_2_processed          matlab.ui.control.UIAxes
        UIAxes_RDY_1_processed          matlab.ui.control.UIAxes
        UIAxes_RDY_1_raw                matlab.ui.control.UIAxes
        UIAxes_RDY_2_raw                matlab.ui.control.UIAxes
        MeanxdiameterTab                matlab.ui.container.Tab
        CleanedCheckBox                 matlab.ui.control.CheckBox
        UIAxes_MeanX_2_processed        matlab.ui.control.UIAxes
        UIAxes_MeanX_1_processed        matlab.ui.control.UIAxes
        UIAxes_MeanX_1_raw              matlab.ui.control.UIAxes
        UIAxes_MeanX_2_raw              matlab.ui.control.UIAxes
        MeanydiameterTab                matlab.ui.container.Tab
        CleanedCheckBox_2               matlab.ui.control.CheckBox
        UIAxes_MeanY_2_processed        matlab.ui.control.UIAxes
        UIAxes_MeanY_1_processed        matlab.ui.control.UIAxes
        UIAxes_MeanY_1_raw              matlab.ui.control.UIAxes
        UIAxes_MeanY_2_raw              matlab.ui.control.UIAxes
        LeftsurfaceareaTab              matlab.ui.container.Tab
        CleanedCheckBox_3               matlab.ui.control.CheckBox
        UIAxes_LSurf_2_processed        matlab.ui.control.UIAxes
        UIAxes_LSurf_1_processed        matlab.ui.control.UIAxes
        UIAxes_LSurf_1_raw              matlab.ui.control.UIAxes
        UIAxes_LSurf_2_raw              matlab.ui.control.UIAxes
        RightsurfaceareaTab             matlab.ui.container.Tab
        CleanedCheckBox_4               matlab.ui.control.CheckBox
        UIAxes_RSurf_2_processed        matlab.ui.control.UIAxes
        UIAxes_RSurf_1_processed        matlab.ui.control.UIAxes
        UIAxes_RSurf_1_raw              matlab.ui.control.UIAxes
        UIAxes_RSurf_2_raw              matlab.ui.control.UIAxes
        AlltrialsCheckBox               matlab.ui.control.CheckBox
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        TrialsLabel                     matlab.ui.control.Label
        SubjectcodesLabel               matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        ListBox                         matlab.ui.control.ListBox
        LoadButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        % Description
      
        % signal
            signalTypes
            conditions
            testedConditions
            allTailHypothesis
            testedConditionsTailHypothesis
            testedConditionsTailHypothesisPermutest
            metricsName;
            testedConditionsVectorForDisplayFunction
            
            
        % trials 
            nParticipant 
            nTrials
            pathOfEachSubject
            whosYoung
            whosOld
        
            recognitionRates
            categorizationRates
            
            numberOfRKG
            
            everyTrial
            isThisTrialValidOrNot
            
            everyTimeBlock
            everyRawSignalDiameterBlock
            everyRawSignalBlockIndexes
            everyRawTime
            everyRawSignalGaze
            everyRawSignalDiameter
            everyRawLabel

            medianPupilResponses
            medianPupilResponsesOld
            medianPupilResponsesYoung
            
            
            meanPupilResponsesOld
            meanPupilResponsesYoung
            
            computedMeanForConditionsDifferencesYoung
            computedMeanForConditionsDifferencesOld
            
            meanResponseTimes
            meanResponseTimesOld
            meanResponseTimesYoung
            
            pupilMetrics
            pupilMetricsOld
            pupilMetricsYoung
            
            meanBaselineValue
            meanBaselineValueYoung
            meanBaselineValueOld


            significanceInterGroup
            bayesFactorInterGroup
            pValueInterGroup
            
            significanceIntraGroupYoung
            bayesFactorIntraGroupYoung
            pValueIntraGroupYoung
            
            significanceIntraGroupOld
            bayesFactorIntraGroupOld
            pValueIntraGroupOld
            
            whoIsNanIntraGroupYoung
            whoIsNanIntraGroupOld 
            
            significanceInterGroupClustersBasedPermutation
            significanceIntraGroupYoungClustersBasedPermutation
            significanceIntraGroupOldClustersBasedPermutation
            
            significanceInterGroup_ClustersBasedPermutation
             clusterCharacteristicsInterGroup            
            significanceIntraGroupYoung_ClustersBasedPermutation
             clusterCharacteristicsIntraGroupYoung
            significanceIntraGroupOld_ClustersBasedPermutation
             clusterCharacteristicsIntraGroupOld

            significanceResponseTimeInterGroup 
            significanceResponseTimeIntraGroupYoung
            significanceResponseTimeIntraGroupOld
            
            significancePupilMetricsInterGroup 
            pValuePupilMetricsInterGroup
            
            significancePupilMetricsIntraGroupYoung
            pValuePupilMetricsIntraGroupYoung
            
            significancePupilMetricsIntraGroupOld
            pValuePupilMetricsIntraGroupOld
            
            matrixOfDifferencesBetweenConditionsIntraGroupYoung
            significanceMatrixOfDifferencesIntraGroupYoung
            bayesFactorMatrixOfDifferencesIntraGroupYoung
            pValueMatrixOfDifferencesIntraGroupYoung            
            significanceMatrixOfDifferencesIntraGroupYoung_CBPT
            matrixContainingMediansSubstractedBetweenCdtsYoung
            
            matrixOfDifferencesBetweenConditionsIntraGroupOld
            significanceMatrixOfDifferencesIntraGroupOld
            bayesFactorMatrixOfDifferencesIntraGroupOld
            pValueMatrixOfDifferencesIntraGroupOld
            significanceMatrixOfDifferencesIntraGroupOld_CBPT
            matrixContainingMediansSubstractedBetweenCdtsOld
            
            significanceMatricesOfDifferencesInterGroup
             clusterCharacteristicsInterGroupDifferences
            bayesFactorMatricesOfDifferencesInterGroup
            pValueMatricesOfDifferencesInterGroup
            significanceMatricesOfDifferencesInterGroup_CBPT
            
           
        % plotting parameters
            selectedTrialsForEachSubjectAndSignalTypes %Which trials to use to compute the median
            howManyTrialsHaveBeenUsedToComputeMedian 
            allTrialsForEachSubjectAndSignalTypes
            multiSelectForEachSubjectAndSignalTypes
            selectAllForEachSubjectAndSignalTypes
            colors
            
            
        % other apps
            dialogBoxAppObject
        
        % saving parameters
            savePath
            
        % miscellaneous
            appDir 
            
    end
    
    properties (Access = public)
        hampelParameters 
        samplingFrequency  
        trialDuration
        sigmaForConfidenceInterval
        numberOfClusters
        numberOfPermutations
        timeWindowForHandmadeCBPT
        chanceLevel
        pThresholdCluster
        trialThreshold
        interpolationThreshold
    end
    
    methods (Access = public)
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%             %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%   PUBLIC    %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%             %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     

        function updatePlotTrials(app)
            
            %We first get the value of the chosen subject
            selectedSubject = app.ListBox.Value;
            %We then take his alphabetical order (i.e. indexSubject) in order to pick the
            %right trial structure located in "everyTrial"
            indexSubject = find( selectedSubject == string(app.ListBox.Items) );
            %Structure containing everything important
            selectedSubjectTrial=app.everyTrial{indexSubject};
            
            %We get the chosen trials
            indexTrials = app.ListBox_2.Value;
            numberOfChosenTrials=length(indexTrials);
                       
            %Then we plot
            samplingFrequency=app.samplingFrequency;
            %We plot the right type of signal (LDx, RDY, etc..)
            %depending on the chosen tab (which is string typed).
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes);    
            
             
            %We process the signals if asked to (one method has been
            %selected)
            methodName=string(app.ProcessingmethodButtonGroup.SelectedObject.Text);
            processingByChosenMethod(app, methodName, indexSubject, indexTrials, indexSignalType)   
            
            %If no trial is chosen, then we clear axis
            if numberOfChosenTrials==0
                switch indexSignalType 
                case 1    
                    cla(app.UIAxes_LDX_1_raw)
                case 2                   
                    cla(app.UIAxes_LDY_1_raw)
                case 3
                    cla(app.UIAxes_RDX_1_raw)
                case 4
                    cla(app.UIAxes_RDY_1_raw)                       
                case 5
                    cla(app.UIAxes_MeanX_1_raw)
                case 6
                    cla(app.UIAxes_MeanY_1_raw)
                case 7
                    cla(app.UIAxes_LSurf_1_raw)
                case 8
                    cla(app.UIAxes_RSurf_1_raw)
                end
            end
            
            
            for cpt=1:numberOfChosenTrials
                
                %We treat each trial at a time
                bufferTrial=selectedSubjectTrial(str2num(indexTrials{cpt})); 
                
                %We define a X and a Y function just for convenience
                nbPts=length(bufferTrial.time);
                x=(1:nbPts)/samplingFrequency;
                y=bufferTrial.rawSignalDiameter(:,indexSignalType); %(:,3) if we plot RDx for instance
                
                %We do the same but for processed signals
                switch indexSignalType
                    
                    case 1
                
                        plot(app.UIAxes_LDX_1_raw,x,y);
                        %legend(app.UIAxes_LDX_1_raw) %legend takes too
                        %much space on graph
                        hold(app.UIAxes_LDX_1_raw, 'on')
                        axis(app.UIAxes_LDX_1_raw, 'tight')

                    case 2
                        
                        plot(app.UIAxes_LDY_1_raw,x,y);
                        hold(app.UIAxes_LDY_1_raw, 'on')
                        axis(app.UIAxes_LDY_1_raw, 'tight')
                        
                    case 3
                        
                        plot(app.UIAxes_RDX_1_raw,x,y);
                        hold(app.UIAxes_RDX_1_raw, 'on')
                        axis(app.UIAxes_RDX_1_raw, 'tight')
                        
                    case 4
                        
                        plot(app.UIAxes_RDY_1_raw,x,y);
                        hold(app.UIAxes_RDY_1_raw, 'on')
                        axis(app.UIAxes_RDY_1_raw, 'tight')
                        
                    case 5

                        plot(app.UIAxes_MeanX_1_raw,x,y);
                        hold(app.UIAxes_MeanX_1_raw, 'on')
                        axis(app.UIAxes_MeanX_1_raw, 'tight')
                        
                    case 6
                        
                        plot(app.UIAxes_MeanY_1_raw,x,y);
                        hold(app.UIAxes_MeanY_1_raw, 'on')
                        axis(app.UIAxes_MeanY_1_raw, 'tight')
                        
                    case 7
                        
                        plot(app.UIAxes_LSurf_1_raw,x,y);
                        hold(app.UIAxes_LSurf_1_raw, 'on')
                        axis(app.UIAxes_LSurf_1_raw, 'tight')
                        
                    case 8
                        
                        plot(app.UIAxes_RSurf_1_raw,x,y);
                        hold(app.UIAxes_RSurf_1_raw, 'on')
                        axis(app.UIAxes_RSurf_1_raw, 'tight')
                end
                
            end
            
            %Holding off the plot
            switch indexSignalType 
                case 1    
                    hold(app.UIAxes_LDX_1_raw, 'off')
                case 2
                    hold(app.UIAxes_LDY_1_raw, 'off')
                case 3
                    hold(app.UIAxes_RDX_1_raw, 'off')
                case 4
                    hold(app.UIAxes_RDY_1_raw, 'off')                        
                case 5
                    hold(app.UIAxes_MeanX_1_raw, 'off')
                case 6
                    hold(app.UIAxes_MeanY_1_raw, 'off')
                case 7
                    hold(app.UIAxes_LSurf_1_raw, 'off')
                case 8
                    hold(app.UIAxes_RSurf_1_raw, 'off')
            end
   
            end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
        
        function results = updatePlotMedians(app)
        
            %We plot the right type of signal (LDx, RDY, etc..)
            %depending on the chosen tab (which is string typed).
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            samplingFrequency=app.samplingFrequency;
            nbPts=length(app.medianPupilResponses{1}.raw);
            x=(1:nbPts)/samplingFrequency;
            
            numberOfMediansComputedRaw=0;
            numberOfMediansComputedProcessed=0;

            for cptParticipant = 1:app.nParticipant
                
                y_raw(:,cptParticipant)=app.medianPupilResponses{cptParticipant}.raw(:,indexSignalType,1); 
                y_processed(:,cptParticipant)=app.medianPupilResponses{cptParticipant}.clean(:,indexSignalType,1);


                if ~isnan(y_raw(1,cptParticipant))
                    numberOfMediansComputedRaw=numberOfMediansComputedRaw+1;
                end
                
                if ~isnan(y_processed(1,cptParticipant))
                    numberOfMediansComputedProcessed=numberOfMediansComputedProcessed+1;
                end
                
                youngOrOld = convertStringsToChars(app.pathOfEachSubject(cptParticipant));
                youngOrOld=youngOrOld(end-2);
                
                if youngOrOld=='j'
                    curveColor='green';
                else
                    curveColor='blue';
                end
                
                switch indexSignalType
                    
                    case 1
                
                        plot(app.UIAxes_LDX_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LDX_2_raw, 'on')
                        axis(app.UIAxes_LDX_2_raw, 'tight')
                        
                        plot(app.UIAxes_LDX_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LDX_2_processed, 'on')
                        axis(app.UIAxes_LDX_2_processed, 'tight')
                        
                    case 2
                        
                        plot(app.UIAxes_LDY_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LDY_2_raw, 'on')
                        axis(app.UIAxes_LDY_2_raw, 'tight')
                        
                        plot(app.UIAxes_LDY_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LDY_2_processed, 'on')
                        axis(app.UIAxes_LDY_2_processed, 'tight')
                        
                    case 3
                        
                        plot(app.UIAxes_RDX_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RDX_2_raw, 'on')
                        axis(app.UIAxes_RDX_2_raw, 'tight')
                        
                        plot(app.UIAxes_RDX_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RDX_2_processed, 'on')
                        axis(app.UIAxes_RDX_2_processed, 'tight')
                        
                    case 4
                        
                        plot(app.UIAxes_RDY_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RDY_2_raw, 'on')
                        axis(app.UIAxes_RDY_2_raw, 'tight')
                        
                        plot(app.UIAxes_RDY_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RDY_2_processed, 'on')
                        axis(app.UIAxes_RDY_2_processed, 'tight')
                        
                    case 5

                        plot(app.UIAxes_MeanX_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_MeanX_2_raw, 'on')
                        axis(app.UIAxes_MeanX_2_raw, 'tight')
                        
                        plot(app.UIAxes_MeanX_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_MeanX_2_processed, 'on')
                        axis(app.UIAxes_MeanX_2_processed, 'tight')
                        
                    case 6
                        
                        plot(app.UIAxes_MeanY_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_MeanY_2_raw, 'on')
                        axis(app.UIAxes_MeanY_2_raw, 'tight')
                        
                        plot(app.UIAxes_MeanY_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_MeanY_2_processed, 'on')
                        axis(app.UIAxes_MeanY_2_processed, 'tight')
                        
                    case 7
                        
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LSurf_2_raw, 'on')
                        axis(app.UIAxes_LSurf_2_raw, 'tight')
                        
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_LSurf_2_processed, 'on')
                        axis(app.UIAxes_LSurf_2_processed, 'tight')
                        
                    case 8
                        
                        plot(app.UIAxes_RSurf_2_raw,x,y_raw(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RSurf_2_raw, 'on')
                        axis(app.UIAxes_RSurf_2_raw, 'tight')
                        
                        plot(app.UIAxes_RSurf_2_processed,x,y_processed(:,cptParticipant), "Color", curveColor, "LineWidth", 0.75);
                        hold(app.UIAxes_RSurf_2_processed, 'on')
                        axis(app.UIAxes_RSurf_2_processed, 'tight')
                end
                
            end
            
            % Confidence interval : 1 => 68% ; 2 => 95% ; 3 => 99.7%
            nSigma=app.sigmaForConfidenceInterval;
            
            y_raw_mean=nanmean(y_raw,2);
            y_raw_lowerConfidenceInterval=y_raw_mean-nSigma * nanstd(y_raw,0,2)/sqrt(numberOfMediansComputedRaw);
            y_raw_upperConfidenceInterval=y_raw_mean+nSigma * nanstd(y_raw,0,2)/sqrt(numberOfMediansComputedRaw);
            
            y_processed_mean=nanmean(y_processed,2);
            y_processed_lowerConfidenceInterval=y_processed_mean-nSigma * nanstd(y_processed,0,2)/sqrt(numberOfMediansComputedProcessed);
            y_processed_upperConfidenceInterval=y_processed_mean+nSigma * nanstd(y_processed,0,2)/sqrt(numberOfMediansComputedProcessed);
               
            switch indexSignalType
                    
                    case 1
                
                        plot(app.UIAxes_LDX_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LDX_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LDX_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LDX_2_raw, 'tight')
            
                        plot(app.UIAxes_LDX_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LDX_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LDX_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LDX_2_processed, 'tight')  
                                    
                    case 2
                        
                        plot(app.UIAxes_LDY_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LDY_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LDY_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LDY_2_raw, 'tight')
            
                        plot(app.UIAxes_LDY_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LDY_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LDY_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LDY_2_processed, 'tight')  
                        
                    case 3
                        
                        plot(app.UIAxes_RDX_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_RDX_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_RDX_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_RDX_2_raw, 'tight')
            
                        plot(app.UIAxes_RDX_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_RDX_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_RDX_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_RDX_2_processed, 'tight')  
                        
                    case 4
                        
                        plot(app.UIAxes_RDY_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_RDY_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_RDY_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_RDY_2_raw, 'tight')
            
                        plot(app.UIAxes_RDY_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_RDY_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_RDY_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_RDY_2_processed, 'tight')  
                        
                    case 5

                        plot(app.UIAxes_MeanX_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_MeanX_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_MeanX_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_MeanX_2_raw, 'tight')
            
                        plot(app.UIAxes_MeanX_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_MeanX_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_MeanX_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_MeanX_2_processed, 'tight')  
                        
                    case 6
                        
                        plot(app.UIAxes_MeanY_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_MeanY_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_MeanY_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_MeanY_2_raw, 'tight')
            
                        plot(app.UIAxes_MeanY_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_MeanY_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_MeanY_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_MeanY_2_processed, 'tight')  
                        
                    case 7
                        
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LSurf_2_raw, 'tight')
            
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LSurf_2_processed, 'tight')
                        
                    case 8
                        
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_mean, "Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LSurf_2_raw,x,y_raw_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LSurf_2_raw, 'tight')
            
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_mean,"Color", 'red', "LineWidth", 3);
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_lowerConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        plot(app.UIAxes_LSurf_2_processed,x,y_processed_upperConfidenceInterval,"Color", 'red', "LineWidth", 2,'LineStyle','--');
                        axis(app.UIAxes_LSurf_2_processed, 'tight')
                end            

            
            hold(app.UIAxes_LDX_2_raw, 'off')
            hold(app.UIAxes_LDX_2_processed, 'off')
            hold(app.UIAxes_LDY_2_raw, 'off')
            hold(app.UIAxes_LDY_2_processed, 'off')
            hold(app.UIAxes_RDX_2_raw, 'off')
            hold(app.UIAxes_RDX_2_processed, 'off')
            hold(app.UIAxes_RDY_2_raw, 'off')
            hold(app.UIAxes_RDY_2_processed, 'off')
            hold(app.UIAxes_MeanX_2_raw, 'off')
            hold(app.UIAxes_MeanX_2_processed, 'off')
            hold(app.UIAxes_MeanY_2_raw, 'off')
            hold(app.UIAxes_MeanY_2_processed, 'off')
            hold(app.UIAxes_LSurf_2_raw, 'off')
            hold(app.UIAxes_LSurf_2_processed, 'off')
            hold(app.UIAxes_RSurf_2_raw, 'off')
            hold(app.UIAxes_RSurf_2_processed, 'off')

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
        
        function results = processingByChosenMethod(app, methodName, indexSubject, indexTrials, indexSignalType)
            
%             indexSubject
%             indexTrials
%             indexSignalType
                        
            timeBlockOfOfSelectedSubject=app.everyTimeBlock{indexSubject};
            rawSignalDiameterBlock=app.everyRawSignalDiameterBlock{indexSubject};
            rawSignalBlockIndexes=app.everyRawSignalBlockIndexes{indexSubject};
            
            rawSignalDiameter=app.everyRawSignalDiameter{indexSubject};
            detrend="detrend";


            for block=1:4
                bufferRawBlock=rawSignalDiameterBlock{block};
                bufferRawSignalType=bufferRawBlock(:,indexSignalType);
                cleanSignalDiameterBlock{block}=NaN(size(bufferRawSignalType));
                indexModifiedPointsBlock{block}.hampel=zeros(size(bufferRawSignalType));
                indexModifiedPointsBlock{block}.interpolation=zeros(size(bufferRawSignalType));
                indexModifiedPointsBlock{block}.total=zeros(size(bufferRawSignalType));


                switch methodName
                                    
                    case("Custom")
                    %Process the everyTrial structure via a custom method

                                    
                    case("Default")
                    %Process the everyTrial structure via default method
                    %(the one in the paper)
                        
                        %%IF WE WANT MEAN X DIAMETER, MEAN Y DIAMETER, LEFT SURFACE
                        %%AREA, AND RIGHT SURFACE AREA TO TAKE INTO ACCOUNT PROCESSED
                        %%SIGNALS
                            switch indexSignalType
                                
                                case 1
                                    [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 2
                                    [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 3
                                    [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 4
                                    [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 5 %mean x diameter
                                    if app.CleanedCheckBox.Value
                                        bufferRawSignalType1=bufferRawBlock(:,1);
                                        bufferRawSignalType2=bufferRawBlock(:,3);

                                        [cleanSignalDiameterBlock1{block} indexModifiedPointsBlock1{block} ]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} indexModifiedPointsBlock2{block} ]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= nanmean([cleanSignalDiameterBlock1{block}, cleanSignalDiameterBlock2{block}],2);
                                        indexModifiedPointsBlock{block}.hampel= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.hampel);
                                        indexModifiedPointsBlock{block}.interpolation= or(indexModifiedPointsBlock1{block}.interpolation, indexModifiedPointsBlock2{block}.interpolation);
                                        indexModifiedPointsBlock{block}.total= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.interpolation);

                                    else
                                        [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                    end
                                case 6 %mean y diameter
                                    if app.CleanedCheckBox_2.Value
                                        bufferRawSignalType1=bufferRawBlock(:,2);
                                        bufferRawSignalType2=bufferRawBlock(:,4);

                                        [cleanSignalDiameterBlock1{block} indexModifiedPointsBlock1{block} ]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} indexModifiedPointsBlock2{block} ]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= nanmean([cleanSignalDiameterBlock1{block}, cleanSignalDiameterBlock2{block}],2);
                                        indexModifiedPointsBlock{block}.hampel= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.hampel);
                                        indexModifiedPointsBlock{block}.interpolation= or(indexModifiedPointsBlock1{block}.interpolation, indexModifiedPointsBlock2{block}.interpolation);
                                        indexModifiedPointsBlock{block}.total= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.interpolation);

                                    else
                                        [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                    end
                                case 7 %left surface area
                                    if app.CleanedCheckBox_3.Value
                                        bufferRawSignalType1=bufferRawBlock(:,1);
                                        bufferRawSignalType2=bufferRawBlock(:,2);

                                        [cleanSignalDiameterBlock1{block} indexModifiedPointsBlock1{block} ]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} indexModifiedPointsBlock2{block} ]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= 0.25*cleanSignalDiameterBlock1{block} .* cleanSignalDiameterBlock2{block} * pi;
                                        indexModifiedPointsBlock{block}.hampel= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.hampel);
                                        indexModifiedPointsBlock{block}.interpolation= or(indexModifiedPointsBlock1{block}.interpolation, indexModifiedPointsBlock2{block}.interpolation);
                                        indexModifiedPointsBlock{block}.total= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.interpolation);

                                    else
                                        [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "surface", detrend);
                                    end
                                case 8 %right surface area
                                    if app.CleanedCheckBox_4.Value
                                        bufferRawSignalType1=bufferRawBlock(:,3);
                                        bufferRawSignalType2=bufferRawBlock(:,4);

                                        [cleanSignalDiameterBlock1{block} indexModifiedPointsBlock1{block} ]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} indexModifiedPointsBlock2{block} ]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= 0.25*cleanSignalDiameterBlock1{block} .* cleanSignalDiameterBlock2{block} * pi;
                                        indexModifiedPointsBlock{block}.hampel= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.hampel);
                                        indexModifiedPointsBlock{block}.interpolation= or(indexModifiedPointsBlock1{block}.interpolation, indexModifiedPointsBlock2{block}.interpolation);
                                        indexModifiedPointsBlock{block}.total= or(indexModifiedPointsBlock1{block}.hampel, indexModifiedPointsBlock2{block}.interpolation);

                                    else
                                        [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=processingMethod(bufferRawSignalType,app.hampelParameters, "surface", detrend);
                                    end
                            end
                        
                    case("No processing") 
                    %Does nothing
                            [cleanSignalDiameterBlock{block} indexModifiedPointsBlock{block} ]=noProcessing(bufferRawSignalType,app.hampelParameters);
                end
          
            end
            
            cleanSignalDiameter=rawSignalDiameter(:,indexSignalType);
            indexModifiedPoints=zeros(size(rawSignalDiameter));
            indexModifiedPointsByHampel=indexModifiedPoints;
            indexModifiedPointsByInterpolation=indexModifiedPoints;
            
            cleanSignalDiameter( rawSignalBlockIndexes(1):rawSignalBlockIndexes(2)) = cleanSignalDiameterBlock{1};
            cleanSignalDiameter( rawSignalBlockIndexes(3):rawSignalBlockIndexes(4)) = cleanSignalDiameterBlock{2};
            cleanSignalDiameter( rawSignalBlockIndexes(5):rawSignalBlockIndexes(6)) = cleanSignalDiameterBlock{3};
            cleanSignalDiameter( rawSignalBlockIndexes(7):rawSignalBlockIndexes(8)) = cleanSignalDiameterBlock{4};
            
            indexModifiedPoints( rawSignalBlockIndexes(1):rawSignalBlockIndexes(2)) = indexModifiedPointsBlock{1}.total;
            indexModifiedPoints( rawSignalBlockIndexes(3):rawSignalBlockIndexes(4)) = indexModifiedPointsBlock{2}.total;
            indexModifiedPoints( rawSignalBlockIndexes(5):rawSignalBlockIndexes(6)) = indexModifiedPointsBlock{3}.total;
            indexModifiedPoints( rawSignalBlockIndexes(7):rawSignalBlockIndexes(8)) = indexModifiedPointsBlock{4}.total;
            
            indexModifiedPointsByHampel( rawSignalBlockIndexes(1):rawSignalBlockIndexes(2)) = indexModifiedPointsBlock{1}.hampel;
            indexModifiedPointsByHampel( rawSignalBlockIndexes(3):rawSignalBlockIndexes(4)) = indexModifiedPointsBlock{2}.hampel;
            indexModifiedPointsByHampel( rawSignalBlockIndexes(5):rawSignalBlockIndexes(6)) = indexModifiedPointsBlock{3}.hampel;
            indexModifiedPointsByHampel( rawSignalBlockIndexes(7):rawSignalBlockIndexes(8)) = indexModifiedPointsBlock{4}.hampel;
            
            indexModifiedPointsByInterpolation( rawSignalBlockIndexes(1):rawSignalBlockIndexes(2)) = indexModifiedPointsBlock{1}.interpolation;
            indexModifiedPointsByInterpolation( rawSignalBlockIndexes(3):rawSignalBlockIndexes(4)) = indexModifiedPointsBlock{2}.interpolation;
            indexModifiedPointsByInterpolation( rawSignalBlockIndexes(5):rawSignalBlockIndexes(6)) = indexModifiedPointsBlock{3}.interpolation;
            indexModifiedPointsByInterpolation( rawSignalBlockIndexes(7):rawSignalBlockIndexes(8)) = indexModifiedPointsBlock{4}.interpolation;


            %We compute the undetrended baseline first, because since we detrend the
            %signals, we'd lose the baseline information
            computeBaselinePupilDiameterForEachTrial(app, indexSubject);    


            for cptTrial=1:app.nTrials
                
                app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameter(:,indexSignalType)=cleanSignalDiameter( app.everyTrial{indexSubject}(cptTrial).onset : app.everyTrial{indexSubject}(cptTrial).offset );
                app.everyTrial{indexSubject}(cptTrial).noise=app.everyTrial{indexSubject}(cptTrial).rawSignalDiameter - app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameter;
                app.everyTrial{indexSubject}(cptTrial).indexModifiedPoints(:,indexSignalType)=indexModifiedPoints(app.everyTrial{indexSubject}(cptTrial).onset : app.everyTrial{indexSubject}(cptTrial).offset);
                app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByInterpolation(:,indexSignalType)=indexModifiedPointsByInterpolation(app.everyTrial{indexSubject}(cptTrial).onset : app.everyTrial{indexSubject}(cptTrial).offset);
                app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByHampel(:,indexSignalType)=indexModifiedPointsByHampel(app.everyTrial{indexSubject}(cptTrial).onset : app.everyTrial{indexSubject}(cptTrial).offset);
%                 app.everyTrial{indexSubject}(cptTrial).cleanSignalBaseline(:,indexSignalType)=cleanSignalDiameter( app.everyTrial{indexSubject}(cptTrial).onsetBaseline : app.everyTrial{indexSubject}(cptTrial).onset);
                app.everyTrial{indexSubject}(cptTrial).baselineMeanValue(indexSignalType)=nanmean(cleanSignalDiameter( app.everyTrial{indexSubject}(cptTrial).onsetBaseline : app.everyTrial{indexSubject}(cptTrial).onset)) ;

                app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline=app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameter - app.everyTrial{indexSubject}(cptTrial).baselineMeanValue(indexSignalType);
                
                %SMOOTHING TRIALS
                if methodName ~= "No processing"
                    app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType)= smoothdata( app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType) , 'movmean' , 40);
                end
                bufferManualInterpolationPoints=app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByManualInterpolation(:,indexSignalType);
                if sum(bufferManualInterpolationPoints)
                    
                    bufferX=1:length(app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline);
                    bufferXtruncated=bufferX;
                    bufferYtruncated=app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType);
                    
                    bufferXtruncated(logical(bufferManualInterpolationPoints))=[];                    
                    bufferYtruncated(logical(bufferManualInterpolationPoints))=[];
                    
                    app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType)=interp1(bufferXtruncated,bufferYtruncated,bufferX, 'linear');
                    
                end
                
                %NORMALIZATION TO BASELINE (if necessary)
                TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                switch(TEPRtype)
                    case "Absolute"
                        %Nothing to do because it's detrended
                    
                    case "Z-score"
                        app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType)=zscore(app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType));
                    
                    case "Percentage"
                        app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType)=100*(app.everyTrial{indexSubject}(cptTrial).cleanSignalDiameterMinusBaseline(:,indexSignalType))/app.everyTrial{indexSubject}(cptTrial).undetrendedBaseline(indexSignalType) ;
                    end


%                 %Take into account the interpolation points if necessary
                app.everyTrial{indexSubject}(cptTrial).modifiedPointsPercentage(indexSignalType)=sum(app.everyTrial{indexSubject}(cptTrial).indexModifiedPoints(:,indexSignalType))/length(app.everyTrial{indexSubject}(cptTrial).indexModifiedPoints(:,indexSignalType));
                app.everyTrial{indexSubject}(cptTrial).modifiedPointsPercentageByHampel(indexSignalType)=sum(app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByHampel(:,indexSignalType))/length(app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByHampel(:,indexSignalType));
                app.everyTrial{indexSubject}(cptTrial).modifiedPointsPercentageByInterpolation(indexSignalType)=sum(app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByInterpolation(:,indexSignalType))/length(app.everyTrial{indexSubject}(cptTrial).indexModifiedPointsByInterpolation(:,indexSignalType));
                app.everyTrial{indexSubject}(cptTrial).undetrendedBaseline=NaN(size(app.everyTrial{indexSubject}(cptTrial).baselineMeanValue));
                %

            end

            
            %Display part
            numberOfChosenTrials=length(indexTrials);
            selectedSubjectTrial=app.everyTrial{indexSubject};
            
            if numberOfChosenTrials==0
                switch indexSignalType 
                case 1    
                    cla(app.UIAxes_LDX_1_processed)
                case 2                   
                    cla(app.UIAxes_LDY_1_processed)
                case 3
                    cla(app.UIAxes_RDX_1_processed)
                case 4
                    cla(app.UIAxes_RDY_1_processed)                       
                case 5
                    cla(app.UIAxes_MeanX_1_processed)
                case 6
                    cla(app.UIAxes_MeanY_1_processed)
                case 7
                    cla(app.UIAxes_LSurf_1_processed)
                case 8
                    cla(app.UIAxes_RSurf_1_processed)
                end
            end
            
            
            for cpt=1:numberOfChosenTrials
                
                %We treat each trial at a time
                bufferTrial=selectedSubjectTrial(str2num(indexTrials{cpt})); 
                
                %We define a X and a Y function just for convenience
                nbPts=length(bufferTrial.time);
                x=(1:nbPts)/app.samplingFrequency;
                y=bufferTrial.cleanSignalDiameterMinusBaseline(:,indexSignalType); %(:,3) if we plot RDx for instance
                
                %We do the same but for processed signals
                
                switch indexSignalType
                    
                    case 1
                
                        plot(app.UIAxes_LDX_1_processed,x,y);
                        %legend(app.UIAxes_LDX_1_raw) %legend takes too
                        %much space on graph
                        hold(app.UIAxes_LDX_1_processed, 'on')
                        axis(app.UIAxes_LDX_1_processed, 'tight')

                    case 2
                        
                        plot(app.UIAxes_LDY_1_processed,x,y);
                        hold(app.UIAxes_LDY_1_processed, 'on')
                        axis(app.UIAxes_LDY_1_processed, 'tight')
                        
                    case 3
                        
                        plot(app.UIAxes_RDX_1_processed,x,y);
                        hold(app.UIAxes_RDX_1_processed, 'on')
                        axis(app.UIAxes_RDX_1_processed, 'tight')
                        
                    case 4
                        
                        plot(app.UIAxes_RDY_1_processed,x,y);
                        hold(app.UIAxes_RDY_1_processed, 'on')
                        axis(app.UIAxes_RDY_1_processed, 'tight')
                        
                    case 5

                        plot(app.UIAxes_MeanX_1_processed,x,y);
                        hold(app.UIAxes_MeanX_1_processed, 'on')
                        axis(app.UIAxes_MeanX_1_processed, 'tight')
                        
                    case 6
                        
                        plot(app.UIAxes_MeanY_1_processed,x,y);
                        hold(app.UIAxes_MeanY_1_processed, 'on')
                        axis(app.UIAxes_MeanY_1_processed, 'tight')
                        
                    case 7
                        
                        plot(app.UIAxes_LSurf_1_processed,x,y);
                        hold(app.UIAxes_LSurf_1_processed, 'on')
                        axis(app.UIAxes_LSurf_1_processed, 'tight')
                        
                    case 8
                        
                        plot(app.UIAxes_RSurf_1_processed,x,y);
                        hold(app.UIAxes_RSurf_1_processed, 'on')
                        axis(app.UIAxes_RSurf_1_processed, 'tight')
                end
                
            end
            
            %Holding off the plot
            switch indexSignalType 
                case 1    
                    hold(app.UIAxes_LDX_1_processed, 'off')
                case 2
                    hold(app.UIAxes_LDY_1_processed, 'off')
                case 3
                    hold(app.UIAxes_RDX_1_processed, 'off')
                case 4
                    hold(app.UIAxes_RDY_1_processed, 'off')                        
                case 5
                    hold(app.UIAxes_MeanX_1_processed, 'off')
                case 6
                    hold(app.UIAxes_MeanY_1_processed, 'off')
                case 7
                    hold(app.UIAxes_LSurf_1_processed, 'off')
                case 8
                    hold(app.UIAxes_RSurf_1_processed, 'off')
            end


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     

        function results = computeMedianFromSelectedTrials(app)
            
        %   Takes the lines labeled selectedTrials of trialsOfSelectedSubject and
        %   compute the median response out of it
        
        %   We do this for every condition
        
        %   The medians are set to NaN by default
           
        indexConditionAll=find("All"==app.conditions);
        
        indexConditionCongruent=find("Congruent"==app.conditions);
        indexConditionIncongruent=find("Incongruent"==app.conditions);
        
        indexConditionAnimal=find("Animal"==app.conditions);
        indexConditionObject=find("Object"==app.conditions);
        
        indexConditionWellCategorized=find("Well categorized"==app.conditions);
        indexConditionBadlyCategorized=find("Badly categorized"==app.conditions);
        
        indexConditionRecognizedAboveCL=find("Recognized"==app.conditions);
        indexConditionForgottenAboveCL=find("Forgotten"==app.conditions);
        
            indexConditionRecognizedAboveCL_R=find("Recognized R"==app.conditions);
            indexConditionRecognizedAboveCL_K=find("Recognized K"==app.conditions);
            indexConditionRecognizedAboveCL_G=find("Recognized G"==app.conditions);
            
            indexConditionForgottenAboveCL_K=find("Forgotten K"==app.conditions);
            indexConditionForgottenAboveCL_G=find("Forgotten G"==app.conditions);
        
        indexConditionRecognizedBelowCL=find("Recognized below CL"==app.conditions);
        indexConditionForgottenBelowCL=find("Forgotten below CL"==app.conditions);
        
            indexConditionRecognizedBelowCL_R=find("Recognized R below CL"==app.conditions);
            indexConditionRecognizedBelowCL_K=find("Recognized K below CL"==app.conditions);
            indexConditionRecognizedBelowCL_G=find("Recognized G below CL"==app.conditions);
            
            indexConditionForgottenBelowCL_K=find("Forgotten K below CL"==app.conditions);
            indexConditionForgottenBelowCL_G=find("Forgotten G below CL"==app.conditions);
    
        %We get the subject
        indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
        %We get all his trials
        allTrialsOfSelectedSubject=app.everyTrial{indexSubject};
        %We get the chosen tab
        chosenTab=string(app.TabGroup.SelectedTab.Title); 
        indexSignalType=find(chosenTab==app.signalTypes); 
        %We get the selected trials
        selectedTrials = app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType};
        %We get the path of the subject
        participantPath=app.pathOfEachSubject{indexSubject};
        %We compute the recognition rate at V2
        cd(participantPath);
        txtV2=[dir('*Reconnaissance*').name];
        txtV2Path=[participantPath filesep txtV2];
        learnedSet=['set' allTrialsOfSelectedSubject(1).set];
        recognitionRateRecoTask=recognitionRate(learnedSet, txtV2Path);
        app.recognitionRates{indexSubject}=recognitionRateRecoTask;
                
        % Trials
        nAllSelectedTrials=length(selectedTrials); %It is worth the number of trials we've chosen
        nAllTrials=0;
        nCongruentTrials=0;
        nIncongruentTrials=0;
        nAnimalTrials=0;
        nObjectTrials=0;
        %Above chance level
        nRecognizedTrials=0;
                    nRecognizedTrials_R=0;
                    nRecognizedTrials_K=0;
                    nRecognizedTrials_G=0;
        nForgottenTrials=0;
                    nForgottenTrials_K=0;
                    nForgottenTrials_G=0;
        nWellCategorizedTrials=0;
        nBadlyCategorizedTrials=0;
        %Below chance level
        nRecognizedTrials_BelowCL=0;
                    nRecognizedTrials_R_BelowCL=0;
                    nRecognizedTrials_K_BelowCL=0;
                    nRecognizedTrials_G_BelowCL=0;
        nForgottenTrials_BelowCL=0;
                    nForgottenTrials_K_BelowCL=0;
                    nForgottenTrials_G_BelowCL=0;
        %categorization            
        nWellCategorizedTrials=0;
        nBadlyCategorizedTrials=0;
        
        %Initialization
        bufferNaN_matrix=NaN(length(allTrialsOfSelectedSubject(1).cleanSignalDiameterMinusBaseline(:,1)), app.nTrials);
        bufferNaN_vector=NaN(size(allTrialsOfSelectedSubject(1).cleanSignalDiameterMinusBaseline(:,1)));
    
        %All
        MatrixUsedForCalculationProcessed_conditionAll=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionAll=bufferNaN_matrix;
        %Congruent
        MatrixUsedForCalculationProcessed_conditionCongruent=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionCongruent=bufferNaN_matrix;
        %Incongruent
        MatrixUsedForCalculationProcessed_conditionIncongruent=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionIncongruent=bufferNaN_matrix;
        %Animal
        MatrixUsedForCalculationProcessed_conditionAnimal=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionAnimal=bufferNaN_matrix;        
        %Object
        MatrixUsedForCalculationProcessed_conditionObject=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionObject=bufferNaN_matrix;
        %Well catogorized    
        MatrixUsedForCalculationProcessed_conditionWellCateg=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionWellCateg=bufferNaN_matrix;
        %Badly catogorized    
        MatrixUsedForCalculationProcessed_conditionBadCateg=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionBadCateg=bufferNaN_matrix;
        
        
        %Recognized above chance level
        MatrixUsedForCalculationProcessed_conditionRecognized=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionRecognized=bufferNaN_matrix;
            %Recognized above chance level, with confidence R
            MatrixUsedForCalculationProcessed_conditionRecognized_R=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_R=bufferNaN_matrix;
            %Recognized above chance level, with confidence K
            MatrixUsedForCalculationProcessed_conditionRecognized_K=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_K=bufferNaN_matrix;
            %Recognized above chance level, with confidence G
            MatrixUsedForCalculationProcessed_conditionRecognized_G=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_G=bufferNaN_matrix;
        %Forgotten above chance level
        MatrixUsedForCalculationProcessed_conditionForgotten=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionForgotten=bufferNaN_matrix;
            %Forgotten above chance level, with confidence K
            MatrixUsedForCalculationProcessed_conditionForgotten_K=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionForgotten_K=bufferNaN_matrix;
            %Forgotten above chance level, with confidence G
            MatrixUsedForCalculationProcessed_conditionForgotten_G=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionForgotten_G=bufferNaN_matrix;

        
        %Recognized below chance level
        MatrixUsedForCalculationProcessed_conditionRecognized_BelowCL=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionRecognized_BelowCL=bufferNaN_matrix;
            %Recognized below chance level, with confidence R
            MatrixUsedForCalculationProcessed_conditionRecognized_R_BelowCL=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_R_BelowCL=bufferNaN_matrix;
            %Recognized below chance level, with confidence K
            MatrixUsedForCalculationProcessed_conditionRecognized_K_BelowCL=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_K_BelowCL=bufferNaN_matrix;
            %Recognized below chance level, with confidence G
            MatrixUsedForCalculationProcessed_conditionRecognized_G_BelowCL=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionRecognized_G_BelowCL=bufferNaN_matrix;
        %Forgotten below chance level
        MatrixUsedForCalculationProcessed_conditionForgotten_BelowCL=bufferNaN_matrix;
        MatrixUsedForCalculationRaw_conditionForgotten_BelowCL=bufferNaN_matrix;
            %Forgotten below chance level, with confidence K
            MatrixUsedForCalculationProcessed_conditionForgotten_K_BelowCL=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionForgotten_K_BelowCL=bufferNaN_matrix;
            %Forgotten below chance level, with confidence G
            MatrixUsedForCalculationProcessed_conditionForgotten_G_BelowCL=bufferNaN_matrix;
            MatrixUsedForCalculationRaw_conditionForgotten_G_BelowCL=bufferNaN_matrix;
        

                for cptTrial=1:nAllSelectedTrials
                
                    %We take the trial n°cptTrial of subject indexSubject
                    indexCurrentBufferTrial=str2num(selectedTrials{cptTrial});
                    currentBufferTrial=allTrialsOfSelectedSubject(indexCurrentBufferTrial);
                    
                    %We extract the different conditions
                    conditionForeground = currentBufferTrial.foreground;
                    conditionCongruence = currentBufferTrial.congruence;
                    conditionRecognition = currentBufferTrial.isRecognized;
                    conditionRecognitionTrustLevel = currentBufferTrial.trustLevel;
                    conditionCategorization = currentBufferTrial.categorizationResult;
                     
                    currentRawSignal=currentBufferTrial.rawSignalDiameter(:, indexSignalType );
                    currentCleanSignal=currentBufferTrial.cleanSignalDiameterMinusBaseline(:, indexSignalType );
   
                    %exclusion criterion for the interpolation rate
                    exclusionCriterion1 = currentBufferTrial.modifiedPointsPercentageByInterpolation(indexSignalType) > app.interpolationThreshold ;

                    %exclusion criterion for the recognition trials
                    exclusionCriterion2 = recognitionRateRecoTask(1) < app.chanceLevel ; 

                    %exclusion criterion for the baseline value
                    TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                    switch(TEPRtype)
                        case "Absolute"
                            if (indexSignalType == 7) || (indexSignalType == 8) %if surface
                                exclusionCriterion3 = abs(currentCleanSignal(1)) > 5000;
                            else
                                exclusionCriterion3 = abs(currentCleanSignal(1)) > 10; %if diameter
                            end
                            
                        case "Z-score"
                            exclusionCriterion3=abs(currentCleanSignal(1)) > 3;
                            
                        case "Percentage"
                            if (indexSignalType == 7) || (indexSignalType == 8) %if surface
                                exclusionCriterion3 = abs(currentCleanSignal(1)) > 10; %in percentage
                            else 
                                exclusionCriterion3 = abs(currentCleanSignal(1)) > 10; %if diameter
                            end
                        end
             
                    inclusionCriterion0=isnan(currentCleanSignal(1));
                
                    if (~exclusionCriterion1 && ~exclusionCriterion3) 

                    %%%all
                    nAllTrials=nAllTrials+1;
                    MatrixUsedForCalculationRaw_conditionAll(:,cptTrial)=currentRawSignal;
                    MatrixUsedForCalculationProcessed_conditionAll(:,cptTrial)=currentCleanSignal;
                    app.isThisTrialValidOrNot{indexSubject}(indexCurrentBufferTrial)=1;
                    
                        %%%congruence
                        if conditionCongruence == 'C'   %congruent
                            nCongruentTrials=nCongruentTrials+1;
                            MatrixUsedForCalculationRaw_conditionCongruent(:,nCongruentTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionCongruent(:,nCongruentTrials)=currentCleanSignal;
                        elseif conditionCongruence == 'I'          %incongruent
                            nIncongruentTrials=nIncongruentTrials+1;
                            MatrixUsedForCalculationRaw_conditionIncongruent(:,nIncongruentTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionIncongruent(:,nIncongruentTrials)=currentCleanSignal;
                        end
                        
                        %%%foreground
                        if conditionForeground == 'ani' %animal
                            nAnimalTrials=nAnimalTrials+1;
                            MatrixUsedForCalculationRaw_conditionAnimal(:,nAnimalTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionAnimal(:,nAnimalTrials)=currentCleanSignal;
                        elseif conditionForeground == 'obj' %object
                            nObjectTrials=nObjectTrials+1;
                            MatrixUsedForCalculationRaw_conditionObject(:,nObjectTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionObject(:,nObjectTrials)=currentCleanSignal;
                        end
                    
                        %%%categorisation
                        if conditionCategorization == "G"   %good categorization
                            nWellCategorizedTrials=nWellCategorizedTrials+1;
                            MatrixUsedForCalculationRaw_conditionWellCateg(:,nWellCategorizedTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionWellCateg(:,nWellCategorizedTrials)=currentCleanSignal;
                        elseif conditionCategorization == "B"   %bad categorization
                            nBadlyCategorizedTrials=nBadlyCategorizedTrials+1;
                            MatrixUsedForCalculationRaw_conditionBadCateg(:,nBadlyCategorizedTrials)=currentRawSignal;
                            MatrixUsedForCalculationProcessed_conditionBadCateg(:,nBadlyCategorizedTrials)=currentCleanSignal;
                        end
                    
                        %%%recognition
                        if ~exclusionCriterion2
                    
                            if conditionRecognition == 'R'   %recognized
                                nRecognizedTrials=nRecognizedTrials+1;
                                MatrixUsedForCalculationRaw_conditionRecognized(:,nRecognizedTrials)=currentRawSignal;
                                MatrixUsedForCalculationProcessed_conditionRecognized(:,nRecognizedTrials)=currentCleanSignal;
                                
                                %trust level
                                    %remember
                                    if conditionRecognitionTrustLevel=="R"
                                        nRecognizedTrials_R=nRecognizedTrials_R+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_R(:,nRecognizedTrials_R)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_R(:,nRecognizedTrials_R)=currentCleanSignal;
                                    
                                    %know
                                    elseif conditionRecognitionTrustLevel=="K" 
                                        nRecognizedTrials_K=nRecognizedTrials_K+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_K(:,nRecognizedTrials_K)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_K(:,nRecognizedTrials_K)=currentCleanSignal;
                                        
                                    %guess
                                    elseif conditionRecognitionTrustLevel=="G" 
                                        nRecognizedTrials_G=nRecognizedTrials_G+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_G(:,nRecognizedTrials_G)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_G(:,nRecognizedTrials_G)=currentCleanSignal;
                                    end
                                    
                            elseif conditionRecognition == 'N'                            %not recognized = forgotten
                                nForgottenTrials=nForgottenTrials+1;
                                MatrixUsedForCalculationRaw_conditionForgotten(:,nForgottenTrials)=currentRawSignal;
                                MatrixUsedForCalculationProcessed_conditionForgotten(:,nForgottenTrials)=currentCleanSignal;
                                
                                %trust level
                                    %know
                                    if conditionRecognitionTrustLevel=="K"  
                                        nForgottenTrials_K=nForgottenTrials_K+1;
                                        MatrixUsedForCalculationRaw_conditionForgotten_K(:,nForgottenTrials_K)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionForgotten_K(:,nForgottenTrials_K)=currentCleanSignal;
                                        
                                    %guess
                                    elseif conditionRecognitionTrustLevel=="G" 
                                        nForgottenTrials_G=nForgottenTrials_G+1;
                                        MatrixUsedForCalculationRaw_conditionForgotten_G(:,nForgottenTrials_G)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionForgotten_G(:,nForgottenTrials_G)=currentCleanSignal;
                                    end
                                    
                            end


                        else %below chance level
                            
                            if conditionRecognition == 'R'   %recognized
                                nRecognizedTrials_BelowCL=nRecognizedTrials_BelowCL+1;
                                MatrixUsedForCalculationRaw_conditionRecognized_BelowCL(:,nRecognizedTrials_BelowCL)=currentRawSignal;
                                MatrixUsedForCalculationProcessed_conditionRecognized_BelowCL(:,nRecognizedTrials_BelowCL)=currentCleanSignal;
                                
                                %trust level
                                    %remember
                                    if conditionRecognitionTrustLevel=="R"
                                        nRecognizedTrials_R_BelowCL=nRecognizedTrials_R_BelowCL+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_R_BelowCL(:,nRecognizedTrials_R_BelowCL)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_R_BelowCL(:,nRecognizedTrials_R_BelowCL)=currentCleanSignal;
                                    
                                    %know
                                    elseif conditionRecognitionTrustLevel=="K" 
                                        nRecognizedTrials_K_BelowCL=nRecognizedTrials_K_BelowCL+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_K_BelowCL(:,nRecognizedTrials_K_BelowCL)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_K_BelowCL(:,nRecognizedTrials_K_BelowCL)=currentCleanSignal;
                                        
                                    %guess
                                    elseif conditionRecognitionTrustLevel=="G" 
                                        nRecognizedTrials_G_BelowCL=nRecognizedTrials_G_BelowCL+1;
                                        MatrixUsedForCalculationRaw_conditionRecognized_G_BelowCL(:,nRecognizedTrials_G_BelowCL)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionRecognized_G_BelowCL(:,nRecognizedTrials_G_BelowCL)=currentCleanSignal;
                                    end
                                    
                            elseif conditionRecognition == 'N' %not recognized = forgotten
                                nForgottenTrials_BelowCL=nForgottenTrials_BelowCL+1;
                                MatrixUsedForCalculationRaw_conditionForgotten_BelowCL(:,nForgottenTrials_BelowCL)=currentRawSignal;
                                MatrixUsedForCalculationProcessed_conditionForgotten_BelowCL(:,nForgottenTrials_BelowCL)=currentCleanSignal;
                                
                                %trust level
                                    %know
                                    if conditionRecognitionTrustLevel=="K"  
                                        nForgottenTrials_K_BelowCL=nForgottenTrials_K_BelowCL+1;
                                        MatrixUsedForCalculationRaw_conditionForgotten_K_BelowCL(:,nForgottenTrials_K_BelowCL)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionForgotten_K_BelowCL(:,nForgottenTrials_K_BelowCL)=currentCleanSignal;
                                        
                                    %guess
                                    elseif conditionRecognitionTrustLevel=="G" 
                                        nForgottenTrials_G_BelowCL=nForgottenTrials_G_BelowCL+1;
                                        MatrixUsedForCalculationRaw_conditionForgotten_G_BelowCL(:,nForgottenTrials_G_BelowCL)=currentRawSignal;
                                        MatrixUsedForCalculationProcessed_conditionForgotten_G_BelowCL(:,nForgottenTrials_G_BelowCL)=currentCleanSignal;
                                    end
                                    
                            end
                            
                        end
                        
                    end
             
                end
                

        thresholdNumberOfTrialMinimal1=app.trialThreshold;
        thresholdNumberOfTrialMinimal2=app.trialThreshold;
        thresholdNumberOfTrialMinimal3=app.trialThreshold;


        %ALL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionAll)=nAllTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionAll)=app.conditions(indexConditionAll); %"All"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionAll)=nanmedian(MatrixUsedForCalculationProcessed_conditionAll,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionAll)=nanmedian(MatrixUsedForCalculationRaw_conditionAll,2);
        if nAllTrials < thresholdNumberOfTrialMinimal1     
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionAll)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionAll)=0;
        end
        
        %CONGRUENT
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionCongruent)=nCongruentTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionCongruent)=app.conditions(indexConditionCongruent); %"Congruent"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionCongruent)=nanmedian(MatrixUsedForCalculationProcessed_conditionCongruent,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionCongruent)=nanmedian(MatrixUsedForCalculationRaw_conditionCongruent,2);
        if nCongruentTrials < thresholdNumberOfTrialMinimal2    
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionCongruent)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionCongruent)=0;
        end
        
        %INCONGRUENT
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionIncongruent)=nIncongruentTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionIncongruent)=app.conditions(indexConditionIncongruent); %"Incongruent"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionIncongruent)=nanmedian(MatrixUsedForCalculationProcessed_conditionIncongruent,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionIncongruent)=nanmedian(MatrixUsedForCalculationRaw_conditionIncongruent,2);
        if nIncongruentTrials < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionIncongruent)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionIncongruent)=0;
        end 
        
        %ANIMAL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionAnimal)=nAnimalTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionAnimal)=app.conditions(indexConditionAnimal); %"Animal"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionAnimal)=nanmedian(MatrixUsedForCalculationProcessed_conditionAnimal,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionAnimal)=nanmedian(MatrixUsedForCalculationRaw_conditionAnimal,2);
        if nAnimalTrials < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionAnimal)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionAnimal)=0;
        end
        
        %OBJECT
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionObject)=nObjectTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionObject)=app.conditions(indexConditionObject); %"Object"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionObject)=nanmedian(MatrixUsedForCalculationProcessed_conditionObject,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionObject)=nanmedian(MatrixUsedForCalculationRaw_conditionObject,2);
        if nObjectTrials < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionObject)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionObject)=0;
        end
        
        %RECOGNIZED ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL)=nRecognizedTrials;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedAboveCL)=app.conditions(indexConditionRecognizedAboveCL); %"Recognized"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedAboveCL)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized,2);
        if nRecognizedTrials < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL)=0;        
        end
        
        %FORGOTTEN ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL)=nForgottenTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenAboveCL)=app.conditions(indexConditionForgottenAboveCL); %"Forgotten"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenAboveCL)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten,2);
        if nForgottenTrials < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL)=0;
        end        
        
        %WELL CATEGORIZED
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionWellCategorized)=nWellCategorizedTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionWellCategorized)=app.conditions(indexConditionWellCategorized); %"Well categorized"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionWellCategorized)=nanmedian(MatrixUsedForCalculationProcessed_conditionWellCateg,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionWellCategorized)=nanmedian(MatrixUsedForCalculationRaw_conditionWellCateg,2);
        if nWellCategorizedTrials < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionWellCategorized)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionWellCategorized)=0;
        end      

        %BADLY CATEGORIZED
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionBadlyCategorized)=nBadlyCategorizedTrials;
        app.medianPupilResponses{indexSubject}.condition(indexConditionBadlyCategorized)=app.conditions(indexConditionBadlyCategorized); %"Badly categorized"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionBadlyCategorized)=nanmedian(MatrixUsedForCalculationProcessed_conditionBadCateg,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionBadlyCategorized)=nanmedian(MatrixUsedForCalculationRaw_conditionBadCateg,2);
        if nBadlyCategorizedTrials < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionBadlyCategorized)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionBadlyCategorized)=0;
        end           
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE R ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_R)=nRecognizedTrials_R;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedAboveCL_R)=app.conditions(indexConditionRecognizedAboveCL_R); %"Recognized with confidence R"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_R)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_R,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedAboveCL_R)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_R,2);
        if nRecognizedTrials_R < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_R)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_R)=0;
        end
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_K)=nRecognizedTrials_K;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedAboveCL_K)=app.conditions(indexConditionRecognizedAboveCL_K); %"Recognized with confidence K"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_K)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_K,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedAboveCL_K)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_K,2);
        if nRecognizedTrials_K < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_K)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_K)=0;
        end
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_G)=nRecognizedTrials_G;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedAboveCL_G)=app.conditions(indexConditionRecognizedAboveCL_G); %"Recognized with confidence G"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_G)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_G,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedAboveCL_G)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_G,2);
        if nRecognizedTrials_G < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedAboveCL_G)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedAboveCL_G)=0;
        end
        
        %FORGOTTEN WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL_K)=nForgottenTrials_K;
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenAboveCL_K)=app.conditions(indexConditionForgottenAboveCL_K); %"Forgotten"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL_K)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten_K,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenAboveCL_K)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten_K,2);
        if nForgottenTrials_K < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL_K)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL_K)=0;
        end 
        
        %FORGOTTEN WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL_G)=nForgottenTrials_G;
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenAboveCL_G)=app.conditions(indexConditionForgottenAboveCL_G); %"Forgotten"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL_G)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten_G,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenAboveCL_G)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten_G,2);
        if nForgottenTrials_G < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenAboveCL_G)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenAboveCL_G)=0;
        end 
        
        %%
        %BELOW CHANCE LEVEL
        %%
        %RECOGNIZED BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL)=nRecognizedTrials_BelowCL;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedBelowCL)=app.conditions(indexConditionRecognizedBelowCL); %"Recognized below chance level"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedBelowCL)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_BelowCL,2);
        if nRecognizedTrials_BelowCL < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL)=0;        
        end
        %FORGOTTEN BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL)=nForgottenTrials_BelowCL;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenBelowCL)=app.conditions(indexConditionForgottenBelowCL); %"Recognized below chance level"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenBelowCL)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten_BelowCL,2);
        if nForgottenTrials_BelowCL < thresholdNumberOfTrialMinimal2
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL)=0;        
        end
        
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE R BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_R)=nRecognizedTrials_R_BelowCL;        
        app.medianPupilResponses{indexSubject}.condition(indexConditionRecognizedBelowCL_R)=app.conditions(indexConditionRecognizedBelowCL_R); %"Recognized with confidence R"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_R)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_R_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedBelowCL_R)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_R_BelowCL,2);
        if nRecognizedTrials_R_BelowCL < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_R)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_R)=0;
        end
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_K)=nRecognizedTrials_K_BelowCL;        
        app.medianPupilResponses{indexSubject}.condition(18)=app.conditions(indexConditionRecognizedBelowCL_K); %"Recognized with confidence K"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_K)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_K_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedBelowCL_K)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_K_BelowCL,2);
        if nRecognizedTrials_K_BelowCL < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_K)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_K)=0;
        end
        
        %RECOGNIZED WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_G)=nRecognizedTrials_G_BelowCL;        
        app.medianPupilResponses{indexSubject}.condition(19)=app.conditions(indexConditionRecognizedBelowCL_G); %"Recognized with confidence G"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_G)=nanmedian(MatrixUsedForCalculationProcessed_conditionRecognized_G_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionRecognizedBelowCL_G)=nanmedian(MatrixUsedForCalculationRaw_conditionRecognized_G_BelowCL,2);
        if nRecognizedTrials_G_BelowCL < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionRecognizedBelowCL_G)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionRecognizedBelowCL_G)=0;
        end
        
        %FORGOTTEN WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL_K)=nForgottenTrials_K_BelowCL;
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenBelowCL_K)=app.conditions(indexConditionForgottenBelowCL_K); %"Forgotten"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL_K)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten_K_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenBelowCL_K)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten_K_BelowCL,2);
        if nForgottenTrials_K_BelowCL < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL_K)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL_K)=0;
        end 
        
        %FORGOTTEN WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
        app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL_G)=nForgottenTrials_G_BelowCL;
        app.medianPupilResponses{indexSubject}.condition(indexConditionForgottenBelowCL_G)=app.conditions(indexConditionForgottenBelowCL_G); %"Forgotten"
        app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL_G)=nanmedian(MatrixUsedForCalculationProcessed_conditionForgotten_G_BelowCL,2);
        app.medianPupilResponses{indexSubject}.raw(:,indexSignalType,indexConditionForgottenBelowCL_G)=nanmedian(MatrixUsedForCalculationRaw_conditionForgotten_G_BelowCL,2);
        if nForgottenTrials_G_BelowCL < thresholdNumberOfTrialMinimal3
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,indexConditionForgottenBelowCL_G)=bufferNaN_vector;
            app.howManyTrialsHaveBeenUsedToComputeMedian(indexSubject,indexSignalType,indexConditionForgottenBelowCL_G)=0;
        end 
        
        
        %SMOOTHING PART (OPTIONAL)
        for cptCondition=1:numel(app.conditions)
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition)=smoothdata(app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition), 'movmean', 100);
        end
        
        %SETS START AT Y=0      
        for cptCondition=1:numel(app.conditions)
            valueAt0=app.medianPupilResponses{indexSubject}.clean(1,indexSignalType,cptCondition);
            app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition)=app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition)-valueAt0;
        end


        %FITTING ERLANG MODEL (OPTIONAL) 
        %This is left commented because we wanted to fit a specific
        %function, but we didn't explore much..
%         if app.ModelCheckBox.Value
%             for cptCondition=1:numel(app.conditions)
%                 dataY=app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition);
%                 dataX=(1:length(dataY))/app.samplingFrequency;
%                 if ~isnan(dataY(1))
%                     [app.medianPupilResponses{indexSubject}.clean(:,indexSignalType,cptCondition) modelParameters]=fitErlang(dataY, dataX');
%                 end
%             end
%         end
        

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        

    function results = medians2mean(app)
            
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            timePoints=(1:numberOfPoints)/app.samplingFrequency;
            
            %We extract the median responses of the young group
            app.medianPupilResponsesYoung=app.medianPupilResponses(find(app.whosYoung));
            %We extract the median responses of the old group
            app.medianPupilResponsesOld=app.medianPupilResponses(find(app.whosOld));
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
    
            nSigma=app.sigmaForConfidenceInterval;

            %%OLD%%

            numberOfMediansComputedOld=zeros(numberOfSignalTypes,numberOfConditions);
            for cptOld=1:numberOfOldSubject
                
                for cptSignalTypes=1:numberOfSignalTypes
                                        
                    for cptConditions=1:numberOfConditions

                        matrixForComputingMeanResponseOld(:,cptSignalTypes,cptConditions,cptOld)=app.medianPupilResponsesOld{cptOld}.clean(:,cptSignalTypes,cptConditions);

                            if ~isnan( matrixForComputingMeanResponseOld(:,cptSignalTypes,cptConditions,cptOld))
                                numberOfMediansComputedOld(cptSignalTypes,cptConditions)=numberOfMediansComputedOld(cptSignalTypes,cptConditions)+1;
                            end
                        
                    end
                
                end
                
            end
                        
            meanPupilResponsesOld.clean=nanmean(matrixForComputingMeanResponseOld,4);
            standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseOld, 0, 4);
            for cpt=1:length(nanstd(meanPupilResponsesOld.clean, 0, 4))
                standardDeviationAtEachTimepoints(cpt,:,:)=squeeze(standardDeviationAtEachTimepoints(cpt,:,:))./sqrt(numberOfMediansComputedOld);
            end
            meanPupilResponsesOld.lowerConfidenceInterval=meanPupilResponsesOld.clean - nSigma * standardDeviationAtEachTimepoints;
            meanPupilResponsesOld.upperConfidenceInterval=meanPupilResponsesOld.clean + nSigma * standardDeviationAtEachTimepoints;
            meanPupilResponsesOld.numberOfUsedMedians=numberOfMediansComputedOld;

            
            %%YOUNG%%

            numberOfMediansComputedYoung=zeros(numberOfSignalTypes,numberOfConditions);
            for cptYoung=1:numberOfYoungSubject
                
                for cptSignalTypes=1:numberOfSignalTypes
                                        
                    for cptConditions=1:numberOfConditions

                        matrixForComputingMeanResponseYoung(:,cptSignalTypes,cptConditions,cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(:,cptSignalTypes,cptConditions);

                            if ~isnan( matrixForComputingMeanResponseYoung(:,cptSignalTypes,cptConditions,cptYoung))
                                numberOfMediansComputedYoung(cptSignalTypes,cptConditions)=numberOfMediansComputedYoung(cptSignalTypes,cptConditions)+1;
                            end
                        
                    end
                
                end
                
            end
            
            meanPupilResponsesYoung.clean=nanmean(matrixForComputingMeanResponseYoung,4);
            standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseYoung, 0, 4);
            for cpt=1:length(nanstd(meanPupilResponsesYoung.clean, 0, 4))
                standardDeviationAtEachTimepoints(cpt,:,:)=squeeze(standardDeviationAtEachTimepoints(cpt,:,:))./sqrt(numberOfMediansComputedYoung);
            end
            meanPupilResponsesYoung.lowerConfidenceInterval=meanPupilResponsesYoung.clean - nSigma * standardDeviationAtEachTimepoints;
            meanPupilResponsesYoung.upperConfidenceInterval=meanPupilResponsesYoung.clean + nSigma * standardDeviationAtEachTimepoints;
            meanPupilResponsesYoung.numberOfUsedMedians=numberOfMediansComputedYoung;
            %%%%
            
            app.meanPupilResponsesYoung=meanPupilResponsesYoung;
            app.meanPupilResponsesOld=meanPupilResponsesOld;
    end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function results = isItSignificantInterGroup(app)
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
        
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            
            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for inter-subject analysis : frequentist and bayesian unpaired t-testing');
            maxIteration=numberOfPoints*numberOfSubject*numberOfConditions;
            cptWaitbar=0;
            tail=app.allTailHypothesis;
            
                    for cptCondition=1:numberOfConditions
%                     for cptCondition=1:1

                    significanceBuffer=zeros(1,numberOfPoints);
                    bayesFactorBuffer=zeros(1,numberOfPoints);
                    pValueBuffer=zeros(1,numberOfPoints);

                    for cptPoint=1:numberOfPoints
                        
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionAtChosenPoint(cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(cptPoint,indexSignalType,cptCondition);
                            cptWaitbar=cptWaitbar+1;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionAtChosenPoint(cptOld)=app.medianPupilResponsesOld{cptOld}.clean(cptPoint,indexSignalType,cptCondition);
                            cptWaitbar=cptWaitbar+1;
                        end
                        
                        
                        %We remove nan columns
                        youngDistributionAtChosenPoint=youngDistributionAtChosenPoint(~isnan(youngDistributionAtChosenPoint));
                        oldDistributionAtChosenPoint=oldDistributionAtChosenPoint(~isnan(oldDistributionAtChosenPoint));

                        significanceBuffer(cptPoint)=0;
                        bayesFactorBuffer(cptPoint)=NaN;
                        pValueBuffer(cptPoint)=NaN;
                        
                        if ~ (isempty(youngDistributionAtChosenPoint) || isempty(oldDistributionAtChosenPoint))
                            [BF10 pBF]=bf.ttest2(youngDistributionAtChosenPoint,oldDistributionAtChosenPoint, 'tail', convertStringsToChars(tail) );
                            [h p]=ttest2(youngDistributionAtChosenPoint,oldDistributionAtChosenPoint, 'tail', tail);
                            
                            bayesFactorBuffer(cptPoint)=BF10;
                            pValueBuffer(cptPoint)=p;

                            significanceBuffer(cptPoint)=p<=app.pThresholdCluster;
                            
                        end
                        
                        
                        waitbar(cptWaitbar/maxIteration, wb);
                        
                    end
                    
                    app.significanceInterGroup(:,indexSignalType,cptCondition)=significanceBuffer;
                    app.bayesFactorInterGroup(:,indexSignalType,cptCondition)=bayesFactorBuffer;  
                    app.pValueInterGroup(:,indexSignalType,cptCondition)=pValueBuffer;
            
                end
        close(wb)
 
        end
 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = displayResultsInterGroup(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            timepoints=(1:numberOfPoints)/app.samplingFrequency;

            
            %We do this because of issues occuring with multiple dot
            %indexing
            meanPupilResponsesYoungBuffer=app.meanPupilResponsesYoung;
            meanPupilResponsesOldBuffer=app.meanPupilResponsesOld;

            %We compute the number of chosen trials and the number of
            %subjects
            bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
            bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
                
            bufferNumberOfSubjectsYoung=meanPupilResponsesYoungBuffer.numberOfUsedMedians;
            bufferNumberOfSubjectsOld=meanPupilResponsesOldBuffer.numberOfUsedMedians;
    
            %We define variables that will distribute the plots on two
            %different figure because there's too many 
            cptFirstFigure=0;
            cptSecondFigure=0;
            conditionsOnFirstFigure=[1:7]; %all , congruent , incongruent , animal , object , well categorized , badly categorized
            conditionsOnSecondFigure=[8:21]; %recognized above CL, forgotten above CL , recognized above CL - R, recognized above CL - K,  recognized above CL - G, 
            %forgotten above CL - K , forgotten above CL - G , %recognized below CL, forgotten below CL , recognized below CL - R, recognized below CL - K,  recognized below CL - G, 
            %forgotten below CL - K , forgotten below CL - G , 
    
            %%%
            for cptCondition=1:numberOfConditions
%             for cptCondition=1:1
                %%%
                %%%SUBPLOT 
                %%%
                
                    figureHandle=figure(cptCondition);
                    set(figureHandle,'units','normalized','outerposition',[0 0 1 1], 'Name', chosenTab);
                    sgtitle(chosenTab)
                    subplot(5,3,[1 1.2 4 4.2 7 7.2 10 10.2 ])
                
                plot(timepoints,meanPupilResponsesYoungBuffer.clean(:,indexSignalType,cptCondition), 'Color', app.colors(1,:) , 'LineWidth', 2)
                hold on 
                plot(timepoints,meanPupilResponsesOldBuffer.clean(:,indexSignalType,cptCondition), 'Color', app.colors(2,:), 'LineWidth', 2)
        
                %Matrix used for plotting purposes
                minScaleMatrix=[meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition) meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition) ];
                maxScaleMatrix=[meanPupilResponsesYoungBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition) meanPupilResponsesOldBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition) ];

                %We plot the shaded area where significances overlap
                significance=app.significanceInterGroup(:,indexSignalType,cptCondition);
%                 significanceBayes=app.bayesFactorInterGroup(:,indexSignalType,cptCondition)'>3;
                significanceByHandmadeCBPT = app.significanceInterGroup_ClustersBasedPermutation(:,indexSignalType,cptCondition); 

                significanceOverlap=significance & significanceByHandmadeCBPT;
%                 plotGrayAreaSignificance(app, significanceBayes, min(minScaleMatrix, [], 'all'), max(maxScaleMatrix, [], 'all'), timepoints)                
                
                TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                switch(TEPRtype)
                    case "Absolute"
                        plotGrayAreaSignificance(app, significanceOverlap', -12, 30, timepoints)    
                        %We plot the significance (uncorrected)
                        plot(timepoints(find(significance)), -9*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                        % and bayesian t-test  
        %                 plot(timepoints(find(significanceBayes)), -8*significanceBayes(find(significanceBayes)),'LineStyle','none','Marker','.','MarkerEdgeColor',[60 57 196]/255,'MarkerSize', 20);
                        % and CBPT   
                        plot(timepoints(find(significanceByHandmadeCBPT)), -12*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesYoungBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(1,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesOldBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(2,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        axis tight
                        ylim([-12 30]);     

                    case "Z-score"
                        plotGrayAreaSignificance(app, significanceOverlap', -1.5, 2.5, timepoints)    
                        plot(timepoints(find(significance)), -1*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                        plot(timepoints(find(significanceByHandmadeCBPT)), -1.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesYoungBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(1,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesOldBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(2,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        axis tight
                        ylim([-1.5 2.5]); 

                    case "Percentage"
                        plotGrayAreaSignificance(app, significanceOverlap', -2.5, 10, timepoints)    
                        plot(timepoints(find(significance)), -2*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                        plot(timepoints(find(significanceByHandmadeCBPT)), -2.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesYoungBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(1,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesOldBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(2,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                        axis tight
                        ylim([-2.5 10]); 
                end


                hold off
%                 xlabel('time(s)')
                    xlabelText = 'time(s)';
                    xlabelHandle = xlabel(xlabelText);
                    currentPosition = get(xlabelHandle, 'Position');
                    n = -1.2; 
                    currentPosition(2) = currentPosition(2) + n; 
                    set(xlabelHandle, 'Position', currentPosition);
                
                ylabel('arbitrary units')
                
                
                legend( strcat( "Young - ", num2str( bufferNumberOfTrialsYoung(indexSignalType,cptCondition)  ), " trials - ", num2str(bufferNumberOfSubjectsYoung(indexSignalType,cptCondition)), " subs (", num2str( sum(app.whosYoung) - bufferNumberOfSubjectsYoung(indexSignalType,cptCondition)), " rej.)"  ) , strcat( "Old - ", num2str(bufferNumberOfTrialsOld(indexSignalType,cptCondition)), " trials - ", num2str(bufferNumberOfSubjectsOld(indexSignalType,cptCondition)), " subs (", num2str( sum(app.whosOld) - bufferNumberOfSubjectsOld(indexSignalType,cptCondition ) ), " rej.)"  ) ) 
                title(app.conditions(cptCondition))
                ax = gca;
                ax.FontSize = 12; 
                ax.FontWeight = 'bold';
                
                
                buff=20;
                subplot(buff,3,[(buff-0)*3-2 (buff-0)*3-1.8])
%                 imagesc(app.bayesFactorInterGroup(:,indexSignalType,cptCondition)' >3)
                plotBF10(app, app.bayesFactorInterGroup(:,indexSignalType,cptCondition)')
                %cb = colorbar('Location', 'southoutside');                
                axis tight
                axis off


                indexMetrics=1;
                for cptPlot=[2,3,5,6,8,9,11,12,14,15]
                    
                    subplot(5,3, cptPlot)  
                    displayResultsInterGroupPupilMetrics(app, indexSignalType, cptCondition, indexMetrics)
                    indexMetrics=indexMetrics+1;
                
                end
                

            end


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

function results = dataTransferFromAuxToMainApp(app, hampelParameters, nPermutations, trialThreshold, pThresholdCluster,interpolationThreshold)
            %Parameters order in the structure : adjacentSampleHamp1,
            %                                    nSigma1,nTimesHampel1,
            %                                    adjacentSampleHamp2,
            %                                    nSigma2,
            %                                    nTimesHampel2,
            %                                    adjacentSampleInterp
            app.hampelParameters=hampelParameters;
            app.numberOfPermutations=nPermutations; 
            app.trialThreshold=trialThreshold;
            app.pThresholdCluster=pThresholdCluster;
            app.interpolationThreshold=interpolationThreshold;


        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = isItSignificantIntraGroup(app)
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            numberOfConditions=numberOfConditions-1; %We remove the "all" condition
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for intra-subject analysis (YOUNG group) : frequentist and bayesian paired t-testing');
            maxIteration=numel(app.testedConditions);
            cptWaitbar=0;
        
            ageGroup='j';
    
            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);            
                
            
            close(wb)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for intra-subject analysis (OLD group) : frequentist and bayesian paired t-testing');
            maxIteration=numel(app.testedConditions);
            cptWaitbar=0;
        
            ageGroup='a';
        
            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail)
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);            
                
            
            close(wb)

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = displayResultsIntraGroup(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            ageGroup='j';

            for cptTest=1:numel(app.testedConditions)
%             for cptTest=2:2
   
                figureHandle1=figure(100*indexSignalType+cptTest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : YOUNG group"));
                
                subplot(5,3,[1 1.2 4 4.2 7 7.2 10 10.2 ])
                conditionOneVsConditionTwoDisplay(app, cptTest, ageGroup)

                buff=20;
                subplot(buff,3,[(buff-0)*3-2 (buff-0)*3-1.8])
                plotBF10(app, app.bayesFactorIntraGroupYoung(:,indexSignalType,cptTest)')
                %cb = colorbar('Location', 'southoutside');                
                axis tight
                axis ('off')
                indexMetrics=1;

                for cptPlot=[2,3,5,6,8,9,11,12,14,15]
                    subplot(5,3, cptPlot)   
                    conditionOneVsConditionTwoDisplayPupilMetrics(app, cptTest, ageGroup, indexMetrics)
                    indexMetrics=indexMetrics+1;
                end


            end
        
            ageGroup='a';
          
            for cptTest=1:numel(app.testedConditions)
%             for cptTest=2:2
                
                figureHandle1=figure(200*indexSignalType+cptTest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : OLD group"));
                
                subplot(5,3,[1 1.2 4 4.2 7 7.2 10 10.2 ])
                conditionOneVsConditionTwoDisplay(app, cptTest, ageGroup)
                
                buff=20;
                subplot(buff,3,[(buff-0)*3-2 (buff-0)*3-1.8])
                plotBF10(app, app.bayesFactorIntraGroupOld(:,indexSignalType,cptTest)')
                axis tight
                axis ('off')
                indexMetrics=1;

                for cptPlot=[2,3,5,6,8,9,11,12,14,15]
                    subplot(5,3, cptPlot)   
                    conditionOneVsConditionTwoDisplayPupilMetrics(app, cptTest, ageGroup, indexMetrics)
                    indexMetrics=indexMetrics+1;
                end

            end
            
                
            %also the differences
            
            wb= waitbar(0,'Computing statistical significance for intra-subject analysis : CBPT on condition differences');
            maxIteration=numel(app.testedConditions);
            cptWaitbar=0;
            
            for cptTest=1:numel(app.testedConditions)
%             for cptTest=2:2
                figureHandle2=figure(300*indexSignalType+cptTest);
                set(figureHandle2,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : Differences between conditions, YOUNG v. OLDER"));

                conditionOneVsConditionTwoDisplayDifferences(app, cptTest, ageGroup, indexSignalType) %display AND computation at the same time            
                cptWaitbar=cptWaitbar+1;
                waitbar(cptWaitbar/maxIteration, wb);   
            end

            close(wb)
        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = saveState(app)
        %%%% FUNCTION NOT USED 
            state=struct();

        % signal
            state.signalTypes=app.signalTypes;
            state.conditions=app.conditions;
            state.trialDuration=app.trialDuration;
        % trials 
            state.nParticipant=app.nParticipant ;
            state.nTrials=app.nTrials;
            state.pathOfEachSubject=app.pathOfEachSubject;
            state.whosYoung=app.whosYoung;
            state.whosOld=app.whosOld;
            state.everyTrial=app.everyTrial;
            state.everyTimeBlock=app.everyTimeBlock;
            state.everyRawSignalDiameterBlock=app.everyRawSignalDiameterBlock;
            state.everyRawSignalBlockIndexes=app.everyRawSignalBlockIndexes;
            state.everyRawTime=app.everyRawTime;
            state.everyRawSignalGaze=app.everyRawSignalGaze;
            state.everyRawSignalDiameter=app.everyRawSignalDiameter;
            state.everyRawLabel=app.everyRawLabel;
            state.medianPupilResponses=app.medianPupilResponses;
            state.medianPupilResponsesOld=app.medianPupilResponsesOld;
            state.medianPupilResponsesYoung=app.medianPupilResponsesYoung;
            state.meanPupilResponsesOld=app.meanPupilResponsesOld;
            state.meanPupilResponsesYoung=app.meanPupilResponsesYoung;
            state.sigmaForConfidenceInterval=app.sigmaForConfidenceInterval;
            state.significanceInterGroup=app.significanceInterGroup;
            state.significanceIntraGroupYoung=app.significanceIntraGroupYoung;
            state.significanceIntraGroupOld=app.significanceIntraGroupOld;

        % plotting parameters
            state.selectedTrialsForEachSubjectAndSignalTypes=app.selectedTrialsForEachSubjectAndSignalTypes; %Which trials to use to compute the median
            state.howManyTrialsHaveBeenUsedToComputeMedian=app.howManyTrialsHaveBeenUsedToComputeMedian;
            state.allTrialsForEachSubjectAndSignalTypes=app.allTrialsForEachSubjectAndSignalTypes;
            state.multiSelectForEachSubjectAndSignalTypes=app.multiSelectForEachSubjectAndSignalTypes;
            state.selectAllForEachSubjectAndSignalTypes=app.selectAllForEachSubjectAndSignalTypes;
            state.samplingFrequency=app.samplingFrequency;
        % other apps
        % button state
            state.loadedSubjectsWhenClosed=app.ListBox.Items;
            state.chosenSubjectWhenClosed=app.ListBox.Value;
            state.labelsOfAvailableTrials=app.ListBox_2.Items;
            state.chosenTab=app.TabGroup.SelectedTab.Title;
            state.folderParticipantPath=app.PathEditField.Value;

            
            %% On check le poids des sauvegardes 
            weight=whos('state').bytes/(1024^3);
            disp(['Weight in gigabytes: ' num2str(weight) ' GB']);

        
        % we save and display a loading wheel
            savePath=app.savePath;
            cd(savePath)
            f1 = figure; % creating figure
            set(f1, 'Toolbar', 'none', 'Menubar', 'none');
            f1pos = get(f1, 'Position'); % determine position
            framesize = [400 400]; % set framesize
            [hdls] = StartLoadingSpinner('parent', f1, 'size', '32px', 'position', [(f1pos(3)/2)-framesize(1)/2 (f1pos(4)/2)-framesize(1)/2], 'framesize', framesize, 'text', 'Saving... it might take a few minutes... you should get a coffee...'); % start spinner
            pause(0.01)
            save('stateMainApp.mat', 'state', '-v7.3');
            
            StopLoadingSpinner('hdls', hdls, 'successtext', 'Successfully saved ! See you next time !'); % stop spinner
            pause(1)
            close(f1)
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results = loadState(app)
        %%%% FUNCTION NOT USED 

        savePath=app.savePath;
        cd(savePath)

        if exist('stateMainApp.mat')==2
            
            f1 = figure; % creating figure
            set(f1, 'Toolbar', 'none', 'Menubar', 'none');
            f1pos = get(f1, 'Position'); % determine position
            framesize = [400 400]; % set framesize
            [hdls] = StartLoadingSpinner('parent', f1, 'size', '32px', 'position', [(f1pos(3)/2)-framesize(1)/2 (f1pos(4)/2)-framesize(1)/2], 'framesize', framesize, 'text', 'Loading... it might take a few minutes... get prepared...'); % start spinner
              
            pause(0.01)
            load('stateMainApp.mat');
    
            % signal
                app.signalTypes=state.signalTypes;
                app.conditions=state.conditions;
                app.trialDuration=state.trialDuration;
                
            % trials 
                app.nParticipant=state.nParticipant;
                app.nTrials=state.nTrials;
                app.pathOfEachSubject=state.pathOfEachSubject;
    
                app.whosYoung=state.whosYoung;
                app.whosOld=state.whosOld;
            
                app.everyTrial=state.everyTrial;
                
                app.everyTimeBlock=state.everyTimeBlock;
                app.everyRawSignalDiameterBlock=state.everyRawSignalDiameterBlock;
                app.everyRawSignalBlockIndexes=state.everyRawSignalBlockIndexes;
                app.everyRawTime=state.everyRawTime;
                app.everyRawSignalGaze=state.everyRawSignalGaze;
                app.everyRawSignalDiameter=state.everyRawSignalDiameter;
                app.everyRawLabel=state.everyRawLabel;
    
                app.medianPupilResponses=state.medianPupilResponses;
                app.medianPupilResponsesOld=state.medianPupilResponsesOld;
                app.medianPupilResponsesYoung=state.medianPupilResponsesYoung;
                
                app.meanPupilResponsesOld=state.meanPupilResponsesOld;
                app.meanPupilResponsesYoung=state.meanPupilResponsesYoung;
                
                app.sigmaForConfidenceInterval=state.sigmaForConfidenceInterval;
                
                app.significanceInterGroup=state.significanceInterGroup;
                app.significanceIntraGroupYoung=state.significanceIntraGroupYoung;
                app.significanceIntraGroupOld=state.significanceIntraGroupOld;
            % plotting parameters
                app.selectedTrialsForEachSubjectAndSignalTypes=state.selectedTrialsForEachSubjectAndSignalTypes; %Which trials to use to compute the median
                app.howManyTrialsHaveBeenUsedToComputeMedian=state.howManyTrialsHaveBeenUsedToComputeMedian;
                app.allTrialsForEachSubjectAndSignalTypes=state.allTrialsForEachSubjectAndSignalTypes;
                app.multiSelectForEachSubjectAndSignalTypes=state.multiSelectForEachSubjectAndSignalTypes;
                app.selectAllForEachSubjectAndSignalTypes=state.selectAllForEachSubjectAndSignalTypes;
                app.samplingFrequency=state.samplingFrequency;
                
            % other apps
    %             app.dialogBoxAppObject=state.dialogBoxAppObject;
    
            % button state
                app.ListBox.Items=state.loadedSubjectsWhenClosed;
                app.ListBox.Value=state.chosenSubjectWhenClosed;
                app.ListBox_2.Items=state.labelsOfAvailableTrials;
%                 app.ListBox_2.Value=state.chosenTrialsWhenClosed;
%                 app.TabGroup.SelectedTab.Title=state.chosenTab;
                app.PathEditField.Value=state.folderParticipantPath;


            %We update the plots
             
            %We change the values of allTrials, multiSelect, and selected
            %trials based on the selected tab            
            %We get the chosen subject
            indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            %We get the chosen tab
            chosenTab=string(state.chosenTab); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            app.TabGroup.SelectedTab=app.TabGroup.Children(indexSignalType); %we open the right tab
            
            %We update the chosen trials
            app.ListBox_2.Value=app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType};
            app.AlltrialsCheckBox.Value=app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            app.MultiselectCheckBox.Value=app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType);    

            %We update the plot here
            updatePlotTrials(app)
            %We update the median plot here
            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);
            
            
            StopLoadingSpinner('hdls', hdls, 'successtext', 'Successfully loaded ! Time to work now !'); % stop spinner
            pause(1)
            close(f1)

        end

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function results = interpolationGraphicalInterface(app)

            %We first get the value of the chosen subject
            selectedSubject = app.ListBox.Value;
            %We then take his alphabetical order (i.e. indexSubject) in order to pick the
            %right trial structure located in "everyTrial"
            indexSubject = find( selectedSubject == string(app.ListBox.Items) );
            selectedSubjectTrial=app.everyTrial{indexSubject};
            
            
            %We get the LAST chosen trials
            indexTrials = app.ListBox_2.Value;
            numberOfChosenTrials=length(indexTrials);
            lastChosenTrial=indexTrials{end};
                       
            %We plot the right type of signal (LDx, RDY, etc..)
            %depending on the chosen tab (which is string typed).
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes);   
            
            %We define a X and a Y function just for convenience
            samplingFrequency=app.samplingFrequency;
            bufferTrial=selectedSubjectTrial(str2num(lastChosenTrial)); 
            nbPts=length(bufferTrial.time);
            x=(1:nbPts)/samplingFrequency;
            y=bufferTrial.cleanSignalDiameterMinusBaseline(:,indexSignalType); %(:,3) if we plot RDx for instance

            %We plot
            figureHandle=figure(1000+indexSignalType);
            set(figureHandle,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " - ", selectedSubject, " - Trial : ", lastChosenTrial)); 
            plot(x,y)
            axis tight
            hold on
            
            %We manually get the interpolation interval and we convert x1 and x2 to their relative indexes
            disp('Click on one side of the blink');
            [x1, ~] = ginput(1);
            [~, index1] = min(abs(x - x1));
            plot(x(index1), y(index1), "Color", "red", "LineWidth", 4, "Marker","o")
            axis tight
            
            disp('Click on the other side of the blink');
            [x2, ~] = ginput(1);
            [~, index2] = min(abs(x - x2));
            plot(x(index2), y(index2), "Color", "red", "LineWidth", 4, "Marker","o")
            axis tight
            
            %We update the index vector of modified points by interpolation
            indexesInterpolation=sort([index1 index2])
            app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPointsByManualInterpolation(indexesInterpolation(1):indexesInterpolation(2),indexSignalType)=ones(size(indexesInterpolation(1):indexesInterpolation(2)));
            app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPoints=or(app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPoints , app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPointsByManualInterpolation)  ;

            %Interpolation stage
            truncatedY=y;
            truncatedX=x;
            truncatedY(indexesInterpolation(1):indexesInterpolation(2))=[];
            truncatedX(indexesInterpolation(1):indexesInterpolation(2))=[];
            interpolatedY=interp1(truncatedX, truncatedY, x, 'linear');


            plot(x, interpolatedY, "LineStyle", '--', 'Color', 'red')
            axis tight
               
            pause(1)

            close(figureHandle)

            %We update the plot here
            updatePlotTrials(app)
            %We update the medians plot here
            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results = isItSignificantInterGroupClusters(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
        
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for inter-subject analysis : cluster based permutation testing');
            maxIteration=numberOfConditions;
            cptWaitbar=0;
            
            
            for cptCondition=1:numberOfConditions
%             for cptCondition=1:1

                        for cptYoung=1:numberOfYoungSubject
%                             cptYoung
                            matrixContainingMediansForOneConditionYoung(:,cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(:,indexSignalType,cptCondition);
                        end
                        for cptOld=1:numberOfOldSubject
%                             cptOld
                            matrixContainingMediansForOneConditionOld(:,cptOld)=app.medianPupilResponsesOld{cptOld}.clean(:,indexSignalType,cptCondition);
                        end
                        
                        %We remove nan columns
                        matrixContainingMediansForOneConditionYoung=matrixContainingMediansForOneConditionYoung(:, all(~isnan(matrixContainingMediansForOneConditionYoung)));
                        matrixContainingMediansForOneConditionOld=matrixContainingMediansForOneConditionOld(:, all(~isnan(matrixContainingMediansForOneConditionOld)));

                        %We compute the cluster based permutation test
                        dependent_samples=false;
                        p_threshold=app.pThresholdCluster;
                        num_permutations=app.numberOfPermutations;
                        two_sided=true;
                        num_clusters=app.numberOfClusters;
                        

                        [app.significanceInterGroup(:,indexSignalType,cptCondition) app.significanceInterGroup_ClustersBasedPermutation(:,indexSignalType,cptCondition) app.clusterCharacteristicsInterGroup{cptCondition} ] = CBPT_ztValueThreshold(matrixContainingMediansForOneConditionYoung,...
                                                                                                                      matrixContainingMediansForOneConditionOld,...
                                                                                                                      app.pThresholdCluster, app.numberOfPermutations, "independent", app.allTailHypothesis, "parametric");
                    
                        
                cptWaitbar=cptWaitbar+1;
                waitbar(cptWaitbar/maxIteration, wb);
            
            end
                        
            
        close(wb)
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results = displayResultsInterGroupClusters(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            timepoints=(1:numberOfPoints)/app.samplingFrequency;


            %We do this because of issues occuring with multiple dot
            %indexing
            meanPupilResponsesYoungBuffer=app.meanPupilResponsesYoung;
            meanPupilResponsesOldBuffer=app.meanPupilResponsesOld;

            %We compute the number of chosen trials and the number of
            %subjects
            bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
            bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
                
            bufferNumberOfSubjectsYoung=meanPupilResponsesYoungBuffer.numberOfUsedMedians;
            bufferNumberOfSubjectsOld=meanPupilResponsesOldBuffer.numberOfUsedMedians;
            
            %We define variables that will distribute the plots on two
            %different figure because there's too many 
            cptFirstFigure=0;
            cptSecondFigure=0;
            conditionsOnFirstFigure=[1:7]; %all , congruent , incongruent , animal , object , well categorized , badly categorized
            conditionsOnSecondFigure=[8:21]; %recognized above CL, forgotten above CL , recognized above CL - R, recognized above CL - K,  recognized above CL - G, 
            %forgotten above CL - K , forgotten above CL - G , %recognized below CL, forgotten below CL , recognized below CL - R, recognized below CL - K,  recognized below CL - G, 
            %forgotten below CL - K , forgotten below CL - G , 
    
       
%             for cptCondition=1:numberOfConditions
            for cptCondition=1:1

                if ismember(cptCondition, conditionsOnFirstFigure)
                    figureHandle=figure(indexSignalType);
                    set(figureHandle,'units','normalized','outerposition',[0 0 1 1], 'Name', chosenTab);
                    cptFirstFigure=cptFirstFigure+1;
                    subplot(2,ceil(length(conditionsOnFirstFigure)/2),cptFirstFigure)    
                    sgtitle(chosenTab)
                else
                    figureHandle=figure(indexSignalType+50);
                    set(figureHandle,'units','normalized','outerposition',[0 0 1 1], 'Name', chosenTab);
                    cptSecondFigure=cptSecondFigure+1;
                    subplot(2,ceil(length(conditionsOnSecondFigure)/2),cptSecondFigure)
                    sgtitle(chosenTab)
                end
                

                %We compute the number of chosen trials
                bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
                bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
                
                p1=plot(timepoints,meanPupilResponsesYoungBuffer.clean(:,indexSignalType,cptCondition), 'Color', app.colors(1,:), 'LineWidth', 2);
                hold on 
                p2=plot(timepoints,meanPupilResponsesOldBuffer.clean(:,indexSignalType,cptCondition), 'Color', app.colors(2,:), 'LineWidth', 2);
        
                %Matrix used for plotting purposes
                minScaleMatrix=[meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition) meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition) ];
                
                %We plot the significance
                clusters=app.significanceInterGroupClustersBasedPermutation{cptCondition}.clusters;
                pValues=app.significanceInterGroupClustersBasedPermutation{cptCondition}.p_values;
                if isfield(app.significanceInterGroupClustersBasedPermutation{cptCondition} , 'effectSize')
                    effectSize=app.significanceInterGroupClustersBasedPermutation{cptCondition}.effectSize;
                end
                numberOfCluster=numel(clusters);

                legendHandle1=legend([p1 p2],  strcat( "Young - ", num2str( bufferNumberOfTrialsYoung(indexSignalType,cptCondition)  ), " trials - ", num2str(bufferNumberOfSubjectsYoung(indexSignalType,cptCondition)), " subs (", num2str( sum(app.whosYoung) - bufferNumberOfSubjectsYoung(indexSignalType,cptCondition)), " rej.)"  ) , strcat( "Old - ", num2str(bufferNumberOfTrialsOld(indexSignalType,cptCondition)), " trials - ", num2str(bufferNumberOfSubjectsOld(indexSignalType,cptCondition)), " subs (", num2str( sum(app.whosOld) - bufferNumberOfSubjectsOld(indexSignalType,cptCondition)), " rej.)"  )  );


                    for cptClusters=1:numberOfCluster
                        if numberOfCluster>=1 && pValues(cptClusters)<1
                            significance=zeros(1,numberOfPoints);
                            significance(clusters{cptClusters})=1;
                            
                            if pValues(cptClusters) <= 0.05
                                markerSizeCluster=20;
                                markerEdgeColorCluster=[0.4660 0.6740 0.1880]; %green like
                            elseif pValues(cptClusters) > 0.05 && pValues(cptClusters) <= 0.20
                                markerSizeCluster=18;
                                markerEdgeColorCluster=[0.9290 0.6940 0.1250]; %yellow like
                            elseif pValues(cptClusters) > 0.20 && pValues(cptClusters) <= 0.30
                                markerSizeCluster=16;
                                markerEdgeColorCluster=[0.8500 0.3250 0.0980]; %red like
                            elseif pValues(cptClusters) > 0.30 && pValues(cptClusters) <= 0.40
                                markerSizeCluster=14;
                                markerEdgeColorCluster=[0.6350 0.0780 0.1840]; %bordeaux
                            elseif pValues(cptClusters) > 0.40 && pValues(cptClusters) 
                                markerSizeCluster=12;
                                markerEdgeColorCluster=[0.4940 0.1840 0.5560]; %purple
                            else
                                markerSizeCluster=10;
                                markerEdgeColorCluster=[0 0 0]; %black
                            end
                            
                            plot(timepoints(find(significance)), -10*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',markerEdgeColorCluster,'MarkerSize', markerSizeCluster)

                            if pValues(cptClusters)<0.05
                                meanCohenD=mean(effectSize{cptClusters});
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)), ' - d ='," ",num2str(meanCohenD)  );
                            else
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)) );
                            end
                        end
                   end
                

                fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesYoungBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(1,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBuffer.lowerConfidenceInterval(:,indexSignalType,cptCondition)', fliplr(meanPupilResponsesOldBuffer.upperConfidenceInterval(:,indexSignalType,cptCondition)')], app.colors(2,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                legendHandle1.String=legendHandle1.String(1:end-2);
                hold off
                xlabel('time(s)')
                ylabel('a.u.')
                axis square
                axis tight
                ylim([-10 30]);                        

                title(app.conditions(cptCondition))
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results = isItSignificantIntraGroupClusters(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            numberOfConditions=numberOfConditions-1; %We remove the "all" condition
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='j';
            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for intra-subject analysis (YOUNG group) : cluster based permutation testing');
            maxIteration=numel(app.testedConditions);
            cptWaitbar=0;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfYoungSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);            
                
            
            close(wb)            


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='a';
            %Preparing for the waiting bar
            wb= waitbar(0,'Computing statistical significance for intra-subject analysis (OLD group) : cluster based permutation testing');
            maxIteration=numel(app.testedConditions);
            cptWaitbar=0;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesisPermutest(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfOldSubject, ageGroup, tail);
            cptWaitbar=cptWaitbar+1;
            waitbar(cptWaitbar/maxIteration, wb);            
                
            
            close(wb)            
      
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results = displayResultsIntraGroupClusters(app)
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            ageGroup='j';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong (2v3), Ani v. Obj (4v5), Well categ v. Badly categ. (6v7)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            figureHandle1=figure(100+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : YOUNG group"));
            for cptTest=1:3
                subplot(1,3,cptTest)
                conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(101+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : YOUNG group"));
            cptTest=4;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            subplot(3,3,4)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            subplot(3,3,5)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            subplot(3,3,6)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            subplot(3,3,8)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(102+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : YOUNG group"));
            cptTest=9;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            subplot(3,3,4);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            subplot(3,3,5);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            subplot(3,3,6);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            subplot(3,3,8);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(103+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : YOUNG group"));
            cptTest=14;
            subplot(1,2,1);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            subplot(1,2,2);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup);                   
   
            
            sgtitle(strcat(chosenTab, " : YOUNG group"))
   
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            ageGroup='a';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong (2v3), Ani v. Obj (4v5), Well categ v. Badly categ. (6v7)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            figureHandle1=figure(200+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : OLD group"));
            for cptTest=1:3
                subplot(1,3,cptTest)
                conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(201+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : OLD group"));
            cptTest=4;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            subplot(3,3,4)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            subplot(3,3,5)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            subplot(3,3,6)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            subplot(3,3,8)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(202+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : OLD group"));
            cptTest=9;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            subplot(3,3,4);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            subplot(3,3,5);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            subplot(3,3,6);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            subplot(3,3,8);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(203+indexSignalType);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat(chosenTab, " : OLD group"));
            cptTest=14;
            subplot(1,2,1);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            subplot(1,2,2);
            conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup);                   
   
            
            sgtitle(strcat(chosenTab, " : OLD group"))

        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
        
function results = conditionOneVsConditionTwoTesting(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfSubject, ageGroup, tail)

            
    if ageGroup=='j'
            
            numberOfYoungSubject=numberOfSubject;
            
            significanceBuffer=zeros(1,numberOfPoints);
            bayesFactorBuffer=zeros(1,numberOfPoints);
            pValueBuffer=zeros(1,numberOfPoints);
            
                for cptPoint=1:numberOfPoints
                    
                    for cptYoung=1:numberOfYoungSubject
                        distributionOfCondition1AtChosenPoint(cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(cptPoint,indexSignalType,indexConditionOne);
                        distributionOfCondition2AtChosenPoint(cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(cptPoint,indexSignalType,indexConditionTwo);
                    end
                
                    
                    %We remove nan columns
                    whoIsNanCondition1=isnan(distributionOfCondition1AtChosenPoint);
                    whoIsNanCondition2=isnan(distributionOfCondition2AtChosenPoint);

                    whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
                    distributionOfCondition1AtChosenPoint=distributionOfCondition1AtChosenPoint(~whoIsNan);
                    distributionOfCondition2AtChosenPoint=distributionOfCondition2AtChosenPoint(~whoIsNan);

                                        
                    %we compute the significance
                    significanceBuffer(cptPoint)=0;
                    bayesFactorBuffer(cptPoint)=NaN;
                    pValueBuffer(cptPoint)=NaN;
                    
                    if ~ (isempty(distributionOfCondition1AtChosenPoint) || isempty(distributionOfCondition2AtChosenPoint))

                        if isequal( size(distributionOfCondition1AtChosenPoint) , size(distributionOfCondition2AtChosenPoint) )
                            [p h]=signrank(distributionOfCondition1AtChosenPoint,distributionOfCondition2AtChosenPoint, 'tail', tail );
                            [BF10 pBF]=bf.ttest(distributionOfCondition1AtChosenPoint,distributionOfCondition2AtChosenPoint, 'tail', convertStringsToChars(tail) );
                        else
                            error(sprintf("%d", cptTest))
                        end
                        
                                                bayesFactorBuffer(cptPoint)=BF10;
                        pValueBuffer(cptPoint)=p;
                        significanceBuffer(cptPoint)=p<app.pThresholdCluster;
                    end
                end
                
                app.whoIsNanIntraGroupYoung(cptTest,:)=whoIsNan;
                
                app.significanceIntraGroupYoung(:,indexSignalType,cptTest)=significanceBuffer;  
                app.bayesFactorIntraGroupYoung(:,indexSignalType,cptTest)=bayesFactorBuffer;  
                app.pValueIntraGroupYoung(:,indexSignalType,cptTest)=pValueBuffer;
                
                
%              
                
                
    elseif ageGroup=='a'
        
        numberOfOldSubject=numberOfSubject;
        
        significanceBuffer=zeros(1,numberOfPoints);
        bayesFactorBuffer=zeros(1,numberOfPoints);
        pValueBuffer=zeros(1,numberOfPoints);

                for cptPoint=1:numberOfPoints
                    
                    for cptOld=1:numberOfOldSubject
                        distributionOfCondition1AtChosenPoint(cptOld)=app.medianPupilResponsesOld{cptOld}.clean(cptPoint,indexSignalType,indexConditionOne);
                        distributionOfCondition2AtChosenPoint(cptOld)=app.medianPupilResponsesOld{cptOld}.clean(cptPoint,indexSignalType,indexConditionTwo);
                    end
                
                    %We remove nan columns
                    whoIsNanCondition1=isnan(distributionOfCondition1AtChosenPoint);
                    whoIsNanCondition2=isnan(distributionOfCondition2AtChosenPoint);

                    whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
                    distributionOfCondition1AtChosenPoint=distributionOfCondition1AtChosenPoint(~whoIsNan);
                    distributionOfCondition2AtChosenPoint=distributionOfCondition2AtChosenPoint(~whoIsNan);
                    
                    
                    %we compute the significance
                    significanceBuffer(cptPoint)=0;
                    bayesFactorBuffer(cptPoint)=NaN;
                    pValueBuffer(cptPoint)=NaN;
                    
                    if ~ (isempty(distributionOfCondition1AtChosenPoint) || isempty(distributionOfCondition2AtChosenPoint))

                        if isequal( size(distributionOfCondition1AtChosenPoint) , size(distributionOfCondition2AtChosenPoint) )
                            [p h]=signrank(distributionOfCondition1AtChosenPoint,distributionOfCondition2AtChosenPoint, 'tail', tail );
                            [BF10 pBF]=bf.ttest(distributionOfCondition1AtChosenPoint,distributionOfCondition2AtChosenPoint, 'tail', convertStringsToChars(tail) );
                        else
                            error(sprintf("%d", cptTest))
                        end
%                         significanceBuffer(cptPoint)=h;
                        bayesFactorBuffer(cptPoint)=BF10;
                        pValueBuffer(cptPoint)=p;
                        significanceBuffer(cptPoint)=p<app.pThresholdCluster;

                    end

                end
                
                app.whoIsNanIntraGroupOld(cptTest,:)=whoIsNan;

                app.significanceIntraGroupOld(:,indexSignalType,cptTest)=significanceBuffer;
                app.bayesFactorIntraGroupOld(:,indexSignalType,cptTest)=bayesFactorBuffer;  
                app.pValueIntraGroupOld(:,indexSignalType,cptTest)=pValueBuffer;


    end

    %%THEN WE COMPUTE THE DIFFERENCES AND DO THE SAME THING
    computeDifferenceBetweenConditions(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfSubject, ageGroup, whoIsNan)
    isItSignificantDifferenceBetweenConditions(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfSubject, ageGroup, whoIsNan, numberOfPoints);

    end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
        
        function results = conditionOneVsConditionTwoDisplay(app, cptTest, ageGroup)
               

            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            numberOfConditions=numberOfConditions-1; %We remove the "all" condition
            timepoints=(1:numberOfPoints)/app.samplingFrequency;

            
            %We do this because of issues occuring with multiple dot
            %indexing
            meanPupilResponsesYoungBuffer=app.meanPupilResponsesYoung;
            meanPupilResponsesOldBuffer=app.meanPupilResponsesOld;
            
            %We compute the number of chosen trials
           
            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);
            
            trialNumberBufferYoung=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),indexSignalType,:));
             bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionTwo));
                        
            trialNumberBufferOld=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),indexSignalType,:));
             bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
             bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)=bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionTwo));

                if ageGroup=='j'
                    meanPupilResponsesYoungBufferConditionOne = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionOne);
                    meanPupilResponsesYoungBufferConditionTwo = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionTwo);

                    plot(timepoints,meanPupilResponsesYoungBufferConditionOne.clean, 'Color', app.colors(3,:), 'LineWidth', 2)
                    hold on 
                    plot(timepoints,meanPupilResponsesYoungBufferConditionTwo.clean, 'Color', app.colors(4,:), 'LineWidth', 2)


                    %Matrix used for plotting purposes
                    minScaleMatrix=[meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval ];
                    maxScaleMatrix=[meanPupilResponsesYoungBufferConditionOne.upperConfidenceInterval meanPupilResponsesYoungBufferConditionTwo.upperConfidenceInterval ];
%                          
                    %We plot the shaded area where significances overlap
                    significance=app.significanceIntraGroupYoung(:,indexSignalType,cptTest);
%                     significanceBayes=app.bayesFactorIntraGroupYoung(:,indexSignalType,cptTest)'>3; 
                    significanceByHandmadeCBPT = app.significanceIntraGroupYoung_ClustersBasedPermutation(:,indexSignalType,cptTest);
                    significanceOverlap=significance & significanceByHandmadeCBPT;                      

                    TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                    switch(TEPRtype)
                        case "Absolute"
                            plotGrayAreaSignificance(app, significanceOverlap', -12, 30, timepoints)    
                            %We plot the significance (uncorrected)
                            plot(timepoints(find(significance)), -9*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                             % and bayesian t-test  
                            plot(timepoints(find(significanceByHandmadeCBPT)), -12*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-12 30]);    
                            
                        case "Z-score"
                            plotGrayAreaSignificance(app, significanceOverlap', -1.5, 2.5, timepoints)    
                            plot(timepoints(find(significance)), -1*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                            plot(timepoints(find(significanceByHandmadeCBPT)), -1.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-1.5 2.5]);  
                            
                        case "Percentage"
                            plotGrayAreaSignificance(app, significanceOverlap', -2.5, 10, timepoints)    
                            plot(timepoints(find(significance)), -2*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                            plot(timepoints(find(significanceByHandmadeCBPT)), -2.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-2.5 10]);  

                    end


                    hold off

                    xlabelText = 'time(s)';
                    xlabelHandle = xlabel(xlabelText);
                    currentPosition = get(xlabelHandle, 'Position');
                    n = -1.2; 
                    currentPosition(2) = currentPosition(2) + n; % Augmenter la coordonnée y
                    set(xlabelHandle, 'Position', currentPosition);


                    ylabel('arbitrary units')
                    

                    numberOfSubs=num2str(sum(~app.whoIsNanIntraGroupYoung(cptTest,:)));
                    numberOfTrials1=num2str(bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne));
                    numberOfTrials2=num2str(bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo));

                    legend( strcat( app.conditions(indexConditionOne), " : ", numberOfTrials1 , " trials - ", numberOfSubs , " subs (", num2str( sum(app.whosYoung) - sum(~app.whoIsNanIntraGroupYoung(cptTest,:)) ), " rej.)"  ) , strcat( app.conditions(indexConditionTwo)," : ", numberOfTrials2, " trials - " , numberOfSubs, " subs (", num2str( sum(app.whosYoung) - sum(~app.whoIsNanIntraGroupYoung(cptTest,:))), " rej.)"  ) )

                    
                    title(app.testedConditions(cptTest))
                    sgtitle(strcat("YOUNG group - " , chosenTab))
                    
                    ax = gca; 
                    ax.FontSize = 12; 
                    ax.FontWeight = 'bold';
                

                elseif ageGroup=='a'
                    
                    meanPupilResponsesOldBufferConditionOne = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionOne);
                    meanPupilResponsesOldBufferConditionTwo = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionTwo);            

                    plot(timepoints,meanPupilResponsesOldBufferConditionOne.clean, 'Color', app.colors(3,:), 'LineWidth', 2)
                    hold on 
                    plot(timepoints,meanPupilResponsesOldBufferConditionTwo.clean, 'Color', app.colors(4,:), 'LineWidth', 2)
%                       
                    %Matrix used for plotting purposes
                    minScaleMatrix=[meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval ];
                    maxScaleMatrix=[meanPupilResponsesOldBufferConditionOne.upperConfidenceInterval meanPupilResponsesOldBufferConditionTwo.upperConfidenceInterval ];
                        
                    %We plot the shaded area where significances overlap
                    significance=app.significanceIntraGroupOld(:,indexSignalType,cptTest);
%                     significanceBayes=app.bayesFactorIntraGroupOld(:,indexSignalType,cptTest)'>3;
                    significanceByHandmadeCBPT = app.significanceIntraGroupOld_ClustersBasedPermutation(:,indexSignalType,cptTest);

                    significanceOverlap=significance & significanceByHandmadeCBPT;                      
                    plotGrayAreaSignificance(app, significanceOverlap', -12, 30, timepoints)    
    
                    
                    TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                    switch(TEPRtype)
                        case "Absolute" 
                            %We plot the significance (uncorrected)
                            plot(timepoints(find(significance)), -9*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                            %and bayesian t-test  
        %                     plot(timepoints(find(significanceBayes)), -8*significanceBayes(find(significanceBayes)),'LineStyle','none','Marker','.','MarkerEdgeColor',[60 57 196]/255,'MarkerSize', 20);
                            plot(timepoints(find(significanceByHandmadeCBPT)), -12*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-12 30]);                        

                        case "Z-score"
                            plot(timepoints(find(significance)), -1*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                            plot(timepoints(find(significanceByHandmadeCBPT)), -1.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-1.5 2.5]);                        

                        case "Percentage"
                            plot(timepoints(find(significance)), -2*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',[170 170 255]/255,'MarkerSize', 20);
                            plot(timepoints(find(significanceByHandmadeCBPT)), -2.5*significanceByHandmadeCBPT(find(significanceByHandmadeCBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 155]/255,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-2.5 10]);   
                    end
                    

                    hold off

                    xlabelText = 'time(s)';
                    xlabelHandle = xlabel(xlabelText);
                    currentPosition = get(xlabelHandle, 'Position');
                    n = -1.2;
                    currentPosition(2) = currentPosition(2) + n; 
                    set(xlabelHandle, 'Position', currentPosition);

                    ylabel('arbitrary units')
                    
                    numberOfSubs=num2str(sum(~app.whoIsNanIntraGroupOld(cptTest,:)));
                    numberOfTrials1=num2str(bufferNumberOfTrialsOld(indexSignalType,indexConditionOne));
                    numberOfTrials2=num2str(bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo));

                    legend( strcat( app.conditions(indexConditionOne), " : ", numberOfTrials1 , " trials - ", numberOfSubs , " subs (", num2str( sum(app.whosOld) - sum(~app.whoIsNanIntraGroupOld(cptTest,:)) ), " rej.)"  ) , strcat( app.conditions(indexConditionTwo)," : ", numberOfTrials2, " trials - " , numberOfSubs, " subs (", num2str( sum(app.whosOld) - sum(~app.whoIsNanIntraGroupOld(cptTest,:))), " rej.)"  ) )

                    title(app.testedConditions(cptTest))
                    sgtitle(strcat("OLD group - " , chosenTab))
                    ax = gca;
                    ax.FontSize = 12; 
                    ax.FontWeight = 'bold';
                    
                end


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
        
        function results = conditionOneVsConditionTwoTestingClusters(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfPoints, numberOfSubject, ageGroup, tail)
           
            num_clusters=app.numberOfClusters;
            p_threshold=app.pThresholdCluster;
            num_permutations=app.numberOfPermutations;

            if ageGroup=='j'
  
                numberOfYoungSubject=numberOfSubject;
                
                for cptYoung=1:numberOfYoungSubject
                    matrixContainingMediansForFirstConditionYoung(:,cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(:,indexSignalType,indexConditionOne);
                    matrixContainingMediansForNextConditionYoung(:,cptYoung)=app.medianPupilResponsesYoung{cptYoung}.clean(:,indexSignalType,indexConditionTwo);
                end
               
                %We remove nan columns
                whoIsNanCondition1=isnan(matrixContainingMediansForFirstConditionYoung(1,:));
                whoIsNanCondition2=isnan(matrixContainingMediansForNextConditionYoung(1,:));

                whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
                matrixContainingMediansForFirstConditionYoung=matrixContainingMediansForFirstConditionYoung(:, ~whoIsNan);
                matrixContainingMediansForNextConditionYoung=matrixContainingMediansForNextConditionYoung(:, ~whoIsNan);
               
                
                doTheMatricesHaveTheSameSize=(size(matrixContainingMediansForFirstConditionYoung,2)==size(matrixContainingMediansForNextConditionYoung,2));
            %We compute the cluster based permutation test
                dependent_samples=true;
                if ~doTheMatricesHaveTheSameSize
                    dependent_samples=false;
                    error(sprintf("%d", cptTest))
                end
                two_sided=str2num(tail);
                
               
            app.whoIsNanIntraGroupYoung(cptTest,:)=whoIsNan;

%             cptTest

            [app.significanceIntraGroupYoung(:,indexSignalType,cptTest) app.significanceIntraGroupYoung_ClustersBasedPermutation(:,indexSignalType,cptTest) app.clusterCharacteristicsIntraGroupYoung{cptTest}] = CBPT_ztValueThreshold(matrixContainingMediansForFirstConditionYoung,...
                                                                                                                      matrixContainingMediansForNextConditionYoung,...
                                                                                                                      app.pThresholdCluster, app.numberOfPermutations, "dependent", app.testedConditionsTailHypothesis(cptTest), "parametric");


            matrixContainingMediansSubstractedBetweenCdts=matrixContainingMediansForFirstConditionYoung-matrixContainingMediansForNextConditionYoung;
            app.matrixContainingMediansSubstractedBetweenCdtsYoung{cptTest}=matrixContainingMediansSubstractedBetweenCdts;
            
            [app.significanceMatrixOfDifferencesIntraGroupYoung(:,indexSignalType,cptTest) app.significanceMatrixOfDifferencesIntraGroupYoung_CBPT(:,indexSignalType,cptTest)] = CBPT_ztValueThresholdOneSample(matrixContainingMediansSubstractedBetweenCdts, ...
                                                                                                                                    app.pThresholdCluster, ...
                                                                                                                                    app.numberOfPermutations, app.testedConditionsTailHypothesis(cptTest), "parametric");   
             
            elseif ageGroup=='a'
                
                numberOfOldSubject=numberOfSubject;
                
               for cptOld=1:numberOfOldSubject
                    matrixContainingMediansForFirstConditionOld(:,cptOld)=app.medianPupilResponsesOld{cptOld}.clean(:,indexSignalType,indexConditionOne);
                    matrixContainingMediansForNextConditionOld(:,cptOld)=app.medianPupilResponsesOld{cptOld}.clean(:,indexSignalType,indexConditionTwo);
               end
                         
                %We remove nan columns
                whoIsNanCondition1=isnan(matrixContainingMediansForFirstConditionOld(1,:));
                whoIsNanCondition2=isnan(matrixContainingMediansForNextConditionOld(1,:));

                whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
                matrixContainingMediansForFirstConditionOld=matrixContainingMediansForFirstConditionOld(:, ~whoIsNan);
                matrixContainingMediansForNextConditionOld=matrixContainingMediansForNextConditionOld(:, ~whoIsNan);
               
                
                doTheMatricesHaveTheSameSize=(size(matrixContainingMediansForFirstConditionOld,2)==size(matrixContainingMediansForNextConditionOld,2));
            
                %We compute the cluster based permutation test
                dependent_samples=true;
                if ~doTheMatricesHaveTheSameSize
                    dependent_samples=false;
                    error(sprintf("%d", cptTest))
                end
                two_sided=str2num(tail);


                app.whoIsNanIntraGroupOld(cptTest,:)=whoIsNan;
%                 cptTest
                
                 [app.significanceIntraGroupOld(:,indexSignalType,cptTest) app.significanceIntraGroupOld_ClustersBasedPermutation(:,indexSignalType,cptTest) app.clusterCharacteristicsIntraGroupOld{cptTest}] = CBPT_ztValueThreshold(matrixContainingMediansForFirstConditionOld,...
                                                                                                                      matrixContainingMediansForNextConditionOld,...
                                                                                                                      app.pThresholdCluster, app.numberOfPermutations, "dependent", app.testedConditionsTailHypothesis(cptTest), "parametric");
                    

                 matrixContainingMediansSubstractedBetweenCdts=matrixContainingMediansForFirstConditionOld-matrixContainingMediansForNextConditionOld;
                app.matrixContainingMediansSubstractedBetweenCdtsOld{cptTest}=matrixContainingMediansSubstractedBetweenCdts;
                
            
                [app.significanceMatrixOfDifferencesIntraGroupOld(:,indexSignalType,cptTest) app.significanceMatrixOfDifferencesIntraGroupOld_CBPT(:,indexSignalType,cptTest)] = CBPT_ztValueThresholdOneSample(matrixContainingMediansSubstractedBetweenCdts, ...
                                                                                                                            app.pThresholdCluster, ...
                                                                                                                            app.numberOfPermutations, app.testedConditionsTailHypothesis(cptTest), "parametric");  
            
            end


        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
        
        
        function results = conditionOneVsConditionTwoDisplayClusters(app, cptTest, ageGroup)
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            numberOfConditions=numberOfConditions-1; %We remove the "all" condition
            timepoints=(1:numberOfPoints)/app.samplingFrequency;

            %We do this because of issues occuring with multiple dot
            %indexing
            meanPupilResponsesYoungBuffer=app.meanPupilResponsesYoung;
            meanPupilResponsesOldBuffer=app.meanPupilResponsesOld;
            
            %We compute the number of chosen trials and the number of
            %subjects
            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);
            
             trialNumberBufferYoung=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),indexSignalType,:));
             bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionTwo));
                        
            trialNumberBufferOld=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),indexSignalType,:));
             bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
             bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)=bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionTwo));
% 

            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);


                if ageGroup=='j'
                    

                    meanPupilResponsesYoungBufferConditionOne = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionOne);
                    meanPupilResponsesYoungBufferConditionTwo = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionTwo);
   
                    p1=plot(timepoints,meanPupilResponsesYoungBufferConditionOne.clean, 'Color', app.colors(3,:), 'LineWidth', 2)
                    hold on 
                    p2=plot(timepoints,meanPupilResponsesYoungBufferConditionTwo.clean, 'Color', app.colors(4,:), 'LineWidth', 2)
                    
                   minScaleMatrix=[meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval, meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval ];

                                       
                    %We plot the significance
                    clusters=app.significanceIntraGroupYoungClustersBasedPermutation{cptTest}.clusters;
                    pValues=app.significanceIntraGroupYoungClustersBasedPermutation{cptTest}.p_values;
                    if isfield(app.significanceIntraGroupYoungClustersBasedPermutation{cptTest} , 'effectSize')
                        effectSize=app.significanceIntraGroupYoungClustersBasedPermutation{cptTest}.effectSize;
                    end
                    numberOfCluster=numel(clusters);
    
                    numberOfSubs=num2str(sum(~app.whoIsNanIntraGroupYoung(cptTest,:)));
                    numberOfTrials1=num2str(bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne));
                    numberOfTrials2=num2str(bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo));
                    
                    legendHandle1=legend([p1 p2], strcat( app.conditions(indexConditionOne), " : ", numberOfTrials1 , " trials - ", numberOfSubs , " subs (", num2str( sum(app.whosYoung) - sum(~app.whoIsNanIntraGroupYoung(cptTest,:)) ), " rej.)"  ) , strcat( app.conditions(indexConditionTwo)," : ", numberOfTrials2, " trials - " , numberOfSubs, " subs (", num2str( sum(app.whosYoung) - sum(~app.whoIsNanIntraGroupYoung(cptTest,:))), " rej.)"  ) )

                    for cptClusters=1:numberOfCluster
                        if numberOfCluster>=1 && pValues(cptClusters)<1
                            significance=zeros(1,numberOfPoints);
                            significance(clusters{cptClusters})=1;
                            
                            if pValues(cptClusters) <= 0.05
                                markerSizeCluster=20;
                                markerEdgeColorCluster=[0.4660 0.6740 0.1880]; %green like
                            elseif pValues(cptClusters) > 0.05 && pValues(cptClusters) <= 0.20
                                markerSizeCluster=18;
                                markerEdgeColorCluster=[0.9290 0.6940 0.1250]; %yellow like
                            elseif pValues(cptClusters) > 0.20 && pValues(cptClusters) <= 0.30
                                markerSizeCluster=16;
                                markerEdgeColorCluster=[0.8500 0.3250 0.0980]; %red like
                            elseif pValues(cptClusters) > 0.30 && pValues(cptClusters) <= 0.40
                                markerSizeCluster=14;
                                markerEdgeColorCluster=[0.6350 0.0780 0.1840]; %bordeaux
                            elseif pValues(cptClusters) > 0.40 && pValues(cptClusters) 
                                markerSizeCluster=12;
                                markerEdgeColorCluster=[0.4940 0.1840 0.5560]; %purple
                            else
                                markerSizeCluster=10;
                                markerEdgeColorCluster=[0 0 0]; %black
                            end
                            

                            plot(timepoints(find(significance)), -10*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',markerEdgeColorCluster,'MarkerSize', markerSizeCluster)

                            if pValues(cptClusters)<0.05
                                meanCohenD=mean(effectSize{cptClusters});
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)), ' - d ='," ",num2str(meanCohenD)  );
                            else
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)) );
                            end
                        end
                    end
                    
                      fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                      fill([timepoints, fliplr(timepoints)], [meanPupilResponsesYoungBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesYoungBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                 

                    legendHandle1.String=legendHandle1.String(1:end-2);
                    hold off
                    xlabel('time(s)')
                    ylabel('a.u.')
                    axis square
                    axis tight
                    ylim([-10 30]);                        


                    title(app.testedConditions(cptTest))
                    sgtitle(strcat("YOUNG group - " , chosenTab))

                elseif ageGroup=='a'
                    

                    meanPupilResponsesOldBufferConditionOne = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionOne);
                    meanPupilResponsesOldBufferConditionTwo = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType,cptTest,indexConditionTwo);
   
                    p1=plot(timepoints,meanPupilResponsesOldBufferConditionOne.clean, 'Color', app.colors(3,:), 'LineWidth', 2)
                    hold on 
                    p2=plot(timepoints,meanPupilResponsesOldBufferConditionTwo.clean, 'Color', app.colors(4,:), 'LineWidth', 2)
                    minScaleMatrix=[meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval, meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval ];

                    %We plot the significance
                    clusters=app.significanceIntraGroupOldClustersBasedPermutation{cptTest}.clusters;
                    pValues=app.significanceIntraGroupOldClustersBasedPermutation{cptTest}.p_values;
                    if isfield(app.significanceIntraGroupOldClustersBasedPermutation{cptTest} , 'effectSize')
                        effectSize=app.significanceIntraGroupOldClustersBasedPermutation{cptTest}.effectSize;
                    end
                    numberOfCluster=numel(clusters);
    
                    numberOfSubs=num2str(sum(~app.whoIsNanIntraGroupOld(cptTest,:)));
                    numberOfTrials1=num2str(bufferNumberOfTrialsOld(indexSignalType,indexConditionOne));
                    numberOfTrials2=num2str(bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo));

                    legendHandle1=legend([p1 p2], strcat( app.conditions(indexConditionOne), " : ", numberOfTrials1 , " trials - ", numberOfSubs , " subs (", num2str( sum(app.whosOld) - sum(~app.whoIsNanIntraGroupOld(cptTest,:)) ), " rej.)"  ) , strcat( app.conditions(indexConditionTwo)," : ", numberOfTrials2, " trials - " , numberOfSubs, " subs (", num2str( sum(app.whosOld) - sum(~app.whoIsNanIntraGroupOld(cptTest,:))), " rej.)"  ) )

                    
                    for cptClusters=1:numberOfCluster
                        if numberOfCluster>=1 && pValues(cptClusters)<1
                            significance=zeros(1,numberOfPoints);
                            significance(clusters{cptClusters})=1;
                            
                            if pValues(cptClusters) <= 0.05
                                markerSizeCluster=20;
                                markerEdgeColorCluster=[0.4660 0.6740 0.1880]; %green like
                            elseif pValues(cptClusters) > 0.05 && pValues(cptClusters) <= 0.20
                                markerSizeCluster=18;
                                markerEdgeColorCluster=[0.9290 0.6940 0.1250]; %yellow like
                            elseif pValues(cptClusters) > 0.20 && pValues(cptClusters) <= 0.30
                                markerSizeCluster=16;
                                markerEdgeColorCluster=[0.8500 0.3250 0.0980]; %red like
                            elseif pValues(cptClusters) > 0.30 && pValues(cptClusters) <= 0.40
                                markerSizeCluster=14;
                                markerEdgeColorCluster=[0.6350 0.0780 0.1840]; %bordeaux
                            elseif pValues(cptClusters) > 0.40 && pValues(cptClusters) 
                                markerSizeCluster=12;
                                markerEdgeColorCluster=[0.4940 0.1840 0.5560]; %purple
                            else
                                markerSizeCluster=10;
                                markerEdgeColorCluster=[0 0 0]; %black
                            end
                            
                            plot(timepoints(find(significance)), -10*significance(find(significance)),'LineStyle','none','Marker','.','MarkerEdgeColor',markerEdgeColorCluster,'MarkerSize', markerSizeCluster)
                            if pValues(cptClusters)<0.05
                                meanCohenD=mean(effectSize{cptClusters});
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)), ' - d ='," ",num2str(meanCohenD)  );
                            else
                                legendHandle1.String{end}=strcat('Cluster'," ", num2str(cptClusters),' - p ='," ",num2str(pValues(cptClusters)) );
                            end                        
                        end
                    end
     
                    fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionOne.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionOne.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                    fill([timepoints, fliplr(timepoints)], [meanPupilResponsesOldBufferConditionTwo.lowerConfidenceInterval', fliplr(meanPupilResponsesOldBufferConditionTwo.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                    legendHandle1.String=legendHandle1.String(1:end-2);
                    hold off
                    xlabel('time(s)')
                    ylabel('a.u.')
                    axis square
                    axis tight
                    ylim([-10 30]);                        

                    title(app.testedConditions(cptTest))
                    sgtitle(strcat("OLD group - " , chosenTab))

                end
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

        function results = computeMeanResponseTimes(app)
                    
        for indexSubject=1:app.nParticipant


            %We get all his trials
            allTrialsOfSelectedSubject = app.everyTrial{indexSubject};


            %We get the path of the subject
            participantPath = app.pathOfEachSubject{indexSubject};
            %We compute the recognition rate at V2
            cd(participantPath);
            txtV2=[dir('*Reconnaissance*').name];
            txtV2Path=[participantPath filesep txtV2];
            learnedSet=['set' allTrialsOfSelectedSubject(1).set];
            recognitionRateRecoTask=recognitionRate(learnedSet, txtV2Path);
            
            indexConditionAll=find("All"==app.conditions);
            
            indexConditionCongruent=find("Congruent"==app.conditions);
            indexConditionIncongruent=find("Incongruent"==app.conditions);
            
            indexConditionAnimal=find("Animal"==app.conditions);
            indexConditionObject=find("Object"==app.conditions);
            
            indexConditionWellCategorized=find("Well categorized"==app.conditions);
            indexConditionBadlyCategorized=find("Badly categorized"==app.conditions);
            
            indexConditionRecognizedAboveCL=find("Recognized"==app.conditions);
            indexConditionForgottenAboveCL=find("Forgotten"==app.conditions);
            
                indexConditionRecognizedAboveCL_R=find("Recognized R"==app.conditions);
                indexConditionRecognizedAboveCL_K=find("Recognized K"==app.conditions);
                indexConditionRecognizedAboveCL_G=find("Recognized G"==app.conditions);
                
                indexConditionForgottenAboveCL_K=find("Forgotten K"==app.conditions);
                indexConditionForgottenAboveCL_G=find("Forgotten G"==app.conditions);
            
            indexConditionRecognizedBelowCL=find("Recognized below CL"==app.conditions);
            indexConditionForgottenBelowCL=find("Forgotten below CL"==app.conditions);
            
                indexConditionRecognizedBelowCL_R=find("Recognized R below CL"==app.conditions);
                indexConditionRecognizedBelowCL_K=find("Recognized K below CL"==app.conditions);
                indexConditionRecognizedBelowCL_G=find("Recognized G below CL"==app.conditions);
                
                indexConditionForgottenBelowCL_K=find("Forgotten K below CL"==app.conditions);
                indexConditionForgottenBelowCL_G=find("Forgotten G below CL"==app.conditions);
                
                
            %%%%%%%%%%%%%%%%%%%%%
            % Response times
            %%%%%%%%%%%%%%%%%%%%%
            
            %ALL
            allTR=NaN;
            %CONGRUENT
            congruentTR=NaN;
            %INCONGRUENT
            incongruentTR=NaN;
            %ANIMAL
            animalTR=NaN;
            %OBJECT
            objectTR=NaN;
            %RECOGNIZED ABOVE CHANCE LEVEL
            recognizedTR=NaN;
            %FORGOTTEN ABOVE CHANCE LEVEL
            notRecognizedTR=NaN;
            %WELL CATEGORIZED
            goodCategTR=NaN;
            %BADLY CATEGORIZED
            badCategTR=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R ABOVE CHANCE LEVEL
            recognizedTR_R=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            recognizedTR_K=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            recognizedTR_G=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            notRecognizedTR_K=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            notRecognizedTR_G=NaN;
            %%
            %BELOW CHANCE LEVEL
            %RECOGNIZED BELOW CHANCE LEVEL
            recognizedTR_BelowCL=NaN;
            %FORGOTTEN BELOW CHANCE LEVEL
            notRecognizedTR_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R BELOW CHANCE LEVEL
            recognizedTR_R_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            recognizedTR_K_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            recognizedTR_G_BelowCL=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            notRecognizedTR_K_BelowCL=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            notRecognizedTR_G_BelowCL=NaN;
            
            %all
            cptTRall=0;
            %congruence/incongruence
            cptTRcong=0;
            cptTRincong=0;
            %foreground
            cptTRani=0;
            cptTRobj=0;
            %categorization    
            cptTRgoodCateg=0;
            cptTRbadCateg=0;
            %recognition above chance level
            cptTRrecog=0;
                cptTRrecog_R=0;
                cptTRrecog_K=0;
                cptTRrecog_G=0;
            cptTRnotrecog=0;
                cptTRnotrecog_K=0;
                cptTRnotrecog_G=0;
            %recognition below chance level
            cptTRrecog_BelowCL=0;
                cptTRrecog_R_BelowCL=0;
                cptTRrecog_K_BelowCL=0;
                cptTRrecog_G_BelowCL=0;
            cptTRnotrecog_BelowCL=0;
                cptTRnotrecog_K_BelowCL=0;
                cptTRnotrecog_G_BelowCL=0;   
    
            
            for cptTrial=1:72
                %We take the trial n°cptTrial of subject indexSubject
                currentBufferTrial=allTrialsOfSelectedSubject(cptTrial);
                
                %We extract the different conditions
                conditionForeground = currentBufferTrial.foreground;
                conditionCongruence = currentBufferTrial.congruence;
                conditionRecognition = currentBufferTrial.isRecognized;
                conditionRecognitionTrustLevel = currentBufferTrial.trustLevel;
                conditionCategorization=currentBufferTrial.categorizationResult;
                                    
                allTR(cptTrial)=allTrialsOfSelectedSubject(cptTrial).responseTime;

                %We only take correctly classified item
                if conditionCategorization == "G"

                    
                    cptTRgoodCateg=cptTRgoodCateg+1;
                    goodCategTR(cptTRgoodCateg)=allTR(cptTrial);
                    
                    if conditionForeground == 'ani' %animal
                        cptTRani=cptTRani+1;
                        animalTR(cptTRani)=allTR(cptTrial);
                    else                            %object
                        cptTRobj=cptTRobj+1;
                        objectTR(cptTRobj)=allTR(cptTrial);
                    end
                    
                    if conditionCongruence == 'C'   %congruent
                        cptTRcong=cptTRcong+1;
                        congruentTR(cptTRcong)=allTR(cptTrial);
                    else                            %incongruent
                        cptTRincong=cptTRincong+1;
                        incongruentTR(cptTRincong)=allTR(cptTrial);
                    end
                    
                    
                    if recognitionRateRecoTask(1) > app.chanceLevel
                            if conditionRecognition == 'R'   %recognized
                                cptTRrecog=cptTRrecog+1;
                                recognizedTR(cptTRrecog)=allTR(cptTrial);
                                
                                    if conditionRecognitionTrustLevel=="R"
                                        cptTRrecog_R=cptTRrecog_R+1;
                                        recognizedTR_R(cptTRrecog_R)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="K"
                                        cptTRrecog_K=cptTRrecog_K+1;
                                        recognizedTR_K(cptTRrecog_K)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptTRrecog_G=cptTRrecog_G+1;
                                        recognizedTR_G(cptTRrecog_G)=allTR(cptTrial);  
                                    end
                                    
                            else                            %not recognized
                                cptTRnotrecog=cptTRnotrecog+1;
                                notRecognizedTR(cptTRnotrecog)=allTR(cptTrial);
                                                    
                                    if conditionRecognitionTrustLevel=="K"
                                        cptTRnotrecog_K=cptTRnotrecog_K+1;
                                        notRecognizedTR_K(cptTRnotrecog_K)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptTRnotrecog_G=cptTRnotrecog_G+1;
                                        notRecognizedTR_G(cptTRnotrecog_G)=allTR(cptTrial);  
                                    end
                            
                            end
                    
                    else
        
                    
                            if conditionRecognition == 'R'   %recognized
                                cptTRrecog_BelowCL=cptTRrecog_BelowCL+1;
                                recognizedTR_BelowCL(cptTRrecog_BelowCL)=allTR(cptTrial);
                                
                                    if conditionRecognitionTrustLevel=="R"
                                        cptTRrecog_R_BelowCL=cptTRrecog_R_BelowCL+1;
                                        recognizedTR_R_BelowCL(cptTRrecog_R_BelowCL)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="K"
                                        cptTRrecog_K_BelowCL=cptTRrecog_K_BelowCL+1;
                                        recognizedTR_K_BelowCL(cptTRrecog_K_BelowCL)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptTRrecog_G_BelowCL=cptTRrecog_G_BelowCL+1;
                                        recognizedTR_G_BelowCL(cptTRrecog_G_BelowCL)=allTR(cptTrial);  
                                    end
                                    
                            else                            %not recognized
                                cptTRnotrecog_BelowCL=cptTRnotrecog_BelowCL+1;
                                notRecognizedTR_BelowCL(cptTRnotrecog_BelowCL)=allTR(cptTrial);
                                                    
                                    if conditionRecognitionTrustLevel=="K"
                                        cptTRnotrecog_K_BelowCL=cptTRnotrecog_K_BelowCL+1;
                                        notRecognizedTR_K_BelowCL(cptTRnotrecog_K_BelowCL)=allTR(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptTRnotrecog_G_BelowCL=cptTRnotrecog_G_BelowCL+1;
                                        notRecognizedTR_G_BelowCL(cptTRnotrecog_G_BelowCL)=allTR(cptTrial);  
                                    end
                            
                            end
                    
                    
                    end
                    
                    
                else%badly categorized
                    cptTRbadCateg=cptTRbadCateg+1;
                    badCategTR(cptTRbadCateg)=allTR(cptTrial);  
                end %we only take correctly classified item
                
                
            end


            %ALL
            app.meanResponseTimes(indexSubject,indexConditionAll)=nanmedian(allTR);
            
            %CONGRUENT
            app.meanResponseTimes(indexSubject,indexConditionCongruent)=nanmedian(congruentTR);
    
            %INCONGRUENT
            app.meanResponseTimes(indexSubject,indexConditionIncongruent)=nanmedian(incongruentTR);
    
            %ANIMAL
            app.meanResponseTimes(indexSubject,indexConditionAnimal)=nanmedian(animalTR);
    
            %OBJECT
            app.meanResponseTimes(indexSubject,indexConditionObject)=nanmedian(objectTR);
    
            %RECOGNIZED ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedAboveCL)=nanmedian(recognizedTR);
            
            %FORGOTTEN ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenAboveCL)=nanmedian(notRecognizedTR);
            
            %WELL CATEGORIZED
            app.meanResponseTimes(indexSubject,indexConditionWellCategorized)=nanmedian(goodCategTR);
            
            %BADLY CATEGORIZED
            app.meanResponseTimes(indexSubject,indexConditionBadlyCategorized)=nanmedian(badCategTR);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedAboveCL_R)=nanmedian(recognizedTR_R);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedAboveCL_K)=nanmedian(recognizedTR_K);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedAboveCL_G)=nanmedian(recognizedTR_G);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenAboveCL_K)=nanmedian(notRecognizedTR_K);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenAboveCL_G)=nanmedian(notRecognizedTR_G);
            
            %%
            %BELOW CHANCE LEVEL
            %%
            %RECOGNIZED BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedBelowCL)=nanmedian(recognizedTR_BelowCL);
    
            %FORGOTTEN BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenBelowCL)=nanmedian(notRecognizedTR_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedBelowCL_R)=nanmedian(recognizedTR_R_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedBelowCL_K)=nanmedian(recognizedTR_K_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionRecognizedBelowCL_G)=nanmedian(recognizedTR_G_BelowCL);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenBelowCL_K)=nanmedian(notRecognizedTR_K_BelowCL);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            app.meanResponseTimes(indexSubject,indexConditionForgottenBelowCL_G)=nanmedian(notRecognizedTR_G_BelowCL);
        
        end
        
        app.meanResponseTimesYoung=app.meanResponseTimes(find(app.whosYoung),:);
        app.meanResponseTimesOld=app.meanResponseTimes(find(app.whosOld),:);
        
        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
        
                
        function results = isItSignificantIntraGroupResponseTimes(app)
            
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='j';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)
  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='a';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)
  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup)


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

        function results = conditionOneVsConditionTwoTestingTimeResponses(app, cptTest, indexConditionOne, indexConditionTwo, numberOfSubject, ageGroup)
            
            if ageGroup=='j'
            
            numberOfYoungSubject=numberOfSubject;
            
            significanceBuffer=NaN;
    
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.meanResponseTimesYoung(cptYoung,indexConditionOne);
                distributionOfCondition2(cptYoung)=app.meanResponseTimesYoung(cptYoung,indexConditionTwo);
            end
            
            
            %We remove nan columns
            
            whoIsNanCondition1=isnan(distributionOfCondition1);
            whoIsNanCondition2=isnan(distributionOfCondition2);
            
            whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);

            distributionOfCondition1=distributionOfCondition1(~whoIsNan);
            distributionOfCondition2=distributionOfCondition2(~whoIsNan);


            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))

                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both' );
                else
                       error(sprintf("%d", cptTest))
                end
                
                significanceBuffer=h;
            end
                
            app.significanceResponseTimeIntraGroupYoung(cptTest)=significanceBuffer;  
                
    elseif ageGroup=='a'
        
            
            numberOfOldSubject=numberOfSubject;
            
            significanceBuffer=NaN;
    
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.meanResponseTimesOld(cptOld,indexConditionOne);
                distributionOfCondition2(cptOld)=app.meanResponseTimesOld(cptOld,indexConditionTwo);
            end
        
            %We remove nan columns
            
            whoIsNanCondition1=isnan(distributionOfCondition1);
            whoIsNanCondition2=isnan(distributionOfCondition2);
            
            whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);

            distributionOfCondition1=distributionOfCondition1(~whoIsNan);
            distributionOfCondition2=distributionOfCondition2(~whoIsNan);

            
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))

                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both' );
                else
                    error(sprintf("%d", cptTest))
                end
                
                significanceBuffer=h;
            end
                
            app.significanceResponseTimeIntraGroupOld(cptTest)=significanceBuffer;  
        
    end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 


        function results = conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            
            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);

            if ageGroup=='j'
            
                for cptYoung=1:numberOfYoungSubject
                    distributionOfCondition1(cptYoung)=app.meanResponseTimesYoung(cptYoung,indexConditionOne);
                    distributionOfCondition2(cptYoung)=app.meanResponseTimesYoung(cptYoung,indexConditionTwo);
                end
        
                %We remove nan columns
                
                whoIsNanCondition1=isnan(distributionOfCondition1);
                whoIsNanCondition2=isnan(distributionOfCondition2);
                
                whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
            
                distributionOfCondition1=distributionOfCondition1(~isnan(whoIsNan))';
                distributionOfCondition2=distributionOfCondition2(~isnan(whoIsNan))';
               
                group = [ 1 * ones(size(distributionOfCondition1))
                          2 * ones(size(distributionOfCondition2))];
                
                donnees{1}=distributionOfCondition1;
                donnees{2}=distributionOfCondition2;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {'1', '2'}, 'scattercolors','same'); 
                ylabel('Performance');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);


                titleString=app.testedConditions(cptTest);

                if app.significanceResponseTimeIntraGroupYoung(cptTest)==1
                    titleString=strcat( titleString , " (d = ", num2str(computeCohen_d(distributionOfCondition1,distributionOfCondition2, 'paired'), '%.3f'), ")");
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString)
                ylabel('Mean response times (ms)')
                axis square


                sgtitle("Response times - YOUNG group")

            elseif ageGroup=='a'
            
                            
                for cptOld=1:numberOfOldSubject
                    distributionOfCondition1(cptOld)=app.meanResponseTimesOld(cptOld,indexConditionOne);
                    distributionOfCondition2(cptOld)=app.meanResponseTimesOld(cptOld,indexConditionTwo);
                end
        
                %We remove nan columns
                 whoIsNanCondition1=isnan(distributionOfCondition1);
                whoIsNanCondition2=isnan(distributionOfCondition2);
                
                whoIsNan=or(whoIsNanCondition1, whoIsNanCondition2);
            
                distributionOfCondition1=distributionOfCondition1(~isnan(whoIsNan))';
                distributionOfCondition2=distributionOfCondition2(~isnan(whoIsNan))';
               
               
                group = [ 1 * ones(size(distributionOfCondition1))
                          2 * ones(size(distributionOfCondition2))];
                         
                donnees{1}=distributionOfCondition1;
                donnees{2}=distributionOfCondition2;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {'1', '2'}, 'scattercolors','same'); 
                ylabel('Performance');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);

                
                titleString=app.testedConditions(cptTest);

                if app.significanceResponseTimeIntraGroupOld(cptTest)==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(distributionOfCondition1,distributionOfCondition2, 'paired'), '%.3f'),")");
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString)
                ylabel('Mean response times (ms)')
                axis square
                

                sgtitle("Response times - OLD group")
                
            end
            
            
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
function results = displayResultsIntraGroupResponseTimes(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            ageGroup='j';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong (2v3), Ani v. Obj (4v5), Well categ v. Badly categ. (6v7)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            figureHandle1=figure(3000);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "YOUNG group");
            for cptTest=1:3
                subplot(1,3,cptTest)
                conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3001);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "YOUNG group");
            cptTest=4;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            subplot(3,3,4)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            subplot(3,3,5)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            subplot(3,3,6)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            subplot(3,3,8)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3002);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "YOUNG group");
            cptTest=9;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            subplot(3,3,4);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            subplot(3,3,5);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            subplot(3,3,6);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            subplot(3,3,8);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3003);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "YOUNG group");
            cptTest=14;
            subplot(1,2,1);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            subplot(1,2,2);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup);                   
   
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            ageGroup='a';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong (2v3), Ani v. Obj (4v5), Well categ v. Badly categ. (6v7)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            figureHandle1=figure(3004);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "OLD group");
            for cptTest=1:3
                subplot(1,3,cptTest)
                conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3005);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "OLD group");
            cptTest=4;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            subplot(3,3,4)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            subplot(3,3,5)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            subplot(3,3,6)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            subplot(3,3,8)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3006);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "OLD group");
            cptTest=9;
            subplot(3,3,2)
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            subplot(3,3,4);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            subplot(3,3,5);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            subplot(3,3,6);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            subplot(3,3,8);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figureHandle1=figure(3007);
            set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', "OLD group");
            cptTest=14;
            subplot(1,2,1);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            subplot(1,2,2);
            conditionOneVsConditionTwoDisplayTimeResponses(app, cptTest, ageGroup); 
            
            
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        function results = exportDataToFile(app)
                        
            wb= waitbar(0,'Exporting data to spreadsheet files: 0%');
            wbMaxIterations=3;
            wbIterator=0;
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes);  
                                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%EXPORT CSV FILE FOR TO MAIN SPREADSHEET %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            exportDataToMainSpreadSheet(app)
            wbIterator=wbIterator+1;
            waitbar(wbIterator/wbMaxIterations, wb, sprintf("Exporting data to spreadsheet files: %d%%", round(wbIterator/wbMaxIterations*100)));
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%EXPORT TRIAL DATA FOR TRIAL-WISE GLM ANALYSIS %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            exportDataForTrialWiseGLManalysis(app)
            wbIterator=wbIterator+1;
            waitbar(wbIterator/wbMaxIterations, wb, sprintf("Exporting data to spreadsheet files: %d%%", round(wbIterator/wbMaxIterations*100)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%EXPORT TRIAL AVAILABILITY INFORMATION         %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            exportSignalAvailiblityToTXT(app)
            wbIterator=wbIterator+1;
            waitbar(wbIterator/wbMaxIterations, wb, sprintf("Exporting data to spreadsheet files: %d%%", round(wbIterator/wbMaxIterations*100)));
                        
            close(wb)
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       

        function results = pupilMetricsComputation(app)
            
            
        % This function will compute each of the following metrics for
        % each median responses (i.e. for each conditions)
        % METRICS : 
        % Peak amplitude
        % Mean amplitude
        % Dilation velocity
        % Area under the curve
        % Latency to constriction peak
        % Latency to dilation onset
        % Latency to peak amplitude
        % Latency from peak amplitude to baseline
        % Duration of reponse 
        % Baseline
        [ numberOfPoints numberOfSignalTypes numberOfConditions ] = size(app.medianPupilResponses{1}.clean);            

        computeBaselinePupilDiameterForEachCdts(app);
        
        for indexParticipant=1:numel(app.medianPupilResponses)
            
            for indexSignalType = 1:numberOfSignalTypes
                
                for indexCondition = 1:numberOfConditions
                    
                    bufferSignal=app.medianPupilResponses{indexParticipant}.clean(:,indexSignalType,indexCondition);
                    bufferSignalPositive = zeros(size(bufferSignal));
                    bufferSignalNegative=bufferSignalPositive;
                    
                    bufferSignalPositive(bufferSignal > 0)=bufferSignal(bufferSignal > 0);
                    bufferSignalNegative(bufferSignal < 0)=bufferSignal(bufferSignal < 0);
                    
                    %Initialisation
                    %PEAK AMPLITUDE
                    metrics(indexParticipant,indexSignalType,indexCondition).dilationAmplitude=NaN;
                    %REFLEXIVE CONSTRICTION
                    metrics(indexParticipant,indexSignalType,indexCondition).pupilLightReflexAmplitude=NaN;
                    %LATENCY TO DILATION PEAK
                    metrics(indexParticipant,indexSignalType,indexCondition).latencyToDilationPeak=NaN;
                    %LATENCY TO CONSTRICTION PEAK
                    metrics(indexParticipant,indexSignalType,indexCondition).latencyToConstrictionPeak=NaN;
                    %DILATION VELOCITY
                    metrics(indexParticipant,indexSignalType,indexCondition).dilationVelocity=NaN;
                    %MEAN DILATION (=area under the curve)
                    metrics(indexParticipant,indexSignalType,indexCondition).meanDilation=NaN;
                    %MEAN REFLEXIVE CONSTRICTION (=area under the curve, for one second)
                    metrics(indexParticipant,indexSignalType,indexCondition).meanPupilLightReflex=NaN;
                    %RETURN TO BASELINE
                    metrics(indexParticipant,indexSignalType,indexCondition).durationOfResponse=NaN;
                    metrics(indexParticipant,indexSignalType,indexCondition).peakToBaselineLatency=NaN;
                    metrics(indexParticipant,indexSignalType,indexCondition).meanBaselineValue=NaN;

                    
                    if ~isnan(bufferSignal(1))
                    
                        %PEAK AMPLITUDE
                        [metrics(indexParticipant,indexSignalType,indexCondition).dilationAmplitude indexDilationAmplitude] = max(bufferSignal);

                        %REFLEXIVE CONSTRICTION
                        [metrics(indexParticipant,indexSignalType,indexCondition).pupilLightReflexAmplitude indexConstrictionAmplitude] = min(bufferSignalNegative(1:app.samplingFrequency));
    
                        %LATENCY TO DILATION PEAK
                        metrics(indexParticipant,indexSignalType,indexCondition).latencyToDilationPeak=indexDilationAmplitude/app.samplingFrequency;
                        
                        %LATENCY TO CONSTRICTION PEAK
                        metrics(indexParticipant,indexSignalType,indexCondition).latencyToConstrictionPeak=indexConstrictionAmplitude/app.samplingFrequency;
                        
                        %DILATION VELOCITY
                        metrics(indexParticipant,indexSignalType,indexCondition).dilationVelocity=metrics(indexParticipant,indexSignalType,indexCondition).dilationAmplitude/metrics(indexParticipant,indexSignalType,indexCondition).latencyToDilationPeak;
                        
                        %MEAN DILATION (=area under the curve)
                        metrics(indexParticipant,indexSignalType,indexCondition).meanDilation=mean(bufferSignalPositive);
                        
                        %MEAN REFLEXIVE CONSTRICTION (=area under the curve, for one second)
                        metrics(indexParticipant,indexSignalType,indexCondition).meanPupilLightReflex=mean(bufferSignalNegative(1:app.samplingFrequency));
                        
                        %RETURN TO BASELINE
                        returnToBaselineIndex=find(bufferSignal(indexDilationAmplitude+1:end) <= 5, 1, 'first')+indexDilationAmplitude;
                        if isempty(returnToBaselineIndex)
                            returnToBaselineIndex=length(bufferSignal);
                        end
                        metrics(indexParticipant,indexSignalType,indexCondition).durationOfResponse=returnToBaselineIndex/app.samplingFrequency;
                        metrics(indexParticipant,indexSignalType,indexCondition).peakToBaselineLatency=(returnToBaselineIndex-indexDilationAmplitude)/app.samplingFrequency;
                        
                        metrics(indexParticipant,indexSignalType,indexCondition).meanBaselineValue=app.meanBaselineValue(indexParticipant,indexCondition)  ;

                    end
                    
                    
                end
                
            end
        end


        app.pupilMetrics=metrics;
        app.pupilMetricsYoung=app.pupilMetrics(find(app.whosYoung),:,:);
        app.pupilMetricsOld=app.pupilMetrics(find(app.whosOld),:,:);


        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
        
        
        function results = isItSignificantInterGroupPupilMetrics(app)
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
        
            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            %tail="both";
            tail=app.allTailHypothesis;
            tail2="both";
            
                for cptCondition=1:numberOfConditions
%                 for cptCondition=1:1


                    %%% dilationAmplitude (1)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).dilationAmplitude;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).dilationAmplitude;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,1)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,1)=pvalueBuffer;

                    
                    %%% pupilLightReflexAmplitude (2)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).pupilLightReflexAmplitude;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).pupilLightReflexAmplitude;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,2)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,2)=pvalueBuffer;

                    
                    %%% latencyToDilationPeak (3)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).latencyToDilationPeak;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).latencyToDilationPeak;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,3)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,3)=pvalueBuffer;

                    
                    %%% latencyToConstrictionPeak (4)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).latencyToConstrictionPeak;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).latencyToConstrictionPeak;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,4)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,4)=pvalueBuffer;

                    %%% dilationVelocity (5)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).dilationVelocity;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).dilationVelocity;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,5)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,5)=pvalueBuffer;

                    %%% meanDilation (6)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanDilation;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanDilation;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,6)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,6)=pvalueBuffer;

                    %%% meanPupilLightReflex (7)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanPupilLightReflex;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanPupilLightReflex;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,7)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,7)=pvalueBuffer;

                    
                    %%% durationOfResponse (8)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).durationOfResponse;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).durationOfResponse;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,8)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,8)=pvalueBuffer;

                    %%% peakToBaselineLatency (9)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).peakToBaselineLatency;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).peakToBaselineLatency;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,9)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,9)=pvalueBuffer;

                    %%% baseline (10)
                    significanceBuffer=NaN;
                    pvalueBuffer=NaN;
                    clear youngDistributionForConditionOne oldDistributionForConditionOne
                    for cptYoung=1:numberOfYoungSubject
                        youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanBaselineValue;
                    end
                    for cptOld=1:numberOfOldSubject
                        oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanBaselineValue;
                    end
                    %We remove nan columns
                    youngDistributionForConditionOne=youngDistributionForConditionOne(~isnan(youngDistributionForConditionOne));
                    oldDistributionForConditionOne=oldDistributionForConditionOne(~isnan(oldDistributionForConditionOne));
                    if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                        pvalueBuffer=p;
                    end
                    app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,10)=significanceBuffer;
                    app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,10)=pvalueBuffer;
                end
                
                
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
        function results = displayResultsInterGroupPupilMetrics(app, indexSignalType, cptCondition, indexMetrics)
            
                numberOfYoungSubject=sum(app.whosYoung);
                numberOfOldSubject=sum(app.whosOld);
                numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
                
                switch indexMetrics
                    case 1
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).dilationAmplitude;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).dilationAmplitude;
                        end
                        
                    case 2      
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).pupilLightReflexAmplitude;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).pupilLightReflexAmplitude;
                        end
                        
                     case 3
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).latencyToDilationPeak;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).latencyToDilationPeak;
                        end
                        
                    case 4  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).latencyToConstrictionPeak;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).latencyToConstrictionPeak;
                        end   
                        
                    case 5  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).dilationVelocity;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).dilationVelocity;
                        end   
                        
                    case 6  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanDilation;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanDilation;
                        end                     
                    
                    case 7  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanPupilLightReflex;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanPupilLightReflex;
                        end    

                    case 8  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).durationOfResponse;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).durationOfResponse;
                        end
                        
                    case 9  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).peakToBaselineLatency;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).peakToBaselineLatency;
                        end

                    case 10  
                        for cptYoung=1:numberOfYoungSubject
                            youngDistributionForConditionOne(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,cptCondition).meanBaselineValue;
                        end
                        
                        for cptOld=1:numberOfOldSubject
                            oldDistributionForConditionOne(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,cptCondition).meanBaselineValue;
                        end
                end
                
                
                youngDistributionForConditionOne=youngDistributionForConditionOne';
                oldDistributionForConditionOne=oldDistributionForConditionOne';
                
                
                group = [ 1 * ones(size(youngDistributionForConditionOne))
                          2 * ones(size(oldDistributionForConditionOne))];
                
                data=[youngDistributionForConditionOne; oldDistributionForConditionOne];
                h = daboxplot(data,'groups',group,...
                                'xtlabels', {'Young', 'Old'},'colors', [app.colors(1,:);app.colors(2,:)],'whiskers',0,...
                                'scatter',1,'scattersize',10,'scatteralpha',0.5,...
                                'boxspacing',0.8); 
                ylabel('a.u.');
                set(gca,'FontSize',9.5);

                titleString=strcat(app.metricsName(indexMetrics), " (p = ", num2str(app.pValuePupilMetricsInterGroup(indexSignalType,cptCondition,indexMetrics) , '%.3f'));
% 
                if app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,indexMetrics)==1
                    titleString=strcat( titleString ,  " - d = ",num2str(computeCohen_d(youngDistributionForConditionOne,oldDistributionForConditionOne, 'independent'), '%.3f'), ")");
                else
                    titleString=strcat( titleString ,  ")" );
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                if app.significancePupilMetricsInterGroup(indexSignalType,cptCondition,indexMetrics)==1
%                     [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', 'both');
                    [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', 'both');
                    H=sigstar({[1,2]},[p]);
                end
                axis square                
                

                set(gca,'XTickLabel',{'Y','O'})


                ax = gca;
                ax.FontSize = 12;
                ax.FontWeight = 'bold';


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         

        function results = isItSignificantIntraGroupPupilMetrics(app)

            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            numberOfSubject=numberOfOldSubject+numberOfYoungSubject;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  YOUNG  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='j';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)
% 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)
  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfYoungSubject, ageGroup, tail)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  OLD   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ageGroup='a';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We first look at conditions Cong v. Incong, Ani v. Obj, Well categ v. Badly categ.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Cong v. Incong
            cptTest=1;
            indexConditionOne=find("Congruent"==app.conditions);
            indexConditionTwo=find("Incongruent"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)
            
            %Ani v. Obj
            cptTest=2;
            indexConditionOne=find("Animal"==app.conditions);
            indexConditionTwo=find("Object"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)
            
            %Well categ. v. Badly categ.
            cptTest=3;
            indexConditionOne=find("Well categorized"==app.conditions);
            indexConditionTwo=find("Badly categorized"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. (8v9)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=4;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Forgotten"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K. (10v11)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=5;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized K"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G. (10v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=6;
            indexConditionOne=find("Recognized R"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G. (11v12)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=7;
            indexConditionOne=find("Recognized K"==app.conditions);
            indexConditionTwo=find("Recognized G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (13v14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=8;
            indexConditionOne=find("Forgotten K"==app.conditions);
            indexConditionTwo=find("Forgotten G"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog v. Forgot. below CL (15v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=9;
            indexConditionOne=find("Recognized below CL"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog K below CL (17v18)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=10;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized K below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog R v. Recog G below CL (17v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=11;
            indexConditionOne=find("Recognized R below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog K v. Recog G below CL (18v19)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=12;
            indexConditionOne=find("Recognized K below CL"==app.conditions);
            indexConditionTwo=find("Recognized G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)
  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forgot K v. Forgot G. (20v21)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=13;
            indexConditionOne=find("Forgotten K below CL"==app.conditions);
            indexConditionTwo=find("Forgotten G below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Recog above CL v. Recog below CL (8v15)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=14;
            indexConditionOne=find("Recognized"==app.conditions);
            indexConditionTwo=find("Recognized below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % We then look at conditions Forget above CL v. Forget below CL (9v16)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cptTest=15;
            indexConditionOne=find("Forgotten"==app.conditions);
            indexConditionTwo=find("Forgotten below CL"==app.conditions);
            tail=app.testedConditionsTailHypothesis(cptTest);
            app.testedConditionsVectorForDisplayFunction(cptTest,:)=[indexConditionOne indexConditionTwo];
            conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfOldSubject, ageGroup, tail)
            
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
        function results = conditionOneVsConditionTwoTestingPupilMetrics(app, cptTest, indexConditionOne, indexConditionTwo, numberOfSubject, ageGroup, tail)

                    
        %We get the chosen tab
        chosenTab=string(app.TabGroup.SelectedTab.Title); 
        indexSignalType=find(chosenTab==app.signalTypes);             
                   
        tail2="both";     
        if ageGroup=='j'
            
            numberOfYoungSubject=numberOfSubject;
            
          
            %dilationAmplitude
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).dilationAmplitude;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).dilationAmplitude;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))

                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,1)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,1)=pvalueBuffer; 

            %pupilLightReflexAmplitude
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).pupilLightReflexAmplitude;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).pupilLightReflexAmplitude;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,2)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,2)=pvalueBuffer; 

            %latencyToDilationPeak
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).latencyToDilationPeak;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).latencyToDilationPeak;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))                
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,3)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,3)=pvalueBuffer; 

            %latencyToConstrictionPeak
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).latencyToConstrictionPeak;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).latencyToConstrictionPeak;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,4)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,4)=pvalueBuffer; 

            %dilationVelocity
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).dilationVelocity;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).dilationVelocity;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,5)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,5)=pvalueBuffer; 
            
            %meanDilation
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanDilation;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanDilation;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,6)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,6)=pvalueBuffer; 
            
            %meanPupilLightReflex
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanPupilLightReflex;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanPupilLightReflex;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,7)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,7)=pvalueBuffer; 
            
            %durationOfResponse
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).durationOfResponse;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).durationOfResponse;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,8)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,8)=pvalueBuffer; 
                        
            %peakToBaselineLatency
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).peakToBaselineLatency;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).peakToBaselineLatency;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,9)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,9)=pvalueBuffer; 
                        
            %baseline
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptYoung=1:numberOfYoungSubject
                distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanBaselineValue;
                distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanBaselineValue;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupYoung(cptTest,10)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupYoung(cptTest,10)=pvalueBuffer; 
            
        elseif ageGroup=='a'
        
            numberOfOldSubject=numberOfSubject;
            
          
            %dilationAmplitude
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).dilationAmplitude;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).dilationAmplitude;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))

                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,1)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,1)=pvalueBuffer; 
                        
            %pupilLightReflexAmplitude
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).pupilLightReflexAmplitude;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).pupilLightReflexAmplitude;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,2)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,2)=pvalueBuffer;                         
            
            %latencyToDilationPeak
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).latencyToDilationPeak;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).latencyToDilationPeak;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,3)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,3)=pvalueBuffer; 
            
            %latencyToConstrictionPeak
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).latencyToConstrictionPeak;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).latencyToConstrictionPeak;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,4)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,4)=pvalueBuffer; 
                        
            %dilationVelocity
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).dilationVelocity;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).dilationVelocity;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,5)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,5)=pvalueBuffer; 
                        
            %meanDilation
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanDilation;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanDilation;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,6)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,6)=pvalueBuffer; 
                        
            %meanPupilLightReflex
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanPupilLightReflex;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanPupilLightReflex;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,7)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,7)=pvalueBuffer; 
                        
            %durationOfResponse
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).durationOfResponse;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).durationOfResponse;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,8)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,8)=pvalueBuffer; 
                        
            %peakToBaselineLatency
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).peakToBaselineLatency;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).peakToBaselineLatency;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,9)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,9)=pvalueBuffer; 

            %baseline
            significanceBuffer=NaN;
            pvalueBuffer=NaN;
            clear distributionOfCondition1 distributionOfCondition2
            for cptOld=1:numberOfOldSubject
                distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanBaselineValue;
                distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanBaselineValue;
            end
            %We remove nan columns
            distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:));
            distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:));
            if ~ (isempty(distributionOfCondition1) || isempty(distributionOfCondition2))
                if isequal( size(distributionOfCondition1) , size(distributionOfCondition2) )
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                    [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', tail );
                else
                    error(sprintf("%d", cptTest))
                end
                significanceBuffer=h;
                pvalueBuffer=p;
            end
            app.significancePupilMetricsIntraGroupOld(cptTest,10)=significanceBuffer; 
            app.pValuePupilMetricsIntraGroupOld(cptTest,10)=pvalueBuffer; 
        end


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     


        function results = conditionOneVsConditionTwoDisplayPupilMetrics(app, cptTest, ageGroup, indexMetrics)
            
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            
            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);


            if ageGroup=='j'
            
                switch indexMetrics

                    case 1
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).dilationAmplitude;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).dilationAmplitude;
                        end
                    case 2
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).pupilLightReflexAmplitude;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).pupilLightReflexAmplitude;
                        end
                    case 3
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).latencyToDilationPeak;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).latencyToDilationPeak;
                        end
                    case 4
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).latencyToConstrictionPeak;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).latencyToConstrictionPeak;
                        end
                    case 5
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).dilationVelocity;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).dilationVelocity;
                        end
                    case 6
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanDilation;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanDilation;
                        end
                    case 7
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanPupilLightReflex;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanPupilLightReflex;
                        end
                    case 8
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).durationOfResponse;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).durationOfResponse;
                        end
                    case 9
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).peakToBaselineLatency;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).peakToBaselineLatency;
                        end
                    case 10
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfCondition1(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionOne).meanBaselineValue;
                            distributionOfCondition2(cptYoung)=app.pupilMetricsYoung(cptYoung,indexSignalType,indexConditionTwo).meanBaselineValue;
                        end
                end
                        
                    %We remove nan columns

                    distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupYoung(cptTest,:)  )';
                    distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupYoung(cptTest,:)   )';
                                        
                    group = [ 1 * ones(size(distributionOfCondition1))
                              2 * ones(size(distributionOfCondition2))];
            
                    data=[distributionOfCondition1; distributionOfCondition2];
                    h = daboxplot(data,'groups',group,...
                                    'colors', [app.colors(3,:);app.colors(4,:)],'whiskers',0,...
                                    'scatter',1,'scattersize',10,'scatteralpha',0.5,...
                                    'boxspacing',0.8); 
                    ylabel('a.u.');
                    set(gca,'FontSize',9.5);


                    titleString=strcat(app.metricsName(indexMetrics), " (p = ", num2str(app.pValuePupilMetricsIntraGroupYoung(cptTest,indexMetrics) , '%.3f'));
                    if app.significancePupilMetricsIntraGroupYoung(cptTest,indexMetrics)==1
                        titleString=strcat(titleString, " - d = ",num2str(computeCohen_d(distributionOfCondition1,distributionOfCondition2, 'paired'), '%.3f'), ")");
                    else
                        titleString=strcat( titleString ,  ")" );
                    end
                    
                    hold off;
                    xticks([1, 2]);
                    title(titleString, 'LineWidth', 0.8)
                    if app.significancePupilMetricsIntraGroupYoung(cptTest,indexMetrics)==1
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                        [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                        H=sigstar({[1,2]},p);
                    end
                    
                    axis square                    
    
                    set(gca,'XTickLabel',{'1','2'})
    
                    ax = gca;
                    ax.FontSize = 12;
                    ax.FontWeight = 'bold';

            elseif ageGroup=='a'
            
                switch indexMetrics

                    case 1
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).dilationAmplitude;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).dilationAmplitude;
                        end
                    case 2
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).pupilLightReflexAmplitude;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).pupilLightReflexAmplitude;
                        end
                    case 3
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).latencyToDilationPeak;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).latencyToDilationPeak;
                        end
                    case 4
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).latencyToConstrictionPeak;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).latencyToConstrictionPeak;
                        end
                    case 5
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).dilationVelocity;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).dilationVelocity;
                        end
                    case 6
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanDilation;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanDilation;
                        end
                    case 7
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanPupilLightReflex;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanPupilLightReflex;
                        end
                    case 8
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).durationOfResponse;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).durationOfResponse;
                        end
                    case 9
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).peakToBaselineLatency;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).peakToBaselineLatency;
                        end
                    case 10
                        for cptOld=1:numberOfOldSubject
                            distributionOfCondition1(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionOne).meanBaselineValue;
                            distributionOfCondition2(cptOld)=app.pupilMetricsOld(cptOld,indexSignalType,indexConditionTwo).meanBaselineValue;
                        end
                end
                        
                    %We remove nan columns
                    distributionOfCondition1=distributionOfCondition1(~app.whoIsNanIntraGroupOld(cptTest,:) )';
                    distributionOfCondition2=distributionOfCondition2(~app.whoIsNanIntraGroupOld(cptTest,:) )';
                    group = [ 1 * ones(size(distributionOfCondition1))
                              2 * ones(size(distributionOfCondition2))];
                    
                    data=[distributionOfCondition1; distributionOfCondition2];
                    h = daboxplot(data,'groups',group,...
                                    'colors', [app.colors(3,:);app.colors(4,:)],'whiskers',0,...
                                    'scatter',1,'scattersize',10,'scatteralpha',0.5,...
                                    'boxspacing',0.8); 
                    ylabel('a.u.');
                    set(gca,'FontSize',9.5);
                    
                    titleString=strcat(app.metricsName(indexMetrics), " (p = ", num2str(app.pValuePupilMetricsIntraGroupOld(cptTest,indexMetrics) , '%.3f'));
                    if app.significancePupilMetricsIntraGroupOld(cptTest,indexMetrics)==1
                        titleString=strcat(titleString, " - d = ",num2str(computeCohen_d(distributionOfCondition1,distributionOfCondition2, 'paired'), '%.3f'), ")");
                    else
                        titleString=strcat( titleString ,  ")" );
                    end
                    hold off;
                    xticks([1, 2]);
                    title(titleString, 'LineWidth', 0.8)
                    
                   if app.significancePupilMetricsIntraGroupOld(cptTest,indexMetrics)==1
%                     [p h]=signrank(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                        [h p]=ttest(distributionOfCondition1,distributionOfCondition2, 'tail', 'both');
                        H=sigstar({[1,2]},p);
                   end
                   axis square  
                    
    
                    set(gca,'XTickLabel',{'1','2'})
                    
                    ax = gca;
                    ax.FontSize = 12;
                    ax.FontWeight = 'bold';
                           
            end
            
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    

        function overallIndices = indicesWithinGroupToOverallIndices(app, ageGroup, binaryVectorWithinGroup)
            
            overallIndices=[];
            indicesWithinGroup=find(binaryVectorWithinGroup);

            if ageGroup=='j'
                if sum(app.whosYoung)~=length(binaryVectorWithinGroup)
                    error("discrepancy between number of young people")
                end
                
                findYoung=find(app.whosYoung);

                for cpt=1:length(indicesWithinGroup)
                    overallIndices(cpt)=findYoung(indicesWithinGroup(cpt));
                end
                
            else
                if sum(app.whosOld)~=length(binaryVectorWithinGroup)
                    error("discrepancy between number of old people")
                end
                
                findOld=find(app.whosOld);

                for cpt=1:length(indicesWithinGroup)
                    overallIndices(cpt)=findOld(indicesWithinGroup(cpt));
                end
                
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
        
function recomputedMean = recomputeMeanForIntraGroup(app, ageGroup, indexSignalType, cptTest, indexCondition)


            %We extract the median responses of the young group
            app.medianPupilResponsesYoung=app.medianPupilResponses(find(app.whosYoung));
            %We extract the median responses of the old group
            app.medianPupilResponsesOld=app.medianPupilResponses(find(app.whosOld));
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
    
            nSigma=app.sigmaForConfidenceInterval;
            
                    if ageGroup == 'j'
                       medianPupilResponsesYoungBuffer=app.medianPupilResponsesYoung;
                        
                       matrixForComputingMeanResponseYoung=nan(length(medianPupilResponsesYoungBuffer{1}.clean(:,1,1)), numberOfYoungSubject);
                       numberOfMediansComputedYoung=0;
                        for cptYoung=1:numberOfYoungSubject
                            if ~app.whoIsNanIntraGroupYoung(cptTest,cptYoung)
                                    matrixForComputingMeanResponseYoung(:,cptYoung)=medianPupilResponsesYoungBuffer{cptYoung}.clean(:,indexSignalType,indexCondition);
                                    numberOfMediansComputedYoung=numberOfMediansComputedYoung+1;
                            end
                        end
                        
                        recomputedMean.clean=nanmean(matrixForComputingMeanResponseYoung,2);
                        standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseYoung, 0, 2);
 
                        recomputedMean.lowerConfidenceInterval=recomputedMean.clean - nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedYoung);
                        recomputedMean.upperConfidenceInterval=recomputedMean.clean + nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedYoung);
                        recomputedMean.numberOfUsedMedians=numberOfMediansComputedYoung;
%             
                        
                    elseif ageGroup == 'a'
                       medianPupilResponsesOldBuffer=app.medianPupilResponsesOld;
                       matrixForComputingMeanResponseOld=nan(length(medianPupilResponsesOldBuffer{1}.clean(:,1,1)), numberOfOldSubject );

                       numberOfMediansComputedOld=0;
                        for cptOld=1:numberOfOldSubject
                            if ~app.whoIsNanIntraGroupOld(cptTest,cptOld)
                                    matrixForComputingMeanResponseOld(:,cptOld)=medianPupilResponsesOldBuffer{cptOld}.clean(:,indexSignalType,indexCondition);
                                    numberOfMediansComputedOld=numberOfMediansComputedOld+1;
                            end
                        end
                        
                        recomputedMean.clean=nanmean(matrixForComputingMeanResponseOld,2);
                        standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseOld, 0, 2);
 
                        recomputedMean.lowerConfidenceInterval=recomputedMean.clean - nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedOld);
                        recomputedMean.upperConfidenceInterval=recomputedMean.clean + nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedOld);
                        recomputedMean.numberOfUsedMedians=numberOfMediansComputedOld;
                        
                        
                    end


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

        function plotBF10(app, bayesFactorVector)
           
            indices = zeros(1, numel(bayesFactorVector));
            
            indices(  bayesFactorVector < 1/10                               ) = 1;  % 
            indices( (bayesFactorVector >= 1/10) & (bayesFactorVector  < 1/3)) = 2;  % 
            indices( (bayesFactorVector >= 1/3 ) & (bayesFactorVector  < 3  )) = 3;  % 
            indices( (bayesFactorVector >= 3 )   & (bayesFactorVector  < 10 )) = 4;  % 
            indices(  bayesFactorVector >= 10                                ) = 5;  % 

            cmap = [ 153 0   0  ;
                     255 0   0  ;
                     255 255 0  ;
                     0   255 0  ;
                     0   153 0  ]/255;
            
            image(indices)
            colormap(cmap)
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  


        function results = plotGrayAreaSignificance(app, significanceVector, min, max, timepoints)
                        
            indices = find(significanceVector == 1);
            
            if ~isempty(indices)
                    
                startIndices = indices([true, diff(indices) > 1]);
                endIndices = indices([diff(indices) > 1, true]);
                
    
                hold on
                for i = 1:length(startIndices)
                    x = [timepoints(startIndices(i)), timepoints(endIndices(i)), timepoints(endIndices(i)), timepoints(startIndices(i))];
                    y = [min, min, max, max];
                    fill(x, y, [59, 227, 70]/255, 'EdgeColor', 'none', 'FaceAlpha', 0.20); % Remplir en vert clair
                end
      
            
            end

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

        function results = displayResultsInterGroupRecognitionRates(app)
             
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            
            
            %app.recognitionRates contains recognition rates of All,
            %Animal, Object, Natural, Artificial, Congruent, Incongruent
            %conditions 
            
            bufferConditionOfInterest=[1, 4, 5, 2, 3];
            recognitionRateConditionsOfInterest=app.conditions(bufferConditionOfInterest);
            
            youngRecognitionRates={app.recognitionRates{find(app.whosYoung)}};
            oldRecognitionRates={app.recognitionRates{find(app.whosOld)}};
                   
            for indexConditionOfInterest = 1:numel(bufferConditionOfInterest)
                figureHandle1=figure(7000+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Recognition rate - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest))));

                clear youngDistributionForConditionOne oldDistributionForConditionOne
                
                for cpt=1:numberOfYoungSubject
                    youngDistributionForConditionOne(cpt)=youngRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                end
                
                for cpt=1:numberOfOldSubject
                    oldDistributionForConditionOne(cpt)=oldRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                end
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                youngDistributionForConditionOne=100*youngDistributionForConditionOne';
                oldDistributionForConditionOne=100*oldDistributionForConditionOne';
                
                
                group = [ 1 * ones(size(youngDistributionForConditionOne))
                          2 * ones(size(oldDistributionForConditionOne))];
                
                
                donnees{1}=youngDistributionForConditionOne;
                donnees{2}=oldDistributionForConditionOne;
                h = daviolinplot(donnees,'colors',[app.colors(1,:);app.colors(2,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {strcat("Young (n=", num2str(numberOfYoungSubject),")"), ...
                    strcat("Old (n=", num2str(numberOfOldSubject),")") }, 'scattercolors','same',...
                    'legend',...
                    {strcat("Young people above CL : ", num2str(sum(youngDistributionForConditionOne>=59)), " (below CL : ", num2str(sum(youngDistributionForConditionOne<59)), ")"), strcat("Older people above CL : ", num2str(sum(oldDistributionForConditionOne>=59)), " (below CL : ", num2str(sum(oldDistributionForConditionOne<59)), ")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);
                
                if bufferConditionOfInterest(indexConditionOfInterest)==1
                    hold on;
                    y = 59; 
                    xLimits = xlim; 
                    line(xLimits, [y y], 'Color', 'r', 'LineWidth', 2);
                    xCenter = mean(xLimits);
                    yLimits = ylim; 
                    yCenter = mean(yLimits);
                    
                    text(xCenter, yCenter, 'Chance level = 59%', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 15, 'Color', 'r', 'FontWeight', 'bold');
                    hold off;


                end
                    
                titleString=strcat("Recognition rate - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(youngDistributionForConditionOne,oldDistributionForConditionOne, 'independent'), '%.3f'), ")");
%                     [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne);
                    [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne);               
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca; 
                ax.FontSize = 20;
                ax.FontWeight = 'bold';            
            end
            
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

        function results = displayResultsIntraGroupRecognitionRates(app)
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            
            
            %Animal, Object Congruent, Incongruent
            %conditions 
            
            bufferConditionOfInterest=[4, 2];
            recognitionRateConditionsOfInterest=app.conditions(bufferConditionOfInterest);
            
            youngRecognitionRates={app.recognitionRates{find(app.whosYoung)}};
            oldRecognitionRates={app.recognitionRates{find(app.whosOld)}};
                   
            %%YOUNG PEOPLE%
            
            for indexConditionOfInterest = 1:numel(bufferConditionOfInterest)
                figureHandle1=figure(70000+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Recognition rate - Young - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1) ) );

                clear youngDistributionForConditionOne youngDistributionForConditionTwo
                
                for cpt=1:numberOfYoungSubject
                    youngDistributionForConditionOne(cpt)=youngRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                    youngDistributionForConditionTwo(cpt)=youngRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest)+1);

                end
               
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(youngDistributionForConditionOne) || isempty(youngDistributionForConditionTwo))
%                         [p h]=signrank(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', tail);
                        [h p]=ttest(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                youngDistributionForConditionOne=100*youngDistributionForConditionOne';
                youngDistributionForConditionTwo=100*youngDistributionForConditionTwo';
                
                
                group = [ 1 * ones(size(youngDistributionForConditionOne))
                          2 * ones(size(youngDistributionForConditionTwo))];
                
                
                donnees{1}=youngDistributionForConditionOne;
                donnees{2}=youngDistributionForConditionTwo;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), ...
                    app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1) }, 'scattercolors','same',...
                    'legend',...
                    {strcat(app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " - young people above CL : ", num2str(sum(youngDistributionForConditionOne>=59)), " (below CL : ", num2str(sum(youngDistributionForConditionOne<59)), ")"), strcat(app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1), " - young people above CL ", num2str(sum(youngDistributionForConditionTwo>=59)), " (below CL: ", num2str(sum(youngDistributionForConditionTwo<59)), ")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);


                titleString=strcat("Recognition rate - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'paired'), '%.3f'), ")");
%                     [p h]=signrank(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', 'both');
                    [h p]=ttest(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', 'both');                 
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca; 
                ax.FontSize = 20; 
                ax.FontWeight = 'bold';            
            end


            %%OLD PEOPLE%
            
            for indexConditionOfInterest = 1:numel(bufferConditionOfInterest)
                figureHandle1=figure(70000+2+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Recognition rate - Older - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1) ) );

                clear oldDistributionForConditionOne oldDistributionForConditionTwo
                
                for cpt=1:numberOfOldSubject
                    oldDistributionForConditionOne(cpt)=oldRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                    oldDistributionForConditionTwo(cpt)=oldRecognitionRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest)+1);

                end
               
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(oldDistributionForConditionOne) || isempty(oldDistributionForConditionTwo))
%                         [p h]=signrank(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', tail);
                        [h p]=ttest(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                oldDistributionForConditionOne=100*oldDistributionForConditionOne';
                oldDistributionForConditionTwo=100*oldDistributionForConditionTwo';
                
                
                group = [ 1 * ones(size(oldDistributionForConditionOne))
                          2 * ones(size(oldDistributionForConditionTwo))];
                
                
                donnees{1}=oldDistributionForConditionOne;
                donnees{2}=oldDistributionForConditionTwo;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), ...
                    app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1) }, 'scattercolors','same',...
                    'legend',...
                    {strcat(app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " - old people above CL : ", num2str(sum(oldDistributionForConditionOne>=59)), " (below CL : ", num2str(sum(oldDistributionForConditionOne<59)), ")"), strcat(app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1), " - old people above CL ", num2str(sum(oldDistributionForConditionTwo>=59)), " (below CL: ", num2str(sum(oldDistributionForConditionTwo<59)), ")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);


                titleString=strcat("Recognition rate - ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", app.conditions(bufferConditionOfInterest(indexConditionOfInterest)+1));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'paired'), '%.3f'), ")");
%                     [p h]=signrank(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', 'both');
                    [h p]=ttest(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', 'both');
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca; 
                ax.FontSize = 20; 
                ax.FontWeight = 'bold';            
            end
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        
        function results = computeCategorizationTimes(app)
              
        for indexSubject=1:app.nParticipant
            %We get all his trials
            allTrialsOfSelectedSubject=app.everyTrial{indexSubject};  
            
            nWellCategorizedTrials=0;
            nWellCategorizedCongruentTrials=0;
            nWellCategorizedIncongruentTrials=0;
            nWellCategorizedAnimalTrials=0;
            nWellCategorizedObjectTrials=0;
    
            nWellCategorizedRecognizedTrials=0;
                        nWellCategorizedRecognizedTrials_R=0;
                        nWellCategorizedRecognizedTrials_K=0;
                        nWellCategorizedRecognizedTrials_G=0;
            nWellCategorizedForgottenTrials=0;
                        nWellCategorizedForgottenTrials_K=0;
                        nWellCategorizedForgottenTrials_G=0;
                for cptTrial=1:72
                
                    %We take the trial n°cptTrial of subject indexSubject
                    currentBufferTrial=allTrialsOfSelectedSubject(cptTrial);
                    
                    %We extract the different conditions
                    conditionForeground = currentBufferTrial.foreground;
                    conditionCongruence = currentBufferTrial.congruence;
                    conditionRecognition = currentBufferTrial.isRecognized;
                    conditionRecognitionTrustLevel = currentBufferTrial.trustLevel;
                    conditionCategorization = currentBufferTrial.categorizationResult;
                     

                    %%%categorisation
                    if conditionCategorization == "G"   %good categorization
                        nWellCategorizedTrials=nWellCategorizedTrials+1;
                                                
                        %%%congruence
                        if conditionCongruence == 'C'   %congruent
                            nWellCategorizedCongruentTrials=nWellCategorizedCongruentTrials+1;
                        elseif conditionCongruence == 'I'          %incongruent
                            nWellCategorizedIncongruentTrials=nWellCategorizedIncongruentTrials+1;
                        end
                        
                        %%%foreground
                        if conditionForeground == 'ani' %animal
                            nWellCategorizedAnimalTrials=nWellCategorizedAnimalTrials+1;
                        elseif conditionForeground == 'obj' %object
                            nWellCategorizedObjectTrials=nWellCategorizedObjectTrials+1;
                        end
                    
                        %%%recognition
                    
                        if conditionRecognition == 'R'   %recognized
                            nWellCategorizedRecognizedTrials=nWellCategorizedRecognizedTrials+1;
                            
                            %trust level
                                %remember
                                if conditionRecognitionTrustLevel=="R"
                                    nWellCategorizedRecognizedTrials_R=nWellCategorizedRecognizedTrials_R+1;
                                %know
                                elseif conditionRecognitionTrustLevel=="K" 
                                    nWellCategorizedRecognizedTrials_K=nWellCategorizedRecognizedTrials_K+1;
                                %guess
                                elseif conditionRecognitionTrustLevel=="G" 
                                    nWellCategorizedRecognizedTrials_G=nWellCategorizedRecognizedTrials_G+1;
                                end
                                
                        elseif conditionRecognition == 'N'   %not recognized = forgotten
                            nWellCategorizedForgottenTrials=nWellCategorizedForgottenTrials+1;                          
                            %trust level
                                %know
                                if conditionRecognitionTrustLevel=="K"  
                                    nWellCategorizedForgottenTrials_K=nWellCategorizedForgottenTrials_K+1;                                        
                                %guess
                                elseif conditionRecognitionTrustLevel=="G" 
                                    nWellCategorizedForgottenTrials_G=nWellCategorizedForgottenTrials_G+1;
                                end 
                        end
                    end        
                end
                           
                   
                allCategorizationRate=nWellCategorizedTrials/72;
                congruentCategorizationRate=nWellCategorizedCongruentTrials/36;
                incongruentCategorizationRate=nWellCategorizedIncongruentTrials/36;
                animalCategorizationRate=nWellCategorizedAnimalTrials/36;
                objectCategorizationRate=nWellCategorizedObjectTrials/36;
                recognizedCategorizationRate=nWellCategorizedRecognizedTrials/72;
                recognizedRCategorizationRate=nWellCategorizedRecognizedTrials_R/72;
                recognizedKCategorizationRate=nWellCategorizedRecognizedTrials_K/72;
                recognizedGCategorizationRate=nWellCategorizedRecognizedTrials_G/72;
                forgottenCategorizationRate=nWellCategorizedForgottenTrials/72;
                forgottenKCategorizationRate=nWellCategorizedForgottenTrials_K/72;
                forgottenGCategorizationRate=nWellCategorizedForgottenTrials_G/72;


                app.categorizationRates{indexSubject}=[allCategorizationRate
                                                       congruentCategorizationRate
                                                       incongruentCategorizationRate
                                                       animalCategorizationRate
                                                       objectCategorizationRate
                                                       recognizedCategorizationRate
                                                       recognizedRCategorizationRate
                                                       recognizedKCategorizationRate
                                                       recognizedGCategorizationRate
                                                       forgottenCategorizationRate
                                                       forgottenKCategorizationRate
                                                       forgottenGCategorizationRate];
                
        end
        
        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         

        

        function results = displayResultsInterGroupCategorizationRates(app)
            
            
            categorizationRateConditionsOfInterest=["All",...
                          "Congruent", "Incongruent",...
                          "Animal", "Object" ,...
                          "Recognized" ,...
                          "Recognized R", "Recognized K", "Recognized G",...
                          "Forgotten" ,...
                          "Forgotten K", "Forgotten G"];
            
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
          
            %app.categorizationRates contains categorization rates of All,
            %Congruent, Incongruent, Animal, Object, Recognized,
            %RecognizedR, RecognizedK, RecognizedG, Forgotten, ForgottenK,
            %ForgottenG conditions 
                        
            youngCategorizationRates={app.categorizationRates{find(app.whosYoung)}};
            oldCategorizationRates={app.categorizationRates{find(app.whosOld)}};
                   
            for indexConditionOfInterest = 1:numel(categorizationRateConditionsOfInterest)
                figureHandle1=figure(8500+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Categorization rate - ", categorizationRateConditionsOfInterest(indexConditionOfInterest)));

                clear youngDistributionForConditionOne oldDistributionForConditionOne
                
                for cpt=1:numberOfYoungSubject
                    youngDistributionForConditionOne(cpt)=youngCategorizationRates{cpt}(indexConditionOfInterest);
                end
                
                for cpt=1:numberOfOldSubject
                    oldDistributionForConditionOne(cpt)=oldCategorizationRates{cpt}(indexConditionOfInterest);
                end
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(youngDistributionForConditionOne) || isempty(oldDistributionForConditionOne))
%                         [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                youngDistributionForConditionOne=100*youngDistributionForConditionOne';
                oldDistributionForConditionOne=100*oldDistributionForConditionOne';
                
                
                group = [ 1 * ones(size(youngDistributionForConditionOne))
                          2 * ones(size(oldDistributionForConditionOne))];
                
                
                donnees{1}=youngDistributionForConditionOne;
                donnees{2}=oldDistributionForConditionOne;
                h = daviolinplot(donnees,'colors',[app.colors(1,:);app.colors(2,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {strcat("Young (n=", num2str(numberOfYoungSubject),")"), ...
                    strcat("Old (n=", num2str(numberOfOldSubject),")") }, 'scattercolors','same',...
                    'legend',...
                    {strcat("Young (n=", num2str(numberOfYoungSubject), ")"),  strcat("Old (n=", num2str(numberOfOldSubject), ")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);
                
                    
                titleString=strcat("Categorization rate - ", categorizationRateConditionsOfInterest(indexConditionOfInterest));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(youngDistributionForConditionOne,oldDistributionForConditionOne, 'independent'), '%.3f'), ")");
%                     [p h]=ranksum(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', 'both');
                    [h p]=ttest2(youngDistributionForConditionOne,oldDistributionForConditionOne, 'tail', 'both');  
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca; 
                ax.FontSize = 20;
                ax.FontWeight = 'bold'; 
                ylim([0 120]);            
            end
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
        function results = displayResultsIntraGroupCategorizationRates(app)
            
               
            numberOfYoungSubject=sum(app.whosYoung);
            numberOfOldSubject=sum(app.whosOld);
            
            
            %Animal, Object Congruent, Incongruent
            %conditions 
            
           categorizationRateConditionsOfInterest=["Congruent", "Incongruent",...
                                                   "Animal", "Object" ];
           bufferConditionOfInterest=[1,3];
           
            youngCategorizationRates={app.categorizationRates{find(app.whosYoung)}};
            oldCategorizationRates={app.categorizationRates{find(app.whosOld)}};
                   
            %%YOUNG PEOPLE%
            
            for indexConditionOfInterest = 1:numel(bufferConditionOfInterest)
                figureHandle1=figure(8600+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Categorization rate - Young - ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1) ) );

                clear youngDistributionForConditionOne youngDistributionForConditionTwo
                
                for cpt=1:numberOfYoungSubject
                    youngDistributionForConditionOne(cpt)=youngCategorizationRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                    youngDistributionForConditionTwo(cpt)=youngCategorizationRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest)+1);

                end
               
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(youngDistributionForConditionOne) || isempty(youngDistributionForConditionTwo))
%                         [p h]=signrank(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', tail);
                        [h p]=ttest(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                youngDistributionForConditionOne=100*youngDistributionForConditionOne';
                youngDistributionForConditionTwo=100*youngDistributionForConditionTwo';
                
                
                group = [ 1 * ones(size(youngDistributionForConditionOne))
                          2 * ones(size(youngDistributionForConditionTwo))];
                
                
                donnees{1}=youngDistributionForConditionOne;
                donnees{2}=youngDistributionForConditionTwo;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), ...
                    categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1) }, 'scattercolors','same',...
                    'legend',...
                    {strcat(categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " - young (n=", num2str(numberOfYoungSubject), ")"), strcat(categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1), " - young (n=", num2str(numberOfYoungSubject),")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);


                titleString=strcat("Categorization rate - ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'paired'), '%.3f'), ")");
%                     [p h]=signrank(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', 'both');
                    [h p]=ttest(youngDistributionForConditionOne,youngDistributionForConditionTwo, 'tail', 'both'); 
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca;
                ax.FontSize = 20; 
                ax.FontWeight = 'bold';            
                ylim([0 120])
            end


            %%OLD PEOPLE%
            
            for indexConditionOfInterest = 1:numel(bufferConditionOfInterest)
                figureHandle1=figure(8600+2+indexConditionOfInterest);
                set(figureHandle1,'units','normalized','outerposition',[0 0 1 1], 'Name', strcat("Categorization rate - Older - ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1) ) );

                clear oldDistributionForConditionOne oldDistributionForConditionTwo
                
                for cpt=1:numberOfOldSubject
                    oldDistributionForConditionOne(cpt)=oldCategorizationRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest));
                    oldDistributionForConditionTwo(cpt)=oldCategorizationRates{cpt}(bufferConditionOfInterest(indexConditionOfInterest)+1);

                end
               
                        
                tail=app.allTailHypothesis;
                if ~ (isempty(oldDistributionForConditionOne) || isempty(oldDistributionForConditionTwo))
%                         [p h]=signrank(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', tail);
                        [h p]=ttest(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', tail);
                        significanceBuffer=h;
                end   
            
                oldDistributionForConditionOne=100*oldDistributionForConditionOne';
                oldDistributionForConditionTwo=100*oldDistributionForConditionTwo';
                
                
                group = [ 1 * ones(size(oldDistributionForConditionOne))
                          2 * ones(size(oldDistributionForConditionTwo))];
                
                
                donnees{1}=oldDistributionForConditionOne;
                donnees{2}=oldDistributionForConditionTwo;
                h = daviolinplot(donnees,'colors',[app.colors(3,:);app.colors(4,:)],'boxcolors','k','outliers',0,...
                    'box',3,'boxwidth',0.8,'scatter',2,'scattersize',35,'jitter',1,...
                    'xtlabels', {categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), ...
                    categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1) }, 'scattercolors','same',...
                    'legend',...
                    {strcat(categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " - old (n=", num2str(numberOfOldSubject), ")"), strcat(categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1), " - old (n=", num2str(numberOfOldSubject),")")}); 
                ylabel('Performance (%)');
                xl = xlim; xlim([xl(1)-0.2, xl(2)+0.2]); % make more space for the legend
                set(gca,'FontSize',10);


                titleString=strcat("Categorization rate - ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)), " v. ", categorizationRateConditionsOfInterest(bufferConditionOfInterest(indexConditionOfInterest)+1));
% 
                if significanceBuffer==1
                    titleString=strcat( titleString ,  " (d = ",num2str(computeCohen_d(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'paired'), '%.3f'), ")");
%                         [p h]=signrank(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', tail);
                    [h p]=ttest(oldDistributionForConditionOne,oldDistributionForConditionTwo, 'tail', 'both');
                    H=sigstar({[1,2]},[p]);
                end
                hold off;
                xticks([1, 2]);
                title(titleString, 'LineWidth', 0.8)
                axis square
                lg=legend;
                lg.String=lg.String(1:2);
                ax = gca; 
                ax.FontSize = 20; 
                ax.FontWeight = 'bold';      
                ylim([0 120])
            end
         
            
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 


function results = computeDifferenceBetweenConditions(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfSubject, ageGroup, whoIsNan)


                if ageGroup=='j'
        
                    numberOfYoungSubject=numberOfSubject;
                    
                    for cptYoung=1:numberOfYoungSubject
                        matrixContainingMediansForFirstConditionYoung=app.medianPupilResponsesYoung{cptYoung}.clean(:,indexSignalType,indexConditionOne);
                        matrixContainingMediansForNextConditionYoung=app.medianPupilResponsesYoung{cptYoung}.clean(:,indexSignalType,indexConditionTwo);
                        differenceBuffer=matrixContainingMediansForFirstConditionYoung-matrixContainingMediansForNextConditionYoung;

                        app.matrixOfDifferencesBetweenConditionsIntraGroupYoung{cptYoung}(:,indexSignalType,cptTest)=differenceBuffer;
                    
                    end


                elseif  ageGroup=='a'
                    
                    numberOfOldSubject=numberOfSubject;
                    
                    for cptOld=1:numberOfOldSubject
                        matrixContainingMediansForFirstConditionOld=app.medianPupilResponsesOld{cptOld}.clean(:,indexSignalType,indexConditionOne);
                        matrixContainingMediansForNextConditionOld=app.medianPupilResponsesOld{cptOld}.clean(:,indexSignalType,indexConditionTwo);
                    
                        differenceBuffer=matrixContainingMediansForFirstConditionOld-matrixContainingMediansForNextConditionOld;

                        app.matrixOfDifferencesBetweenConditionsIntraGroupOld{cptOld}(:,indexSignalType,cptTest)=differenceBuffer;
                    end


                end
                            
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        
        function results = isItSignificantDifferenceBetweenConditions(app, cptTest, indexConditionOne, indexConditionTwo, indexSignalType, numberOfSubject, ageGroup, whoIsNan, numberOfPoints, tail)
            
            
            if ageGroup=='j'
            
                numberOfYoungSubject=numberOfSubject;
                
                significanceBuffer=zeros(1,numberOfPoints);
                bayesFactorBuffer=zeros(1,numberOfPoints);
                pValueBuffer=zeros(1,numberOfPoints);
                
                    for cptPoint=1:numberOfPoints
                        
                        for cptYoung=1:numberOfYoungSubject
                            distributionOfConditionDifferencesAtChosenPoint(cptYoung)=app.matrixOfDifferencesBetweenConditionsIntraGroupYoung{cptYoung}(cptPoint,indexSignalType,cptTest);
                        end
                    
                        %we compute the significance
                        significanceBuffer(cptPoint)=0;
                        bayesFactorBuffer(cptPoint)=NaN;
                        pValueBuffer(cptPoint)=NaN;
                        
                        whoIsNan=isnan(distributionOfConditionDifferencesAtChosenPoint);
                        distributionOfConditionDifferencesAtChosenPoint=distributionOfConditionDifferencesAtChosenPoint(~whoIsNan);

                        if ~ isempty(distributionOfConditionDifferencesAtChosenPoint) 
        
%                             [p h]=signrank(distributionOfConditionDifferencesAtChosenPoint);
                            [h p]=ttest(distributionOfConditionDifferencesAtChosenPoint);
                            [BF10 pBF]=bf.ttest(distributionOfConditionDifferencesAtChosenPoint);
                       
                            bayesFactorBuffer(cptPoint)=BF10;
                            pValueBuffer(cptPoint)=p;
                            significanceBuffer(cptPoint)=p<app.pThresholdCluster;
                        end
                    end
            
                    app.significanceMatrixOfDifferencesIntraGroupYoung(:,indexSignalType,cptTest)=significanceBuffer;  
                    app.bayesFactorMatrixOfDifferencesIntraGroupYoung(:,indexSignalType,cptTest)=bayesFactorBuffer;  
                    app.pValueMatrixOfDifferencesIntraGroupYoung(:,indexSignalType,cptTest)=pValueBuffer;
                

            elseif  ageGroup=='a'
                
                numberOfOldSubject=numberOfSubject;
                
                significanceBuffer=zeros(1,numberOfPoints);
                bayesFactorBuffer=zeros(1,numberOfPoints);
                pValueBuffer=zeros(1,numberOfPoints);
                
                    for cptPoint=1:numberOfPoints
                        
                        for cptOld=1:numberOfOldSubject
                            distributionOfConditionDifferencesAtChosenPoint(cptOld)=app.matrixOfDifferencesBetweenConditionsIntraGroupOld{cptOld}(cptPoint,indexSignalType,cptTest);
                        end
                    
                        %we compute the significance
                        significanceBuffer(cptPoint)=0;
                        bayesFactorBuffer(cptPoint)=NaN;
                        pValueBuffer(cptPoint)=NaN;
                        
                         
                        whoIsNan=isnan(distributionOfConditionDifferencesAtChosenPoint);
                        distributionOfConditionDifferencesAtChosenPoint=distributionOfConditionDifferencesAtChosenPoint(~whoIsNan);

                        
                        if ~ isempty(distributionOfConditionDifferencesAtChosenPoint) 
        
%                             [p h]=signrank(distributionOfConditionDifferencesAtChosenPoint );
                            [h p]=ttest(distributionOfConditionDifferencesAtChosenPoint);
                            [BF10 pBF]=bf.ttest(distributionOfConditionDifferencesAtChosenPoint );
                       
                            bayesFactorBuffer(cptPoint)=BF10;
                            pValueBuffer(cptPoint)=p;
                            significanceBuffer(cptPoint)=p<app.pThresholdCluster;
                        end
                    end
            
                    app.significanceMatrixOfDifferencesIntraGroupOld(:,indexSignalType,cptTest)=significanceBuffer;  
                    app.bayesFactorMatrixOfDifferencesIntraGroupOld(:,indexSignalType,cptTest)=bayesFactorBuffer;  
                    app.pValueMatrixOfDifferencesIntraGroupOld(:,indexSignalType,cptTest)=pValueBuffer;
            
            end
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                
        function computedMeanForConditionsDifferences = computeMeanForMatrixOfDifferencesBetweenConditionsIntraGroup(app, ageGroup, indexSignalType, cptTest)
        %          
            
        numberOfYoungSubject=sum(app.whosYoung);
        numberOfOldSubject=sum(app.whosOld);
        nSigma=app.sigmaForConfidenceInterval;
        [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);

            if ageGroup == 'j'
                           medianPupilResponsesYoungBufferConditionsDifferences=app.matrixOfDifferencesBetweenConditionsIntraGroupYoung;
                            
                           matrixForComputingMeanResponseYoungConditionDifferences=nan(numberOfPoints, numberOfYoungSubject);
                           matrixForComputingMeanResponseYoung=nan(numberOfPoints,numberOfYoungSubject);
                           
                           numberOfMediansComputedYoung=0;
                            for cptYoung=1:numberOfYoungSubject
                                if ~app.whoIsNanIntraGroupYoung(cptTest,cptYoung)
                                        matrixForComputingMeanResponseYoung(:,cptYoung)=medianPupilResponsesYoungBufferConditionsDifferences{cptYoung}(:,indexSignalType,cptTest);
                                        numberOfMediansComputedYoung=numberOfMediansComputedYoung+1;
                                end
                            end
                            
                            computedMeanForConditionsDifferences.clean=nanmean(matrixForComputingMeanResponseYoung,2);
                            standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseYoung, 0, 2);
            
                            computedMeanForConditionsDifferences.lowerConfidenceInterval=computedMeanForConditionsDifferences.clean - nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedYoung);
                            computedMeanForConditionsDifferences.upperConfidenceInterval=computedMeanForConditionsDifferences.clean + nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedYoung);
                            computedMeanForConditionsDifferences.numberOfUsedMedians=numberOfMediansComputedYoung;
                            
            elseif ageGroup=='a'
             
                           medianPupilResponsesOldBufferConditionsDifferences=app.matrixOfDifferencesBetweenConditionsIntraGroupOld;
                            
                           matrixForComputingMeanResponseOldConditionDifferences=nan(numberOfPoints, numberOfOldSubject);
                           matrixForComputingMeanResponseOld=nan(numberOfPoints,numberOfOldSubject);

                           numberOfMediansComputedOld=0;
                            for cptOld=1:numberOfOldSubject
                                if ~app.whoIsNanIntraGroupOld(cptTest,cptOld)
                                        matrixForComputingMeanResponseOld(:,cptOld)=medianPupilResponsesOldBufferConditionsDifferences{cptOld}(:,indexSignalType,cptTest);
                                        numberOfMediansComputedOld=numberOfMediansComputedOld+1;
                                end
                            end
                            
                            computedMeanForConditionsDifferences.clean=nanmean(matrixForComputingMeanResponseOld,2);
                            standardDeviationAtEachTimepoints=nanstd(matrixForComputingMeanResponseOld, 0, 2);
            
                            computedMeanForConditionsDifferences.lowerConfidenceInterval=computedMeanForConditionsDifferences.clean - nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedOld);
                            computedMeanForConditionsDifferences.upperConfidenceInterval=computedMeanForConditionsDifferences.clean + nSigma * standardDeviationAtEachTimepoints/sqrt(numberOfMediansComputedOld);
                            computedMeanForConditionsDifferences.numberOfUsedMedians=numberOfMediansComputedOld;
            end
        
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        
        function results = conditionOneVsConditionTwoDisplayDifferences(app, cptTest, indexSignalType, numberOfPoints)

            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 

            %We get the plotting properties
            [numberOfPoints numberOfSignalTypes numberOfConditions]=size(app.medianPupilResponses{1}.clean);
            numberOfConditions=numberOfConditions-1; %We remove the "all" condition
            timepoints=(1:numberOfPoints)/app.samplingFrequency;


            %We compute the number of chosen trials
           
            indexConditionOne=app.testedConditionsVectorForDisplayFunction(cptTest,1);
            indexConditionTwo=app.testedConditionsVectorForDisplayFunction(cptTest,2);
            
            trialNumberBufferYoung=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),indexSignalType,:));
             bufferNumberOfTrialsYoung=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosYoung),:,:),1) );
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionOne)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsYoung(indexSignalType,indexConditionTwo)-sum(trialNumberBufferYoung(find(app.whoIsNanIntraGroupYoung(cptTest,:)), indexConditionTwo));
                        
            trialNumberBufferOld=squeeze(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),indexSignalType,:));
             bufferNumberOfTrialsOld=squeeze( sum(app.howManyTrialsHaveBeenUsedToComputeMedian(find(app.whosOld),:,:),1) );
             bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)=bufferNumberOfTrialsOld(indexSignalType,indexConditionOne)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionOne));
             bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)=bufferNumberOfTrialsOld(indexSignalType,indexConditionTwo)-sum(trialNumberBufferOld(find(app.whoIsNanIntraGroupOld(cptTest,:)), indexConditionTwo));
                    
            computedMeanForConditionsDifferencesYoung = computeMeanForMatrixOfDifferencesBetweenConditionsIntraGroup(app, 'j', indexSignalType, cptTest);
            computedMeanForConditionsDifferencesOld = computeMeanForMatrixOfDifferencesBetweenConditionsIntraGroup(app, 'a', indexSignalType, cptTest);

                    plot(timepoints,computedMeanForConditionsDifferencesYoung.clean, 'Color', app.colors(1,:), 'LineWidth', 2)
                    hold on 
                    plot(timepoints,computedMeanForConditionsDifferencesOld.clean, 'Color', app.colors(2,:), 'LineWidth', 2)


                    %Matrix used for plotting purposes
                    minScaleMatrix=[computedMeanForConditionsDifferencesYoung.lowerConfidenceInterval computedMeanForConditionsDifferencesOld.lowerConfidenceInterval ];
                    maxScaleMatrix=[computedMeanForConditionsDifferencesYoung.upperConfidenceInterval computedMeanForConditionsDifferencesOld.upperConfidenceInterval ];
%                          
                    %We plot the shaded area where significances overlap
                    significanceUncorrectedYoung=app.significanceMatrixOfDifferencesIntraGroupYoung(:,indexSignalType,cptTest);
                    significanceUncorrectedOld=app.significanceMatrixOfDifferencesIntraGroupOld(:,indexSignalType,cptTest);

                    significanceMatrixOfDifferencesIntraGroupYoung_CBPT = app.significanceMatrixOfDifferencesIntraGroupYoung_CBPT(:,indexSignalType,cptTest);
                    significanceMatrixOfDifferencesIntraGroupOld_CBPT = app.significanceMatrixOfDifferencesIntraGroupOld_CBPT(:,indexSignalType,cptTest);

                    
                    plot((1:numberOfPoints)/app.samplingFrequency, zeros(1,numberOfPoints), 'Color','k')

                    TEPRtype=string(app.TEPRButtonGroup.SelectedObject.Text);
                    switch(TEPRtype)
                        case "Absolute"
                            %We plot the significance (uncorrected) for YOUNG
                            %people
                            plot(timepoints(find(significanceUncorrectedYoung)), -6*significanceUncorrectedYoung(find(significanceUncorrectedYoung)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(1,:),'MarkerSize', 20);
        %                   % and  CBPT   
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)), -7*significanceMatrixOfDifferencesIntraGroupYoung_CBPT(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(1,:),'MarkerSize', 20);
                            %We plot the significance (uncorrected) for OLDER
                            %people
                            plot(timepoints(find(significanceUncorrectedOld)), -8*significanceUncorrectedOld(find(significanceUncorrectedOld)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(2,:),'MarkerSize', 20);
        %                   % and  CBPT   
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)), -9*significanceMatrixOfDifferencesIntraGroupOld_CBPT(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(2,:),'MarkerSize', 20);
                            %AND ALSO WE PLOT THE TWO-SAMPLE RESULT BETWEEN
                            %DIFFERENCES MATRICES
                            isItSignificantBeetweenSubtractedConditions(app, cptTest);
                            significanceUncorrectedInterGroup=app.significanceMatricesOfDifferencesInterGroup(:,cptTest);
                            significanceMatrixOfDifferencesInterGroup_CBPT = app.significanceMatricesOfDifferencesInterGroup_CBPT(:,cptTest);
                            plot(timepoints(find(significanceUncorrectedInterGroup)), -10*significanceUncorrectedInterGroup(find(significanceUncorrectedInterGroup)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 0],'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesInterGroup_CBPT)), -11*significanceMatrixOfDifferencesInterGroup_CBPT(find(significanceMatrixOfDifferencesInterGroup_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[1 1 1]/6,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesYoung.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesYoung.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesOld.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesOld.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-11 15]);    
                            
                        case "Z-score"
                            plot(timepoints(find(significanceUncorrectedYoung)), -1*significanceUncorrectedYoung(find(significanceUncorrectedYoung)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(1,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)), -1.2*significanceMatrixOfDifferencesIntraGroupYoung_CBPT(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(1,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceUncorrectedOld)), -1.4*significanceUncorrectedOld(find(significanceUncorrectedOld)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(2,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)), -1.6*significanceMatrixOfDifferencesIntraGroupOld_CBPT(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(2,:),'MarkerSize', 20);
                            isItSignificantBeetweenSubtractedConditions(app, cptTest);
                            significanceUncorrectedInterGroup=app.significanceMatricesOfDifferencesInterGroup(:,cptTest);
                            significanceMatrixOfDifferencesInterGroup_CBPT = app.significanceMatricesOfDifferencesInterGroup_CBPT(:,cptTest);
                            plot(timepoints(find(significanceUncorrectedInterGroup)), -1.8*significanceUncorrectedInterGroup(find(significanceUncorrectedInterGroup)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 0],'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesInterGroup_CBPT)), -2*significanceMatrixOfDifferencesInterGroup_CBPT(find(significanceMatrixOfDifferencesInterGroup_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[1 1 1]/6,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesYoung.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesYoung.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesOld.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesOld.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-2.5 2.5]);  
                            
                        case "Percentage"
                            plot(timepoints(find(significanceUncorrectedYoung)), -3*significanceUncorrectedYoung(find(significanceUncorrectedYoung)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(1,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)), -3.2*significanceMatrixOfDifferencesIntraGroupYoung_CBPT(find(significanceMatrixOfDifferencesIntraGroupYoung_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(1,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceUncorrectedOld)), -3.4*significanceUncorrectedOld(find(significanceUncorrectedOld)),'LineStyle','none','Marker','.','MarkerEdgeColor',app.colors(2,:),'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)), -3.6*significanceMatrixOfDifferencesIntraGroupOld_CBPT(find(significanceMatrixOfDifferencesIntraGroupOld_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',0.9*app.colors(2,:),'MarkerSize', 20);
                            isItSignificantBeetweenSubtractedConditions(app, cptTest);
                            significanceUncorrectedInterGroup=app.significanceMatricesOfDifferencesInterGroup(:,cptTest);
                            significanceMatrixOfDifferencesInterGroup_CBPT = app.significanceMatricesOfDifferencesInterGroup_CBPT(:,cptTest);
                            plot(timepoints(find(significanceUncorrectedInterGroup)), -3.8*significanceUncorrectedInterGroup(find(significanceUncorrectedInterGroup)),'LineStyle','none','Marker','.','MarkerEdgeColor',[0 0 0],'MarkerSize', 20);
                            plot(timepoints(find(significanceMatrixOfDifferencesInterGroup_CBPT)), -4*significanceMatrixOfDifferencesInterGroup_CBPT(find(significanceMatrixOfDifferencesInterGroup_CBPT)),'LineStyle','none','Marker','.','MarkerEdgeColor',[1 1 1]/6,'MarkerSize', 20);
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesYoung.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesYoung.upperConfidenceInterval')], app.colors(3,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            fill([timepoints, fliplr(timepoints)], [computedMeanForConditionsDifferencesOld.lowerConfidenceInterval', fliplr(computedMeanForConditionsDifferencesOld.upperConfidenceInterval')], app.colors(4,:), 'FaceAlpha', 0.3, 'LineStyle', 'none');
                            axis tight
                            ylim([-5 5]);  
                    end


                    hold off

                    xlabelText = 'time(s)';
                    xlabelHandle = xlabel(xlabelText);
                    currentPosition = get(xlabelHandle, 'Position');
                    n = -1.2; 
                    currentPosition(2) = currentPosition(2) + n; 
                    set(xlabelHandle, 'Position', currentPosition);


                    ylabel('arbitrary units')
%                    
                    
                    title(app.testedConditions(cptTest))
                    sgtitle(strcat("Differences between conditions - YOUNG v. OLDER - " , chosenTab))
                    
                    ax = gca;
                    ax.FontSize = 12; 
                    ax.FontWeight = 'bold';


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
function results = isItSignificantBeetweenSubtractedConditions(app, cptTest)


    %%UNCORRECTED SIGNIFICANCE
    
                    matrixYoung=app.matrixContainingMediansSubstractedBetweenCdtsYoung{cptTest};
                    matrixOld=app.matrixContainingMediansSubstractedBetweenCdtsOld{cptTest};
                    
                    [numberOfPoints numberOfYoungSubject]=size(matrixYoung);
                    [numberOfPoints numberOfOldSubject]=size(matrixOld);

                    
                    tail=app.testedConditionsTailHypothesis(cptTest);
                    
                    significanceBuffer=zeros(1,numberOfPoints);
                    bayesFactorBuffer=nan(1,numberOfPoints);
                    pValueBuffer=nan(1,numberOfPoints);

                    if ~(numberOfOldSubject == 0 || numberOfYoungSubject == 0)
                        for cptPoint=1:numberOfPoints
                            
                            for cptYoung=1:numberOfYoungSubject
                                youngDistributionAtChosenPoint(cptYoung)=matrixYoung(cptPoint,cptYoung);
                            end
                            
                            for cptOld=1:numberOfOldSubject
                                oldDistributionAtChosenPoint(cptOld)=matrixOld(cptPoint,cptOld);
                            end
                            
                            %We remove nan columns
                            youngDistributionAtChosenPoint=youngDistributionAtChosenPoint(~isnan(youngDistributionAtChosenPoint));
                            oldDistributionAtChosenPoint=oldDistributionAtChosenPoint(~isnan(oldDistributionAtChosenPoint));
    
                            significanceBuffer(cptPoint)=0;
                            bayesFactorBuffer(cptPoint)=NaN;
                            pValueBuffer(cptPoint)=NaN;
                            
                            if ~ (isempty(youngDistributionAtChosenPoint) || isempty(oldDistributionAtChosenPoint))
                                [p h]=ranksum(youngDistributionAtChosenPoint,oldDistributionAtChosenPoint, 'tail', tail);
                                [BF10 pBF]=bf.ttest2(youngDistributionAtChosenPoint,oldDistributionAtChosenPoint, 'tail', convertStringsToChars(tail) );
%                                 [h p]=ttest2(youngDistributionAtChosenPoint,oldDistributionAtChosenPoint, 'tail', tail);
    %                             
                                bayesFactorBuffer(cptPoint)=BF10;
                                pValueBuffer(cptPoint)=p;
                                significanceBuffer(cptPoint) = p<=app.pThresholdCluster;
                                
                            end
                            
                        end
                        
                    app.significanceMatricesOfDifferencesInterGroup(:,cptTest)=significanceBuffer;
                    app.bayesFactorMatricesOfDifferencesInterGroup(:,cptTest)=bayesFactorBuffer;  
                    app.pValueMatricesOfDifferencesInterGroup(:,cptTest)=pValueBuffer;
                        
                   
                    [app.significanceMatricesOfDifferencesInterGroup(:,cptTest) app.significanceMatricesOfDifferencesInterGroup_CBPT(:,cptTest) app.clusterCharacteristicsInterGroupDifferences{cptTest}] = CBPT_ztValueThreshold(matrixYoung, matrixOld,...
                                                                                                        app.pThresholdCluster, app.numberOfPermutations,...
                                                                                                        "independent", app.allTailHypothesis, "parametric");


                    else
                    
                        app.significanceMatricesOfDifferencesInterGroup(:,cptTest)=significanceBuffer;
                        app.bayesFactorMatricesOfDifferencesInterGroup(:,cptTest)=bayesFactorBuffer;  
                        app.pValueMatricesOfDifferencesInterGroup(:,cptTest)=pValueBuffer;
                            
                        %%CORRECTED SIGNIFICANCE
                        app.significanceMatricesOfDifferencesInterGroup_CBPT(:,cptTest)=significanceBuffer;
                        
                    end


end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
        
        function results = exportDataToMainSpreadSheet(app)

            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%EXPORT CSV FILE FOR TO MAIN SPREADSHEET %%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %We first compute the mean times of response. 
            computeMeanResponseTimes(app);
            %We then compute the pupil metrics 
            pupilMetricsComputation(app);

            %We extract the arrays in order to create a table containing
            %everything we need to operate further statistical analysis on
            %softwares like Jamovi, JASP, or R
            for cptParticipant=1:app.nParticipant
                
                %dependant variable (variable à expliquer)
                
                %taux de reconnaissance ALL (regresseur)
                recognitionRatesConditionAllForEachParticipant(cptParticipant)=app.recognitionRates{cptParticipant}(1);
                %taux de reconnaissance ANIMAL (regresseur)
                recognitionRatesConditionAnimalForEachParticipant(cptParticipant)=app.recognitionRates{cptParticipant}(2);
                %taux de reconnaissance OBJECT (regresseur)
                recognitionRatesConditionObjectForEachParticipant(cptParticipant)=app.recognitionRates{cptParticipant}(3);
                %taux de reconnaissance CONGRUENT (regresseur)
                recognitionRatesConditionCongruentForEachParticipant(cptParticipant)=app.recognitionRates{cptParticipant}(6);
                %taux de reconnaissance INCONGRUENT (regresseur)
                recognitionRatesConditionIncongruentForEachParticipant(cptParticipant)=app.recognitionRates{cptParticipant}(7);

                
                %independant variables (variables explicatives)
                %code sujet (facteur)
                subjectNamesForEachParticipant(cptParticipant)=string(app.ListBox.Items{cptParticipant});
                %numero sujet (facteur)
                subjectNumber(cptParticipant)=cptParticipant;
                %groupe d'age (facteur)
                ageGroup(cptParticipant)=string(app.ListBox.Items{cptParticipant}(3));


                %amplitude maximale de dilatation ALL (regresseur)
                dilationAmplitude(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).dilationAmplitude;
                %amplitude maximale de dilatation CONGRUENT (regresseur)
                dilationAmplitudeCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).dilationAmplitude;
                %amplitude maximale de dilatation inCONGRUENT (regresseur)
                dilationAmplitudeIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).dilationAmplitude;
                %amplitude maximale de dilatation ANIMAL (regresseur)
                dilationAmplitudeAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).dilationAmplitude;
                %amplitude maximale de dilatation OBJECT (regresseur)
                dilationAmplitudeObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).dilationAmplitude;
                %amplitude maximale de dilatation WELL CATEG (regresseur)
                dilationAmplitudeWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).dilationAmplitude;
                %amplitude maximale de dilatation WRONGLY CATEG (regresseur)
                dilationAmplitudeBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).dilationAmplitude;
                %amplitude maximale de dilatation RECON (regresseur)
                dilationAmplitudeRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).dilationAmplitude;
                %amplitude maximale de dilatation FORGOT (regresseur)
                dilationAmplitudeForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).dilationAmplitude;
                %amplitude maximale de dilatation RECON R (regresseur)
                dilationAmplitudeRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).dilationAmplitude;
                %amplitude maximale de dilatation RECON K (regresseur)
                dilationAmplitudeRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).dilationAmplitude;
                %amplitude maximale de dilatation RECON G (regresseur)
                dilationAmplitudeRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).dilationAmplitude;
                %amplitude maximale de dilatation FORGOT (regresseur)
                dilationAmplitudeForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).dilationAmplitude;
                %amplitude maximale de dilatation FORGOT (regresseur)
                dilationAmplitudeForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).dilationAmplitude;
                
                %constriction maximale (regresseur)
                pupilLightReflexAmplitude(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).pupilLightReflexAmplitude;
                %constriction maximale CONGRUENT (regresseur)
                pupilLightReflexAmplitudeCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).pupilLightReflexAmplitude;
                %constriction maximale inCONGRUENT (regresseur)
                pupilLightReflexAmplitudeIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).pupilLightReflexAmplitude;
                %constriction maximale ANIMAL (regresseur)
                pupilLightReflexAmplitudeAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).pupilLightReflexAmplitude;
                %constriction maximale OBJECT (regresseur)
                pupilLightReflexAmplitudeObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).pupilLightReflexAmplitude;
                %constriction maximale WELL CATEG (regresseur)
                pupilLightReflexAmplitudeWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).pupilLightReflexAmplitude;
                %constriction maximale WRONGLY CATEG(regresseur)
                pupilLightReflexAmplitudeBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).pupilLightReflexAmplitude;                
                %constriction maximale RECON (regresseur)
                pupilLightReflexAmplitudeRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).pupilLightReflexAmplitude;
                %constriction maximale FORGOT (regresseur)
                pupilLightReflexAmplitudeForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).pupilLightReflexAmplitude;
                %constriction maximale RECON R (regresseur)
                pupilLightReflexAmplitudeRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).pupilLightReflexAmplitude;
                %constriction maximale RECON K (regresseur)
                pupilLightReflexAmplitudeRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).pupilLightReflexAmplitude;
                %constriction maximale RECON G (regresseur)
                pupilLightReflexAmplitudeRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).pupilLightReflexAmplitude;
                %constriction maximale FORGOT K (regresseur)
                pupilLightReflexAmplitudeForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).pupilLightReflexAmplitude;
                %constriction maximale FORGOT G (regresseur)
                pupilLightReflexAmplitudeForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).pupilLightReflexAmplitude;
              
                %latence dilatation (regresseur)
                latencyToDilationPeak(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).latencyToDilationPeak;
                latencyToDilationPeakCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).latencyToDilationPeak;
                latencyToDilationPeakIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).latencyToDilationPeak;
                latencyToDilationPeakAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).latencyToDilationPeak;
                latencyToDilationPeakObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).latencyToDilationPeak;
                latencyToDilationPeakWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).latencyToDilationPeak;
                latencyToDilationPeakBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).latencyToDilationPeak;
                latencyToDilationPeakRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).latencyToDilationPeak;
                latencyToDilationPeakForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).latencyToDilationPeak;
                latencyToDilationPeakRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).latencyToDilationPeak;
                latencyToDilationPeakRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).latencyToDilationPeak;
                latencyToDilationPeakRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).latencyToDilationPeak;
                latencyToDilationPeakForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).latencyToDilationPeak;
                latencyToDilationPeakForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).latencyToDilationPeak;
                
                
               %latence constriction (regresseur)
                latencyToConstrictionPeak(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).latencyToConstrictionPeak;
                latencyToConstrictionPeakCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).latencyToConstrictionPeak;
                latencyToConstrictionPeakIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).latencyToConstrictionPeak;
                latencyToConstrictionPeakAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).latencyToConstrictionPeak;
                latencyToConstrictionPeakObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).latencyToConstrictionPeak;
                latencyToConstrictionPeakWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).latencyToConstrictionPeak;
                latencyToConstrictionPeakBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).latencyToConstrictionPeak;
                latencyToConstrictionPeakRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).latencyToConstrictionPeak;
                latencyToConstrictionPeakForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).latencyToConstrictionPeak;
                latencyToConstrictionPeakRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).latencyToConstrictionPeak;
                latencyToConstrictionPeakRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).latencyToConstrictionPeak;
                latencyToConstrictionPeakRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).latencyToConstrictionPeak;
                latencyToConstrictionPeakForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).latencyToConstrictionPeak;
                latencyToConstrictionPeakForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).latencyToConstrictionPeak;
                
                
                %vitesse dilatation (regresseur)
                dilationVelocity(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                dilationVelocityForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).dilationVelocity;%Pas indépendant avec amplitude et latence dilatation
                
                
                %AUC signal positif = dilatation moyenne (regresseur)
                meanDilation(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).meanDilation;
                meanDilationCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).meanDilation;
                meanDilationIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).meanDilation;
                meanDilationAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).meanDilation;
                meanDilationObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).meanDilation;
                meanDilationWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).meanDilation;
                meanDilationBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).meanDilation;
                meanDilationRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).meanDilation;
                meanDilationForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).meanDilation;
                meanDilationRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).meanDilation;
                meanDilationRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).meanDilation;
                meanDilationRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).meanDilation;
                meanDilationForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).meanDilation;
                meanDilationForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).meanDilation;
                
                
                %AUC signal négatif = constriction moyenne (regresseur)
                meanPupilLightReflex(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).meanPupilLightReflex;
                meanPupilLightReflexCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).meanPupilLightReflex;
                meanPupilLightReflexIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).meanPupilLightReflex;
                meanPupilLightReflexAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).meanPupilLightReflex;
                meanPupilLightReflexObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).meanPupilLightReflex;
                meanPupilLightReflexWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).meanPupilLightReflex;
                meanPupilLightReflexBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).meanPupilLightReflex;
                meanPupilLightReflexRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).meanPupilLightReflex;
                meanPupilLightReflexForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).meanPupilLightReflex;
                meanPupilLightReflexRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).meanPupilLightReflex;
                meanPupilLightReflexRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).meanPupilLightReflex;
                meanPupilLightReflexRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).meanPupilLightReflex;
                meanPupilLightReflexForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).meanPupilLightReflex;
                meanPupilLightReflexForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).meanPupilLightReflex;
                
                %durée de la réponse (i.e. quand ça revient basal)
                durationOfResponse(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).durationOfResponse;
                durationOfResponseCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).durationOfResponse;
                durationOfResponseIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).durationOfResponse;
                durationOfResponseAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).durationOfResponse;
                durationOfResponseObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).durationOfResponse;
                durationOfResponseWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).durationOfResponse;
                durationOfResponseBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).durationOfResponse;
                durationOfResponseRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).durationOfResponse;
                durationOfResponseForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).durationOfResponse;
                durationOfResponseRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).durationOfResponse;
                durationOfResponseRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).durationOfResponse;
                durationOfResponseRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).durationOfResponse;
                durationOfResponseForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).durationOfResponse;
                durationOfResponseForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).durationOfResponse;

                %durée entre pic et baseline (regresseur)
                peakToBaselineLatency(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).peakToBaselineLatency;
                peakToBaselineLatencyCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).peakToBaselineLatency;
                peakToBaselineLatencyIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).peakToBaselineLatency;
                peakToBaselineLatencyAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).peakToBaselineLatency;
                peakToBaselineLatencyObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).peakToBaselineLatency;
                peakToBaselineLatencyWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).peakToBaselineLatency;
                peakToBaselineLatencyBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).peakToBaselineLatency;
                peakToBaselineLatencyRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).peakToBaselineLatency;
                peakToBaselineLatencyForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).peakToBaselineLatency;
                peakToBaselineLatencyRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).peakToBaselineLatency;
                peakToBaselineLatencyRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).peakToBaselineLatency;
                peakToBaselineLatencyRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).peakToBaselineLatency;
                peakToBaselineLatencyForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).peakToBaselineLatency;
                peakToBaselineLatencyForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).peakToBaselineLatency;

                %baseline médiane
                meanBaselineValue(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,1).meanBaselineValue;
                meanBaselineValueCongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,2).meanBaselineValue;
                meanBaselineValueIncongruent(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,3).meanBaselineValue;
                meanBaselineValueAnimal(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,4).meanBaselineValue;
                meanBaselineValueObject(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,5).meanBaselineValue;
                meanBaselineValueWellCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,6).meanBaselineValue;
                meanBaselineValueBadCateg(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,7).meanBaselineValue;
                meanBaselineValueRecognized(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,8).meanBaselineValue;
                meanBaselineValueForgotten(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,9).meanBaselineValue;
                meanBaselineValueRecognizedR(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,10).meanBaselineValue;
                meanBaselineValueRecognizedK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,11).meanBaselineValue;
                meanBaselineValueRecognizedG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,12).meanBaselineValue;
                meanBaselineValueForgottenK(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,13).meanBaselineValue;
                meanBaselineValueForgottenG(cptParticipant)=app.pupilMetrics(cptParticipant,indexSignalType,14).meanBaselineValue;
                
                %temps de réponse moyen POUR CATEGORIZATION REUSSIE
                meanRT(cptParticipant)=app.meanResponseTimes(cptParticipant,1);
                meanRTCongruent(cptParticipant)=app.meanResponseTimes(cptParticipant,2);
                meanRTIncongruent(cptParticipant)=app.meanResponseTimes(cptParticipant,3);
                meanRTAnimal(cptParticipant)=app.meanResponseTimes(cptParticipant,4);
                meanRTObject(cptParticipant)=app.meanResponseTimes(cptParticipant,5);
                meanRTWellCateg(cptParticipant)=app.meanResponseTimes(cptParticipant,6);
                meanRTBadCateg(cptParticipant)=app.meanResponseTimes(cptParticipant,7);
                meanRTRecognized(cptParticipant)=app.meanResponseTimes(cptParticipant,8);
                meanRTForgotten(cptParticipant)=app.meanResponseTimes(cptParticipant,9);
                meanRTRecognizedR(cptParticipant)=app.meanResponseTimes(cptParticipant,10);
                meanRTRecognizedK(cptParticipant)=app.meanResponseTimes(cptParticipant,11);
                meanRTRecognizedG(cptParticipant)=app.meanResponseTimes(cptParticipant,12);
                meanRTForgottenK(cptParticipant)=app.meanResponseTimes(cptParticipant,13);
                meanRTForgottenG(cptParticipant)=app.meanResponseTimes(cptParticipant,14);

                
                %taux de catégorisation global (regresseur) (ATTENTION AUX
                %INDICES)                 
                % app.categorizationRates{indexSubject}=[allCategorizationRate
%                                                        congruentCategorizationRate
%                                                        incongruentCategorizationRate
%                                                        animalCategorizationRate
%                                                        objectCategorizationRate
%                                                        recognizedCategorizationRate
%                                                        recognizedRCategorizationRate
%                                                        recognizedKCategorizationRate
%                                                        recognizedGCategorizationRate
%                                                        forgottenCategorizationRate
%                                                        forgottenKCategorizationRate
%                                                        forgottenGCategorizationRate];

                categorizationRatesConditionAllForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(1);
                categorizationRatesConditionCongruentForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(2);
                categorizationRatesConditionIncongruentForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(3);
                categorizationRatesConditionAnimalForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(4);
                categorizationRatesConditionObjectForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(5);
                categorizationRatesConditionRecognizedForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(6);
                categorizationRatesConditionRecognizedRForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(7);
                categorizationRatesConditionRecognizedKForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(8);
                categorizationRatesConditionRecognizedGForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(9);
                categorizationRatesConditionForgottenForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(10);
                categorizationRatesConditionForgottenKForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(11);
                categorizationRatesConditionForgottenGForEachParticipant(cptParticipant)=app.categorizationRates{cptParticipant}(12);


                %Nombre de R, K, G correct (i.e. quand reconnu)
                numberOfCorrectR(cptParticipant)=app.numberOfRKG{cptParticipant}(1);
                numberOfCorrectK(cptParticipant)=app.numberOfRKG{cptParticipant}(2);
                numberOfCorrectG(cptParticipant)=app.numberOfRKG{cptParticipant}(3);
                numberOfIncorrectK(cptParticipant)=app.numberOfRKG{cptParticipant}(4);
                numberOfIncorrectG(cptParticipant)=app.numberOfRKG{cptParticipant}(5);
            end


            colNames1={'Subject code', 'Age group', 'Subject number'};
            tableToBeWritten(:,1)=subjectNamesForEachParticipant;
            tableToBeWritten(:,2)=ageGroup;
            tableToBeWritten(:,3)=subjectNumber;
            
            colNames2={'Dilation amplitude', ...
           'Dilation amplitude CONG', 'Dilation amplitude INCONG', 'Dilation amplitude ANI', 'Dilation amplitude OBJ',...
           'Dilation amplitude WELL CATEG', 'Dilation amplitude BAD CATEG',...
           'Dilation amplitude RECOG', 'Dilation amplitude FORGOT'...
           'Dilation amplitude RECOG R', 'Dilation amplitude RECOG K', 'Dilation amplitude RECOG G',...
           'Dilation amplitude FORGOT K', 'Dilation amplitude FORGOT G' };
            tableToBeWritten(:,4)=dilationAmplitude;
            tableToBeWritten(:,5)=dilationAmplitudeCongruent;
            tableToBeWritten(:,6)=dilationAmplitudeIncongruent;
            tableToBeWritten(:,7)=dilationAmplitudeAnimal;
            tableToBeWritten(:,8)=dilationAmplitudeObject;
            tableToBeWritten(:,9)=dilationAmplitudeWellCateg;
            tableToBeWritten(:,10)=dilationAmplitudeBadCateg;
            tableToBeWritten(:,11)=dilationAmplitudeRecognized;
            tableToBeWritten(:,12)=dilationAmplitudeForgotten;
            tableToBeWritten(:,13)=dilationAmplitudeRecognizedR;
            tableToBeWritten(:,14)=dilationAmplitudeRecognizedK;
            tableToBeWritten(:,15)=dilationAmplitudeRecognizedG;
            tableToBeWritten(:,16)=dilationAmplitudeForgottenK;
            tableToBeWritten(:,17)=dilationAmplitudeForgottenG;
           
            colNames3={'PLR amplitude', ...
            'PLR amplitude CONG', 'PLR amplitude INCONG', 'PLR amplitude ANI', 'PLR amplitude OBJ',...
            'PLR amplitude WELL CATEG', 'PLR amplitude BAD CATEG',...
            'PLR amplitude RECOG', 'PLR amplitude FORGOT'...
            'PLR amplitude RECOG R', 'PLR amplitude RECOG K', 'PLR amplitude RECOG G',...
            'PLR amplitude FORGOT K', 'PLR amplitude FORGOT G' };  
            tableToBeWritten(:,18)=pupilLightReflexAmplitude;
            tableToBeWritten(:,19)=pupilLightReflexAmplitudeCongruent;
            tableToBeWritten(:,20)=pupilLightReflexAmplitudeIncongruent;
            tableToBeWritten(:,21)=pupilLightReflexAmplitudeAnimal;
            tableToBeWritten(:,22)=pupilLightReflexAmplitudeObject;
            tableToBeWritten(:,23)=pupilLightReflexAmplitudeWellCateg;
            tableToBeWritten(:,24)=pupilLightReflexAmplitudeBadCateg;
            tableToBeWritten(:,25)=pupilLightReflexAmplitudeRecognized;
            tableToBeWritten(:,26)=pupilLightReflexAmplitudeForgotten;
            tableToBeWritten(:,27)=pupilLightReflexAmplitudeRecognizedR;
            tableToBeWritten(:,28)=pupilLightReflexAmplitudeRecognizedK;
            tableToBeWritten(:,29)=pupilLightReflexAmplitudeRecognizedG;
            tableToBeWritten(:,30)=pupilLightReflexAmplitudeForgottenK;
            tableToBeWritten(:,31)=pupilLightReflexAmplitudeForgottenG;
            
            colNames4={'Latency to peak', ...
            'Latency to peak CONG', 'Latency to peak INCONG', 'Latency to peak ANI', 'Latency to peak OBJ',...
            'Latency to peak WELL CATEG', 'Latency to peak BAD CATEG',...
            'Latency to peak RECOG', 'Latency to peak FORGOT'...
            'Latency to peak RECOG R', 'Latency to peak RECOG K', 'Latency to peak RECOG G',...
            'Latency to peak FORGOT K', 'Latency to peak FORGOT G' };  
            tableToBeWritten(:,32)=latencyToDilationPeak;
            tableToBeWritten(:,33)=latencyToDilationPeakCongruent;
            tableToBeWritten(:,34)=latencyToDilationPeakIncongruent;
            tableToBeWritten(:,35)=latencyToDilationPeakAnimal;
            tableToBeWritten(:,36)=latencyToDilationPeakObject;
            tableToBeWritten(:,37)=latencyToDilationPeakWellCateg;
            tableToBeWritten(:,38)=latencyToDilationPeakBadCateg;
            tableToBeWritten(:,39)=latencyToDilationPeakRecognized;
            tableToBeWritten(:,40)=latencyToDilationPeakForgotten;
            tableToBeWritten(:,41)=latencyToDilationPeakRecognizedR;
            tableToBeWritten(:,42)=latencyToDilationPeakRecognizedK;
            tableToBeWritten(:,43)=latencyToDilationPeakRecognizedG;
            tableToBeWritten(:,44)=latencyToDilationPeakForgottenK;
            tableToBeWritten(:,45)=latencyToDilationPeakForgottenG;

            colNames5={'Latency to constriction', ...
            'Latency to constriction CONG', 'Latency to constriction INCONG', 'Latency to constriction ANI', 'Latency to constriction OBJ',...
            'Latency to constriction WELL CATEG', 'Latency to constriction BAD CATEG',...
            'Latency to constriction RECOG', 'Latency to constriction FORGOT'...
            'Latency to constriction RECOG R', 'Latency to constriction RECOG K', 'Latency to constriction RECOG G',...
            'Latency to constriction FORGOT K', 'Latency to constriction FORGOT G' };  
            tableToBeWritten(:,46)=latencyToConstrictionPeak;
            tableToBeWritten(:,47)=latencyToConstrictionPeakCongruent;
            tableToBeWritten(:,48)=latencyToConstrictionPeakIncongruent;
            tableToBeWritten(:,49)=latencyToConstrictionPeakAnimal;
            tableToBeWritten(:,50)=latencyToConstrictionPeakObject;
            tableToBeWritten(:,51)=latencyToConstrictionPeakWellCateg;
            tableToBeWritten(:,52)=latencyToConstrictionPeakBadCateg;
            tableToBeWritten(:,53)=latencyToConstrictionPeakRecognized;
            tableToBeWritten(:,54)=latencyToConstrictionPeakForgotten;
            tableToBeWritten(:,55)=latencyToConstrictionPeakRecognizedR;
            tableToBeWritten(:,56)=latencyToConstrictionPeakRecognizedK;
            tableToBeWritten(:,57)=latencyToConstrictionPeakRecognizedG;
            tableToBeWritten(:,58)=latencyToConstrictionPeakForgottenK;
            tableToBeWritten(:,59)=latencyToConstrictionPeakForgottenG;
            
            colNames6={'Dilation velocity', ...
            'Dilation velocity CONG', 'Dilation velocity INCONG', 'Dilation velocity ANI', 'Dilation velocity OBJ',...
            'Dilation velocity WELL CATEG', 'Dilation velocity BAD CATEG',...
            'Dilation velocity RECOG', 'Dilation velocity FORGOT'...
            'Dilation velocity RECOG R', 'Dilation velocity RECOG K', 'Dilation velocity RECOG G',...
            'Dilation velocity FORGOT K', 'Dilation velocity FORGOT G' };  
            tableToBeWritten(:,60)=dilationVelocity;
            tableToBeWritten(:,61)=dilationVelocityCongruent;
            tableToBeWritten(:,62)=dilationVelocityIncongruent;
            tableToBeWritten(:,63)=dilationVelocityAnimal;
            tableToBeWritten(:,64)=dilationVelocityObject;
            tableToBeWritten(:,65)=dilationVelocityWellCateg;
            tableToBeWritten(:,66)=dilationVelocityBadCateg;
            tableToBeWritten(:,67)=dilationVelocityRecognized;
            tableToBeWritten(:,68)=dilationVelocityForgotten;
            tableToBeWritten(:,69)=dilationVelocityRecognizedR;
            tableToBeWritten(:,70)=dilationVelocityRecognizedK;
            tableToBeWritten(:,71)=dilationVelocityRecognizedG;
            tableToBeWritten(:,72)=dilationVelocityForgottenK;
            tableToBeWritten(:,73)=dilationVelocityForgottenG;
            
            colNames7={'Mean dilation', ...
            'Mean dilation CONG', 'Mean dilation INCONG', 'Mean dilation ANI', 'Mean dilation OBJ',...
            'Mean dilation WELL CATEG', 'Mean dilation BAD CATEG',...
            'Mean dilation RECOG', 'Mean dilation FORGOT'...
            'Mean dilation RECOG R', 'Mean dilation RECOG K', 'Mean dilation RECOG G',...
            'Mean dilation FORGOT K', 'Mean dilation FORGOT G' };  
            tableToBeWritten(:,74)=meanDilation;
            tableToBeWritten(:,75)=meanDilationCongruent;
            tableToBeWritten(:,76)=meanDilationIncongruent;
            tableToBeWritten(:,77)=meanDilationAnimal;
            tableToBeWritten(:,78)=meanDilationObject;
            tableToBeWritten(:,79)=meanDilationWellCateg;
            tableToBeWritten(:,80)=meanDilationBadCateg;
            tableToBeWritten(:,81)=meanDilationRecognized;
            tableToBeWritten(:,82)=meanDilationForgotten;
            tableToBeWritten(:,83)=meanDilationRecognizedR;
            tableToBeWritten(:,84)=meanDilationRecognizedK;
            tableToBeWritten(:,85)=meanDilationRecognizedG;
            tableToBeWritten(:,86)=meanDilationForgottenK;
            tableToBeWritten(:,87)=meanDilationForgottenG;

            colNames8={'Mean constriction', ...
            'Mean constriction CONG', 'Mean constriction INCONG', 'Mean constriction ANI', 'Mean constriction OBJ',...
            'Mean constriction WELL CATEG', 'Mean constriction BAD CATEG',...
            'Mean constriction RECOG', 'Mean constriction FORGOT'...
            'Mean constriction RECOG R', 'Mean constriction RECOG K', 'Mean constriction RECOG G',...
            'Mean constriction FORGOT K', 'Mean constriction FORGOT G' };  
            tableToBeWritten(:,88)=meanPupilLightReflex;
            tableToBeWritten(:,89)=meanPupilLightReflexCongruent;
            tableToBeWritten(:,90)=meanPupilLightReflexIncongruent;
            tableToBeWritten(:,91)=meanPupilLightReflexAnimal;
            tableToBeWritten(:,92)=meanPupilLightReflexObject;
            tableToBeWritten(:,93)=meanPupilLightReflexWellCateg;
            tableToBeWritten(:,94)=meanPupilLightReflexBadCateg;
            tableToBeWritten(:,95)=meanPupilLightReflexRecognized;
            tableToBeWritten(:,96)=meanPupilLightReflexForgotten;
            tableToBeWritten(:,97)=meanPupilLightReflexRecognizedR;
            tableToBeWritten(:,98)=meanPupilLightReflexRecognizedK;
            tableToBeWritten(:,99)=meanPupilLightReflexRecognizedG;
            tableToBeWritten(:,100)=meanPupilLightReflexForgottenK;
            tableToBeWritten(:,101)=meanPupilLightReflexForgottenG;

            colNames9={'Duration of response', ...
            'Duration of response CONG', 'Duration of response INCONG', 'Duration of response ANI', 'Duration of response OBJ',...
            'Duration of response WELL CATEG', 'Duration of response BAD CATEG',...
            'Duration of response RECOG', 'Duration of response FORGOT'...
            'Duration of response RECOG R', 'Duration of response RECOG K', 'Duration of response RECOG G',...
            'Duration of response FORGOT K', 'Duration of response FORGOT G' };  
            tableToBeWritten(:,102)=durationOfResponse;
            tableToBeWritten(:,103)=durationOfResponseCongruent;
            tableToBeWritten(:,104)=durationOfResponseIncongruent;
            tableToBeWritten(:,105)=durationOfResponseAnimal;
            tableToBeWritten(:,106)=durationOfResponseObject;
            tableToBeWritten(:,107)=durationOfResponseWellCateg;
            tableToBeWritten(:,108)=durationOfResponseBadCateg;
            tableToBeWritten(:,109)=durationOfResponseRecognized;
            tableToBeWritten(:,110)=durationOfResponseForgotten;
            tableToBeWritten(:,111)=durationOfResponseRecognizedR;
            tableToBeWritten(:,112)=durationOfResponseRecognizedK;
            tableToBeWritten(:,113)=durationOfResponseRecognizedG;
            tableToBeWritten(:,114)=durationOfResponseForgottenK;
            tableToBeWritten(:,115)=durationOfResponseForgottenG;

            colNames10={'Peak to baseline latency', ...
            'Peak to baseline latency CONG', 'Peak to baseline latency INCONG', 'Peak to baseline latency ANI', 'Peak to baseline latency OBJ',...
            'Peak to baseline latency WELL CATEG', 'Peak to baseline latency BAD CATEG',...
            'Peak to baseline latency RECOG', 'Peak to baseline latency FORGOT'...
            'Peak to baseline latency RECOG R', 'Peak to baseline latency RECOG K', 'Peak to baseline latency RECOG G',...
            'Peak to baseline latency FORGOT K', 'Peak to baseline latency FORGOT G' };  
            tableToBeWritten(:,116)=peakToBaselineLatency;
            tableToBeWritten(:,117)=peakToBaselineLatencyCongruent;
            tableToBeWritten(:,118)=peakToBaselineLatencyIncongruent;
            tableToBeWritten(:,119)=peakToBaselineLatencyAnimal;
            tableToBeWritten(:,120)=peakToBaselineLatencyObject;
            tableToBeWritten(:,121)=peakToBaselineLatencyWellCateg;  
            tableToBeWritten(:,122)=peakToBaselineLatencyBadCateg;
            tableToBeWritten(:,123)=peakToBaselineLatencyRecognized;
            tableToBeWritten(:,124)=peakToBaselineLatencyForgotten;
            tableToBeWritten(:,125)=peakToBaselineLatencyRecognizedR;
            tableToBeWritten(:,126)=peakToBaselineLatencyRecognizedK;
            tableToBeWritten(:,127)=peakToBaselineLatencyRecognizedG;
            tableToBeWritten(:,128)=peakToBaselineLatencyForgottenK;
            tableToBeWritten(:,129)=peakToBaselineLatencyForgottenG;

            colNames11={'Recognition rate', ...
            'Recognition rate CONG', 'Recognition rate INCONG', 'Recognition rate ANI', 'Recognition rate OBJ' };  
            tableToBeWritten(:,130)=recognitionRatesConditionAllForEachParticipant;
            tableToBeWritten(:,131)=recognitionRatesConditionCongruentForEachParticipant;
            tableToBeWritten(:,132)=recognitionRatesConditionIncongruentForEachParticipant;
            tableToBeWritten(:,133)=recognitionRatesConditionAnimalForEachParticipant;
            tableToBeWritten(:,134)=recognitionRatesConditionObjectForEachParticipant;

            colNames12={'Mean response time', ...
            'Mean response time CONG', 'Mean response time INCONG', 'Mean response time ANI', 'Mean response time OBJ',...
            'Mean response time WELL CATEG', 'Mean response time BAD CATEG',...
            'Mean response time RECOG', 'Mean response time FORGOT'...
            'Mean response time RECOG R', 'Mean response time RECOG K', 'Mean response time RECOG G',...
            'Mean response time FORGOT K', 'Mean response time FORGOT G' };  
            tableToBeWritten(:,135)=meanRT;
            tableToBeWritten(:,136)=meanRTCongruent;
            tableToBeWritten(:,137)=meanRTIncongruent;
            tableToBeWritten(:,138)=meanRTAnimal;
            tableToBeWritten(:,139)=meanRTObject;
            tableToBeWritten(:,140)=meanRTWellCateg;
            tableToBeWritten(:,141)=meanRTBadCateg;
            tableToBeWritten(:,142)=meanRTRecognized;
            tableToBeWritten(:,143)=meanRTForgotten;
            tableToBeWritten(:,144)=meanRTRecognizedR;
            tableToBeWritten(:,145)=meanRTRecognizedK;
            tableToBeWritten(:,146)=meanRTRecognizedG;
            tableToBeWritten(:,147)=meanRTForgottenK;
            tableToBeWritten(:,148)=meanRTForgottenG;
            
            colNames13={'Categorization rate', ...
            'Categorization rate CONG', 'Categorization rate INCONG', 'Categorization rate ANI', 'Categorization rate OBJ',...
            'Categorization rate RECOG', 'Categorization rate FORGOT'...
            'Categorization rate RECOG R', 'Categorization rate RECOG K', 'Categorization rate RECOG G',...
            'Categorization rate FORGOT K', 'Categorization rate FORGOT G' };  
            tableToBeWritten(:,149)=categorizationRatesConditionAllForEachParticipant;
            tableToBeWritten(:,150)=categorizationRatesConditionCongruentForEachParticipant;
            tableToBeWritten(:,151)=categorizationRatesConditionIncongruentForEachParticipant;
            tableToBeWritten(:,152)=categorizationRatesConditionAnimalForEachParticipant;
            tableToBeWritten(:,153)=categorizationRatesConditionObjectForEachParticipant;
            tableToBeWritten(:,154)=categorizationRatesConditionRecognizedForEachParticipant;
            tableToBeWritten(:,155)=categorizationRatesConditionForgottenForEachParticipant;
            tableToBeWritten(:,156)=categorizationRatesConditionRecognizedRForEachParticipant;
            tableToBeWritten(:,157)=categorizationRatesConditionRecognizedKForEachParticipant;
            tableToBeWritten(:,158)=categorizationRatesConditionRecognizedGForEachParticipant;
            tableToBeWritten(:,159)=categorizationRatesConditionForgottenKForEachParticipant;
            tableToBeWritten(:,160)=categorizationRatesConditionForgottenGForEachParticipant;

            colNames14={'Baseline value', ...
            'Baseline value CONG', 'Baseline value INCONG', 'Baseline value ANI', 'Baseline value OBJ',...
            'Baseline value WELL CATEG', 'Baseline value BAD CATEG',...
            'Baseline value RECOG', 'Baseline value FORGOT'...
            'Baseline value RECOG R', 'Baseline value RECOG K', 'Baseline value RECOG G',...
            'Baseline value FORGOT K', 'Baseline value FORGOT G' };  
            tableToBeWritten(:,161)=meanBaselineValue;
            tableToBeWritten(:,162)=meanBaselineValueCongruent;
            tableToBeWritten(:,163)=meanBaselineValueIncongruent;
            tableToBeWritten(:,164)=meanBaselineValueAnimal;
            tableToBeWritten(:,165)=meanBaselineValueObject;
            tableToBeWritten(:,166)=meanBaselineValueWellCateg;  
            tableToBeWritten(:,167)=meanBaselineValueBadCateg;
            tableToBeWritten(:,168)=meanBaselineValueRecognized;
            tableToBeWritten(:,169)=meanBaselineValueForgotten;
            tableToBeWritten(:,170)=meanBaselineValueRecognizedR;
            tableToBeWritten(:,171)=meanBaselineValueRecognizedK;
            tableToBeWritten(:,172)=meanBaselineValueRecognizedG;
            tableToBeWritten(:,173)=meanBaselineValueForgottenK;
            tableToBeWritten(:,174)=meanBaselineValueForgottenG;
            
            colNames15={'Correct R', 'Correct K', 'Correct G', 'Incorrect K', 'Incorrect G'};
            tableToBeWritten(:,175)=numberOfCorrectR;
            tableToBeWritten(:,176)=numberOfCorrectK;
            tableToBeWritten(:,177)=numberOfCorrectG;
            tableToBeWritten(:,178)=numberOfIncorrectK;
            tableToBeWritten(:,179)=numberOfIncorrectG;

            columnNames=[colNames1, colNames2, colNames3, colNames4, colNames5, colNames6, colNames7, colNames8, colNames9, colNames10, colNames11,  colNames12,  colNames13, colNames14, colNames15];
            T = array2table(tableToBeWritten, 'VariableNames', columnNames);
            fileName=strcat("categorisationResult.xlsx");
            
            upperDir=fileparts(app.appDir);
            
            %We add the folders containing the functions to the path
            cd(strcat(upperDir, filesep, 'outputFiles'));
            writetable(T,fileName);
           
            
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        

        function results = exportDataForTrialWiseGLManalysis(app)
            
        upperDir=fileparts(app.appDir);
        sourceFile = strcat(upperDir, filesep, 'participantAge.xlsx');

                    
        data=readtable(sourceFile);
        dataTableSubjectName=table2array(data(:,1));
        dataTableAge=table2array(data(:,4));
        
            
        %We get the chosen tab
        chosenTab=string(app.TabGroup.SelectedTab.Title); 
        indexSignalType=find(chosenTab==app.signalTypes); 
        
        iterator=0;
        for noSubject=1:numel(app.everyTrial)
            
            sub=string(app.ListBox.Items{noSubject});
            ageBuffer=dataTableAge(find(dataTableSubjectName==sub));
                
            for noTrial=1:numel(app.everyTrial{noSubject}) 
                iterator=iterator+1;
                
                subjectCode{iterator}=sub;
                subjectNumber{iterator}=noSubject;
                group{iterator}=string(app.ListBox.Items{noSubject}(3));
                age{iterator}=ageBuffer;
                %%%%%%%%%%%%%%%%
                %Initialization%
                %%%%%%%%%%%%%%%%
                
                %PEAK AMPLITUDE
                dilationAmplitude{iterator}=NaN;
                %REFLEXIVE CONSTRICTION
                pupilLightReflexAmplitude{iterator}=NaN;
                %LATENCY TO DILATION PEAK
                latencyToDilationPeak{iterator}=NaN;
                %LATENCY TO CONSTRICTION PEAK
                latencyToConstrictionPeak{iterator}=NaN;
                %DILATION VELOCITY
                dilationVelocity{iterator}=NaN;
                %MEAN DILATION (=area under the curve)
                meanDilation{iterator}=NaN;
                %MEAN REFLEXIVE CONSTRICTION (=area under the curve, for one second)
                meanPupilLightReflex{iterator}=NaN;
                %RETURN TO BASELINE
                durationOfResponse{iterator}=NaN;
                peakToBaselineLatency{iterator}=NaN;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
                %Computation of trial-wise metrics%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                currentTrial=app.everyTrial{noSubject}(noTrial);
                cleanSignal=currentTrial.cleanSignalDiameterMinusBaseline(:,indexSignalType);
                            
                bufferSignalPositive = zeros(size(cleanSignal));
                bufferSignalNegative=bufferSignalPositive;
                
                cleanSignalPositive(cleanSignal > 0) = cleanSignal(cleanSignal > 0);
                cleanSignalNegative(cleanSignal < 0) = cleanSignal(cleanSignal < 0);
                
                %clean signal is detrended so maybe we should take the raw
                %signal instead.
%                 baselineOnset=currentTrial.onsetBaseline;
%                 baselineOffset=currentTrial.onset; %because onsent = stimulus onset = baseline offset
%                 baselineDiameter=app.everyRawSignalDiameter( baselineOnset:baselineOffset ,  indexSignalType);
        
                baselineDiameter{iterator} =  currentTrial.undetrendedBaseline(indexSignalType);
                [dilationAmplitude{iterator} indexDilationAmplitude{iterator}]             = max(cleanSignal) ;
                [pupilLightReflexAmplitude{iterator} indexConstrictionAmplitude{iterator}] = min(cleanSignalNegative(1:app.samplingFrequency)) ;
                latencyToDilationPeak{iterator}                                                   = indexDilationAmplitude{iterator}/app.samplingFrequency;
                latencyToConstrictionPeak{iterator}                                               = indexConstrictionAmplitude{iterator}/app.samplingFrequency;
                dilationVelocity{iterator}                                                        = dilationAmplitude{iterator}/latencyToDilationPeak{iterator};
                meanDilation{iterator}                                                            = mean(cleanSignalPositive);
                meanPupilLightReflex{iterator}                                                    = mean(cleanSignalNegative(1:app.samplingFrequency));
                
                
                if ~isempty(find(cleanSignal(indexDilationAmplitude{iterator}+1:end) <= 5, 1, 'first')+indexDilationAmplitude{iterator})
                    returnToBaselineIndex{iterator}=find(cleanSignal(indexDilationAmplitude{iterator}+1:end) <= 5, 1, 'first')+indexDilationAmplitude{iterator};
                else
                    returnToBaselineIndex{iterator}=NaN;
                end
                durationOfResponse{iterator}=returnToBaselineIndex{iterator}/app.samplingFrequency;
                peakToBaselineLatency{iterator}=(returnToBaselineIndex{iterator}-indexDilationAmplitude{iterator})/app.samplingFrequency;
                
                %%%%%%%%%%%%%%%%%%
                %Trial conditions%    
                %%%%%%%%%%%%%%%%%%
                
                %behavior
                recognition{iterator}=string(currentTrial.isRecognized);
                if recognition{iterator}=="R"
                    recognitionBinary{iterator}=1;
                else 
                    recognitionBinary{iterator}=0;
                end
                confidence{iterator}=string(currentTrial.trustLevel);
                categorizationResult{iterator}=string(currentTrial.categorizationResult);
                responseTime{iterator}=currentTrial.responseTime;
                recognitionRate{iterator}=app.recognitionRates{noSubject}(1);

                %stimulus characteristics
                foreground{iterator}=string(currentTrial.foreground);
                background{iterator}=string(currentTrial.background);
                congruence{iterator}=string(currentTrial.congruence);
                
                imageID{iterator}=string(currentTrial.imageID);
                set{iterator}=string(currentTrial.set);
                
                trialValidity{iterator}=app.isThisTrialValidOrNot{noSubject}(noTrial);
                

            end
        
        end


            tableToBeWritten(:,1)  = subjectNumber;
            tableToBeWritten(:,2)  = subjectCode;
            tableToBeWritten(:,3)  = group;
            tableToBeWritten(:,4)  = age;
            tableToBeWritten(:,5)  = baselineDiameter;
            tableToBeWritten(:,6)  = dilationAmplitude;
            tableToBeWritten(:,7)  = pupilLightReflexAmplitude;
            tableToBeWritten(:,8)  = latencyToDilationPeak;
            tableToBeWritten(:,9)  = latencyToConstrictionPeak;
            tableToBeWritten(:,10) = dilationVelocity;
            tableToBeWritten(:,11) = meanDilation;
            tableToBeWritten(:,12) = meanPupilLightReflex;
            tableToBeWritten(:,13) = durationOfResponse;
            tableToBeWritten(:,14) = peakToBaselineLatency;
            tableToBeWritten(:,15) = recognition;
            tableToBeWritten(:,16) = recognitionBinary;
            tableToBeWritten(:,17) = confidence;
            tableToBeWritten(:,18) = categorizationResult;
            tableToBeWritten(:,19) = responseTime;
            tableToBeWritten(:,20) = foreground;
            tableToBeWritten(:,21) = background;
            tableToBeWritten(:,22) = congruence;
            tableToBeWritten(:,23) = imageID;
            tableToBeWritten(:,24) = set;
            tableToBeWritten(:,25) = trialValidity;
            tableToBeWritten(:,26) = recognitionRate;


            columnNames={'subjectNumber', 'subjectCode', 'group', 'age'...
                         'baselineDiameter', 'dilationAmplitude', 'pupilLightReflexAmplitude',...
                         'latencyToDilationPeak', 'latencyToConstrictionPeak',...
                         'dilationVelocity', 'meanDilation', 'meanPupilLightReflex', 'durationOfResponse', 'peakToBaselineLatency',...
                         'recognition', 'recognition (recon=1, forgot=0)','confidence', 'categorizationResult', 'responseTime', 'foreground', 'background', 'congruence', 'imageID', 'set', 'trialValidity', 'recognitionRate'};
            
            T = array2table(tableToBeWritten, 'VariableNames', columnNames);
            fileName=strcat("trialWiseGLMM.xlsx");
            cd(strcat(upperDir, filesep, 'outputFiles'));
            writetable(T,fileName);
        
        end
        
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

        function results = exportSignalAvailiblityToTXT(app)

          
        %We get the chosen tab
        chosenTab=string(app.TabGroup.SelectedTab.Title); 
        indexSignalType=find(chosenTab==app.signalTypes); 
        
        
        [nParticipant nSignalTypes nConditions]=size(app.howManyTrialsHaveBeenUsedToComputeMedian);
        
        trialThreshold=app.trialThreshold;
        chanceLevel=app.chanceLevel;
        
        for noSubject=1:nParticipant
            
            subjectCode{noSubject}=string(app.ListBox.Items{noSubject});
            recognitionRate{noSubject}=app.recognitionRates{noSubject}(1);
            group{noSubject}=string(app.ListBox.Items{noSubject}(3));

            for noCondition=1:nConditions
                
                numberOfValidTrials{noSubject,noCondition}=app.howManyTrialsHaveBeenUsedToComputeMedian(noSubject,indexSignalType,noCondition);
                
            end
            
        end
        
        subjectCode=[subjectCode, {'valid trials :', 'valid trials Y :', 'valid trials O :', 'valid participants :', 'valid participants Y :', 'valid participants O :'}];
        tableToBeWritten(:,1) = subjectCode;
        tableToBeWritten(1:nParticipant,2) = group;
        tableToBeWritten(1:nParticipant,3) = recognitionRate;
        
        recognitionRateArray=cell2mat(recognitionRate);
        recognitionRateArrayYOUNG=recognitionRateArray(find(app.whosYoung));
        recognitionRateArrayOLD=recognitionRateArray(find(app.whosOld));

        numberOfParticipantAboveCL=sum(recognitionRateArray>=chanceLevel);
        numberOfParticipantAboveCL_YOUNG=sum(recognitionRateArrayYOUNG>=chanceLevel);
        numberOfParticipantAboveCL_OLD=sum(recognitionRateArrayOLD>=chanceLevel);


        for noCondition=1:nConditions
            
            noCondition;
            tableToBeWritten(1:nParticipant,noCondition+3)=numberOfValidTrials(:,noCondition);
            
            sumOfNumberOfValidTrials{noCondition}=sum(cell2mat(tableToBeWritten(1:nParticipant,noCondition+3)));
            sumOfNumberOfValidTrials_YOUNG{noCondition}=sum(cell2mat(tableToBeWritten(find(app.whosYoung),noCondition+3)));
            sumOfNumberOfValidTrials_OLD{noCondition}=sum(cell2mat(tableToBeWritten(find(app.whosOld),noCondition+3)));
            
            tableToBeWritten(nParticipant+1,noCondition+3)= sumOfNumberOfValidTrials(noCondition);
            tableToBeWritten(nParticipant+2,noCondition+3)= sumOfNumberOfValidTrials_YOUNG(noCondition);
            tableToBeWritten(nParticipant+3,noCondition+3)= sumOfNumberOfValidTrials_OLD(noCondition);
            
            
            sumOfNumberOfValidParticipants{noCondition}=sum(cell2mat(tableToBeWritten(1:nParticipant,noCondition+3))>trialThreshold);
            sumOfNumberOfValidParticipants_YOUNG{noCondition}=sum(cell2mat(tableToBeWritten(find(app.whosYoung),noCondition+3))>trialThreshold);
            sumOfNumberOfValidParticipants_OLD{noCondition}=sum(cell2mat(tableToBeWritten(find(app.whosOld),noCondition+3))>trialThreshold);


            tableToBeWritten(nParticipant+4,noCondition+3)= sumOfNumberOfValidParticipants(noCondition);
            tableToBeWritten(nParticipant+5,noCondition+3)= sumOfNumberOfValidParticipants_YOUNG(noCondition);
            tableToBeWritten(nParticipant+6,noCondition+3)= sumOfNumberOfValidParticipants_OLD(noCondition);
        end
        
        tableToBeWritten(nParticipant+1,3) = {numberOfParticipantAboveCL};
        tableToBeWritten(nParticipant+2,3) = {numberOfParticipantAboveCL_YOUNG};
        tableToBeWritten(nParticipant+3,3) = {numberOfParticipantAboveCL_OLD};
        
        columnNames={'subjectCode', 'group'...
                     'recognitionRate'};
        columnNames=[columnNames app.conditions];
        
        T = array2table(tableToBeWritten, 'VariableNames', columnNames);
        fileName=strcat("trialValidity.xlsx");
    
        upperDir=fileparts(app.appDir);
        
        %We add the folders containing the functions to the path
        cd(strcat(upperDir, filesep, 'outputFiles'));        
        writetable(T,fileName);
   
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

        
    function results = computeBaselinePupilDiameterForEachCdts(app)
            
        %We get the chosen tab
        chosenTab=string(app.TabGroup.SelectedTab.Title); 
        indexSignalType=find(chosenTab==app.signalTypes); 
        
            indexConditionAll=find("All"==app.conditions);
            
            indexConditionCongruent=find("Congruent"==app.conditions);
            indexConditionIncongruent=find("Incongruent"==app.conditions);
            
            indexConditionAnimal=find("Animal"==app.conditions);
            indexConditionObject=find("Object"==app.conditions);
            
            indexConditionWellCategorized=find("Well categorized"==app.conditions);
            indexConditionBadlyCategorized=find("Badly categorized"==app.conditions);
            
            indexConditionRecognizedAboveCL=find("Recognized"==app.conditions);
            indexConditionForgottenAboveCL=find("Forgotten"==app.conditions);
            
                indexConditionRecognizedAboveCL_R=find("Recognized R"==app.conditions);
                indexConditionRecognizedAboveCL_K=find("Recognized K"==app.conditions);
                indexConditionRecognizedAboveCL_G=find("Recognized G"==app.conditions);
                
                indexConditionForgottenAboveCL_K=find("Forgotten K"==app.conditions);
                indexConditionForgottenAboveCL_G=find("Forgotten G"==app.conditions);
            
            indexConditionRecognizedBelowCL=find("Recognized below CL"==app.conditions);
            indexConditionForgottenBelowCL=find("Forgotten below CL"==app.conditions);
            
                indexConditionRecognizedBelowCL_R=find("Recognized R below CL"==app.conditions);
                indexConditionRecognizedBelowCL_K=find("Recognized K below CL"==app.conditions);
                indexConditionRecognizedBelowCL_G=find("Recognized G below CL"==app.conditions);
                
                indexConditionForgottenBelowCL_K=find("Forgotten K below CL"==app.conditions);
                indexConditionForgottenBelowCL_G=find("Forgotten G below CL"==app.conditions);
                
        
        for indexSubject=1:app.nParticipant

            %We get all his trials
            allTrialsOfSelectedSubject = app.everyTrial{indexSubject};
              
            %ALL
            allBaseline=NaN;
            %CONGRUENT
            congruentBaseline=NaN;
            %INCONGRUENT
            incongruentBaseline=NaN;
            %ANIMAL
            animalBaseline=NaN;
            %OBJECT
            objectBaseline=NaN;
            %RECOGNIZED ABOVE CHANCE LEVEL
            recognizedBaseline=NaN;
            %FORGOTTEN ABOVE CHANCE LEVEL
            notRecognizedBaseline=NaN;
            %WELL CATEGORIZED
            goodCategBaseline=NaN;
            %BADLY CATEGORIZED
            badCategBaseline=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R ABOVE CHANCE LEVEL
            recognizedBaseline_R=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            recognizedBaseline_K=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            recognizedBaseline_G=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            notRecognizedBaseline_K=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            notRecognizedBaseline_G=NaN;
            %%
            %BELOW CHANCE LEVEL
            %RECOGNIZED BELOW CHANCE LEVEL
            recognizedBaseline_BelowCL=NaN;
            %FORGOTTEN BELOW CHANCE LEVEL
            notRecognizedBaseline_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R BELOW CHANCE LEVEL
            recognizedBaseline_R_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            recognizedBaseline_K_BelowCL=NaN;
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            recognizedBaseline_G_BelowCL=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            notRecognizedBaseline_K_BelowCL=NaN;
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            notRecognizedBaseline_G_BelowCL=NaN;
            
            %all
            cptBaselineAll=0;
            %congruence/incongruence
            cptBaselineCong=0;
            cptBaselineIncong=0;
            %foreground
            cptBaselineAni=0;
            cptBaselineObj=0;
            %categorization    
            cptBaselineGoodCateg=0;
            cptBaselineBadCateg=0;
            %recognition above chance level
            cptBaselineRecog=0;
                cptBaselineRecog_R=0;
                cptBaselineRecog_K=0;
                cptBaselineRecog_G=0;
            cptBaselineNotRecog=0;
                cptBaselineNotRecog_K=0;
                cptBaselineNotRecog_G=0;
            %recognition below chance level
            cptBaselineRecog_BelowCL=0;
                cptBaselineRecog_R_BelowCL=0;
                cptBaselineRecog_K_BelowCL=0;
                cptBaselineRecog_G_BelowCL=0;
            cptBaselineNotRecog_BelowCL=0;
                cptBaselineNotRecog_K_BelowCL=0;
                cptBaselineNotRecog_G_BelowCL=0;   
    
                
                %SIGNALS
                rawSignalDiameter=app.everyRawSignalDiameter{indexSubject}(:,indexSignalType);
                
                if (indexSignalType==7) || (indexSignalType==8)
                    [cleanSignalButNotDetrended trash]=processingMethod(rawSignalDiameter, app.hampelParameters, "surface", "noDetrend");
                else
                    [cleanSignalButNotDetrended trash]=processingMethod(rawSignalDiameter, app.hampelParameters, "diameter", "noDetrend");
                end
                
                    clear trash
                
            for cptTrial=1:72
                %We take the trial n°cptTrial of subject indexSubject
                currentBufferTrial=allTrialsOfSelectedSubject(cptTrial);
                
                %We extract the different conditions
                conditionForeground = currentBufferTrial.foreground;
                conditionCongruence = currentBufferTrial.congruence;
                conditionRecognition = currentBufferTrial.isRecognized;
                conditionRecognitionTrustLevel = currentBufferTrial.trustLevel;
                conditionCategorization=currentBufferTrial.categorizationResult;
                                    
                allBaseline(cptTrial)=nanmedian(cleanSignalButNotDetrended(currentBufferTrial.onsetBaseline:currentBufferTrial.onset));
                app.everyTrial{indexSubject}(cptTrial).undetrendedBaseline(indexSignalType)=allBaseline(cptTrial);

                    if conditionForeground == 'ani' %animal
                        cptBaselineAni=cptBaselineAni+1;
                        animalBaseline(cptBaselineAni)=allBaseline(cptTrial);
                    else                            %object
                        cptBaselineObj=cptBaselineObj+1;
                        objectBaseline(cptBaselineObj)=allBaseline(cptTrial);
                    end
                    
                    if conditionCongruence == 'C'   %congruent
                        cptBaselineCong=cptBaselineCong+1;
                        congruentBaseline(cptBaselineCong)=allBaseline(cptTrial);
                    else                            %incongruent
                        cptBaselineIncong=cptBaselineIncong+1;
                        incongruentBaseline(cptBaselineIncong)=allBaseline(cptTrial);
                    end
                    
                    if conditionCategorization == "G" %well categorized
                        cptBaselineGoodCateg=cptBaselineGoodCateg+1;
                        goodCategBaseline(cptBaselineGoodCateg)=allBaseline(cptTrial);
                    else                              %badly categorized
                        cptBaselineBadCateg=cptBaselineBadCateg+1;
                        badCategBaseline(cptBaselineBadCateg)=allBaseline(cptTrial);
                    end
                    
                    if app.recognitionRates{indexSubject}(1) > app.chanceLevel
                            if conditionRecognition == 'R'   %recognized
                                cptBaselineRecog=cptBaselineRecog+1;
                                recognizedBaseline(cptBaselineRecog)=allBaseline(cptTrial);
                                
                                    if conditionRecognitionTrustLevel=="R"
                                        cptBaselineRecog_R=cptBaselineRecog_R+1;
                                        recognizedBaseline_R(cptBaselineRecog_R)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="K"
                                        cptBaselineRecog_K=cptBaselineRecog_K+1;
                                        recognizedBaseline_K(cptBaselineRecog_K)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptBaselineRecog_G=cptBaselineRecog_G+1;
                                        recognizedBaseline_G(cptBaselineRecog_G)=allBaseline(cptTrial);  
                                    end
                                    
                            else                            %not recognized
                                cptBaselineNotRecog=cptBaselineNotRecog+1;
                                notRecognizedBaseline(cptBaselineNotRecog)=allBaseline(cptTrial);
                                                    
                                    if conditionRecognitionTrustLevel=="K"
                                        cptBaselineNotRecog_K=cptBaselineNotRecog_K+1;
                                        notRecognizedBaseline_K(cptBaselineNotRecog_K)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptBaselineNotRecog_G=cptBaselineNotRecog_G+1;
                                        notRecognizedBaseline_G(cptBaselineNotRecog_G)=allBaseline(cptTrial);  
                                    end
                            
                            end
                    
                    else
        
                    
                            if conditionRecognition == 'R'   %recognized
                                cptBaselineRecog_BelowCL=cptBaselineRecog_BelowCL+1;
                                recognizedBaseline_BelowCL(cptBaselineRecog_BelowCL)=allBaseline(cptTrial);
                                
                                    if conditionRecognitionTrustLevel=="R"
                                        cptBaselineRecog_R_BelowCL=cptBaselineRecog_R_BelowCL+1;
                                        recognizedBaseline_R_BelowCL(cptBaselineRecog_R_BelowCL)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="K"
                                        cptBaselineRecog_K_BelowCL=cptBaselineRecog_K_BelowCL+1;
                                        recognizedBaseline_K_BelowCL(cptBaselineRecog_K_BelowCL)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptBaselineRecog_G_BelowCL=cptBaselineRecog_G_BelowCL+1;
                                        recognizedBaseline_G_BelowCL(cptBaselineRecog_G_BelowCL)=allBaseline(cptTrial);  
                                    end
                                    
                            else                            %not recognized
                                cptBaselineNotRecog_BelowCL=cptBaselineNotRecog_BelowCL+1;
                                notRecognizedBaseline_BelowCL(cptBaselineNotRecog_BelowCL)=allBaseline(cptTrial);
                                                    
                                    if conditionRecognitionTrustLevel=="K"
                                        cptBaselineNotRecog_K_BelowCL=cptBaselineNotRecog_K_BelowCL+1;
                                        notRecognizedBaseline_K_BelowCL(cptBaselineNotRecog_K_BelowCL)=allBaseline(cptTrial);  
                                        
                                    elseif conditionRecognitionTrustLevel=="G"
                                        cptBaselineNotRecog_G_BelowCL=cptBaselineNotRecog_G_BelowCL+1;
                                        notRecognizedBaseline_G_BelowCL(cptBaselineNotRecog_G_BelowCL)=allBaseline(cptTrial);  
                                    end
                            
                            end
                    
                    
                    end


            end


            %ALL
            app.meanBaselineValue(indexSubject,indexConditionAll)=nanmedian(allBaseline);
            
            %CONGRUENT
            app.meanBaselineValue(indexSubject,indexConditionCongruent)=nanmedian(congruentBaseline);
    
            %INCONGRUENT
            app.meanBaselineValue(indexSubject,indexConditionIncongruent)=nanmedian(incongruentBaseline);
    
            %ANIMAL
            app.meanBaselineValue(indexSubject,indexConditionAnimal)=nanmedian(animalBaseline);
    
            %OBJECT
            app.meanBaselineValue(indexSubject,indexConditionObject)=nanmedian(objectBaseline);
    
            %RECOGNIZED ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedAboveCL)=nanmedian(recognizedBaseline);
            
            %FORGOTTEN ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenAboveCL)=nanmedian(notRecognizedBaseline);
            
            %WELL CATEGORIZED
            app.meanBaselineValue(indexSubject,indexConditionWellCategorized)=nanmedian(goodCategBaseline);
            
            %BADLY CATEGORIZED
            app.meanBaselineValue(indexSubject,indexConditionBadlyCategorized)=nanmedian(badCategBaseline);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedAboveCL_R)=nanmedian(recognizedBaseline_R);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedAboveCL_K)=nanmedian(recognizedBaseline_K);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedAboveCL_G)=nanmedian(recognizedBaseline_G);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenAboveCL_K)=nanmedian(notRecognizedBaseline_K);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G ABOVE CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenAboveCL_G)=nanmedian(notRecognizedBaseline_G);
            
            %%
            %BELOW CHANCE LEVEL
            %%
            %RECOGNIZED BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedBelowCL)=nanmedian(recognizedBaseline_BelowCL);
    
            %FORGOTTEN BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenBelowCL)=nanmedian(notRecognizedBaseline_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE R BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedBelowCL_R)=nanmedian(recognizedBaseline_R_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedBelowCL_K)=nanmedian(recognizedBaseline_K_BelowCL);
            
            %RECOGNIZED WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionRecognizedBelowCL_G)=nanmedian(recognizedBaseline_G_BelowCL);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE K BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenBelowCL_K)=nanmedian(notRecognizedBaseline_K_BelowCL);
            
            %FORGOTTEN WITH LEVEL OF CONFIDENCE G BELOW CHANCE LEVEL
            app.meanBaselineValue(indexSubject,indexConditionForgottenBelowCL_G)=nanmedian(notRecognizedBaseline_G_BelowCL);
        
        end
        
        app.meanBaselineValueYoung=app.meanBaselineValue(find(app.whosYoung),:);
        app.meanBaselineValueOld=app.meanBaselineValue(find(app.whosOld),:);


        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = computeRecollectionAndFamiliarityRate(app)
            

            for indexSubject=1:app.nParticipant
            cptRecog_R=0;
            cptRecog_K=0;
            cptRecog_G=0;
            cptForgot_K=0;
            cptForgot_G=0;            
            allTrialsOfSelectedSubject = app.everyTrial{indexSubject};
    
                for cptTrial=1:72
                    %We take the trial n°cptTrial of subject indexSubject
                    
                    currentBufferTrial=allTrialsOfSelectedSubject(cptTrial);
                    
                    %We extract the different conditions
    
                    conditionRecognition = currentBufferTrial.isRecognized;
                    conditionRecognitionTrustLevel = currentBufferTrial.trustLevel;
    
                    if conditionRecognition == 'R'   %recognized
    
                        if conditionRecognitionTrustLevel=="R"
                                cptRecog_R=cptRecog_R+1;
                                
                        elseif conditionRecognitionTrustLevel=="K"
                                cptRecog_K=cptRecog_K+1;
                                
                        elseif conditionRecognitionTrustLevel=="G"
                                cptRecog_G=cptRecog_G+1;
                        end
                        
                    elseif conditionRecognition == 'N'
                        
                        if conditionRecognitionTrustLevel=="K"
                                cptForgot_K=cptForgot_K+1;
                                
                        elseif conditionRecognitionTrustLevel=="G"
                                cptForgot_G=cptForgot_G+1;
                        end
                        
                    end
                end
            
                app.numberOfRKG{indexSubject}=[cptRecog_R, cptRecog_K, cptRecog_G, cptForgot_K, cptForgot_G];
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = behaviorStatistics(app)
            
            f1 = figure; % creating figure
            set(f1, 'Toolbar', 'none', 'Menubar', 'none');
            f1pos = get(f1, 'Position'); % determine position
            framesize = [400 400]; % set framesize
            [hdls] = StartLoadingSpinner('parent', f1, 'size', '32px', 'position', [(f1pos(3)/2)-framesize(1)/2 (f1pos(4)/2)-framesize(1)/2], 'framesize', framesize, 'text', 'Behavioral analysis'); % start spinner

            %Response time
            %We first compute the mean times of response. 
            computeMeanResponseTimes(app);
            
            %%Recognition rate (already computed in
            %%computeMedianFromSelectedTrials)
            computeRecollectionAndFamiliarityRate(app)
            
            %Categorization rate
            %We first compute the categorization times. 
            computeCategorizationTimes(app);
            
            StopLoadingSpinner('hdls', hdls, 'successtext', 'Behavioral analysis finished ! Time to work now !'); % stop spinner
            pause(1)
            close(f1)
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     

        function results = computeBaselinePupilDiameterForEachTrial(app, indexSubject)
            
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
        
            %Selected processing method
            methodName=string(app.ProcessingmethodButtonGroup.SelectedObject.Text);
            detrend="noDetrend";
            
            
            allBaseline=NaN;
            cptBaselineAll=0;
                
       
            %SIGNALS
                                
            timeBlockOfOfSelectedSubject=app.everyTimeBlock{indexSubject};
            rawSignalDiameterBlock=app.everyRawSignalDiameterBlock{indexSubject};
            rawSignalBlockIndexes=app.everyRawSignalBlockIndexes{indexSubject};
            
            rawSignalDiameter=app.everyRawSignalDiameter{indexSubject};
            
            for block=1:4
                bufferRawBlock=rawSignalDiameterBlock{block};
                bufferRawSignalType=bufferRawBlock(:,indexSignalType);
                cleanSignalDiameterBlock{block}=NaN(size(bufferRawSignalType));


                switch methodName
                                    
                    case("Custom")
                    %Process the everyTrial structure via a custom method

                    case("Default")
                    %Process the everyTrial structure via default method 
                        
                        %%IF WE WANT MEAN X DIAMETER, MEAN Y DIAMETER, LEFT SURFACE
                        %%AREA, AND RIGHT SURFACE AREA TO TAKE INTO ACCOUNT PROCESSED
                        %%SIGNALS
                            switch indexSignalType
                                
                                case 1
                                    [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 2
                                    [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 3
                                    [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 4
                                    [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                case 5 %mean x diameter
                                    if app.CleanedCheckBox.Value
                                        bufferRawSignalType1=bufferRawBlock(:,1);
                                        bufferRawSignalType2=bufferRawBlock(:,3);

                                        [cleanSignalDiameterBlock1{block} ~]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} ~]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= nanmean([cleanSignalDiameterBlock1{block}, cleanSignalDiameterBlock2{block}],2);
                                       

                                    else
                                        [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                    end
                                case 6 %mean y diameter
                                    if app.CleanedCheckBox_2.Value
                                        bufferRawSignalType1=bufferRawBlock(:,2);
                                        bufferRawSignalType2=bufferRawBlock(:,4);

                                        [cleanSignalDiameterBlock1{block} ~]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} ~]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= nanmean([cleanSignalDiameterBlock1{block}, cleanSignalDiameterBlock2{block}],2);

                                    else
                                        [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "diameter", detrend);
                                    end
                                case 7 %left surface area
                                    if app.CleanedCheckBox_3.Value
                                        bufferRawSignalType1=bufferRawBlock(:,1);
                                        bufferRawSignalType2=bufferRawBlock(:,2);

                                        [cleanSignalDiameterBlock1{block} ~]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} ~]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= 0.25*cleanSignalDiameterBlock1{block} .* cleanSignalDiameterBlock2{block} * pi;
                                        
                                    else
                                        [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "surface", detrend);
                                    end
                                case 8 %right surface area
                                    if app.CleanedCheckBox_4.Value
                                        bufferRawSignalType1=bufferRawBlock(:,3);
                                        bufferRawSignalType2=bufferRawBlock(:,4);

                                        [cleanSignalDiameterBlock1{block} indexModifiedPointsBlock1{block} ]=processingMethod(bufferRawSignalType1,app.hampelParameters, "diameter", detrend);
                                        [cleanSignalDiameterBlock2{block} indexModifiedPointsBlock2{block} ]=processingMethod(bufferRawSignalType2,app.hampelParameters, "diameter", detrend);
                                        cleanSignalDiameterBlock{block}= 0.25*cleanSignalDiameterBlock1{block} .* cleanSignalDiameterBlock2{block} * pi;
                                       
                                    else
                                        [cleanSignalDiameterBlock{block} ~]=processingMethod(bufferRawSignalType,app.hampelParameters, "surface", detrend);
                                    end
                            end
                        
                    case("No processing") 
                    %Does nothing
                            [cleanSignalDiameterBlock{block} ~]=noProcessing(bufferRawSignalType,app.hampelParameters);
                end
          
            end
                    
                    
            cleanSignalDiameterUndetrended=rawSignalDiameter(:,indexSignalType);
            
            cleanSignalDiameterUndetrended( rawSignalBlockIndexes(1):rawSignalBlockIndexes(2)) = cleanSignalDiameterBlock{1};
            cleanSignalDiameterUndetrended( rawSignalBlockIndexes(3):rawSignalBlockIndexes(4)) = cleanSignalDiameterBlock{2};
            cleanSignalDiameterUndetrended( rawSignalBlockIndexes(5):rawSignalBlockIndexes(6)) = cleanSignalDiameterBlock{3};
            cleanSignalDiameterUndetrended( rawSignalBlockIndexes(7):rawSignalBlockIndexes(8)) = cleanSignalDiameterBlock{4};
              
            for cptTrial=1:72
                %We take the trial n°cptTrial of subject indexSubject
                app.everyTrial{indexSubject}(cptTrial).cleanSignalBaseline(:,indexSignalType)=cleanSignalDiameterUndetrended(app.everyTrial{indexSubject}(cptTrial).onsetBaseline:app.everyTrial{indexSubject}(cptTrial).onset);
                 
                allBaseline(cptTrial)=nanmedian(app.everyTrial{indexSubject}(cptTrial).cleanSignalBaseline(:,indexSignalType));
                
                app.everyTrial{indexSubject}(cptTrial).undetrendedBaseline(indexSignalType)=allBaseline(cptTrial);
  
            end
                    

        end
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%                %%%%%%%%%%%%%%%%%%%
%%%%%%%%%   END PUBLIC   %%%%%%%%%%%%%%%%%%%
%%%%%%%%%                %%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%             %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%   PRIVATE   %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%             %%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % initialize some values
            close all
            
            %Place yourself in the folder where visualCategorization.mlapp
            %is located
            app.appDir=mfilename('fullpath');
            upperDir=fileparts(fileparts(app.appDir));
            addpath(upperDir);
            %We add the folders containing the functions to the path
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions', filesep, 'bayesFactor-master'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions', filesep, 'dabarplot'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions', filesep, 'daboxplot'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions', filesep, 'daviolinplot'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'functions', filesep, 'sigstar'));
            addpath(strcat(upperDir, filesep, 'code', filesep, 'icons'));

            %Duration of trials 
            app.trialDuration=5;
            
            %We initialize the path to an empty set
            app.pathOfEachSubject=[];
            
            %We initialize the confidence interval
            app.sigmaForConfidenceInterval=1; %1 for 68% CI (=SEM), 1.96 for 95% CI
            
            %We define the sampling frequency and the different kinds of
            %signal we can manipulate
            app.samplingFrequency = 300;
            app.signalTypes=["Left x diameter","Left y diameter",...
                             "Right x diameter","Right y diameter",...
                             "Mean x diameter","Mean y diameter",...
                             "Left surface area","Right surface area"];
            
            app.conditions=["All", "Congruent", "Incongruent",...
                            "Animal", "Object" , "Well categorized", "Badly categorized",...
                            "Recognized" , "Forgotten" , "Recognized R", "Recognized K", "Recognized G", "Forgotten K", "Forgotten G",...
                            "Recognized below CL", "Forgotten below CL", "Recognized R below CL", "Recognized K below CL", "Recognized G below CL", "Forgotten K below CL", "Forgotten G below CL",...
                            ];
            
            app.colors=[ [ 227, 154, 59]; %copper
                         [167, 112, 194]; %purple
                         [227, 59, 98  ]; %salmon
                         [59, 227, 205 ]; %cyan
                         ]/255;

            
            app.testedConditions=["Congruent v. Incongruent", "Animal v. Object", "Well v. Badly categorized",...
                                  "Recognized v. Forgotten (above CL)",...
                                  "Recognized R v. Recognized K (above CL)", "Recognized R v. Recognized G (above CL)", "Recognized K v. Recognized G (above CL)",...
                                  "Forgotten K v. Forgotten G (above CL)",...
                                  "Recognized v. Forgotten (below CL)",...
                                  "Recognized R v. Recognized K (below CL)", "Recognized R v. Recognized G (below CL)", "Recognized K v. Recognized G (below CL)",...
                                  "Forgotten K v. Forgotten G (below CL)",...
                                  "Recognized (above CL) v. Recognized (below CL)", "Forgotten (above CL) v. Forgotten (below CL)"...
                                  ];
         
%             app.allTailHypothesis="both";
%             app.testedConditionsTailHypothesis=["both", "both", "both",...
%                                                 "both",...
%                                                 "both", "both", "both",...
%                                                 "both",...
%                                                 "both",...
%                                                 "both", "both", "both",...
%                                                 "both",...
%                                                 "both", "both"...
%                                                 ];
                        
            app.allTailHypothesis="right"; %Young > Old
            app.testedConditionsTailHypothesis=["left", "right", "both",... Congruent < Incogruent ; Animal > Furniture ; Correctly != Incorrectly categorized
                                                "right",... Recognized > Forgotten
                                                "both", "both", "both",... Remember != Know ; Remember != Guess ; Know != Guess
                                                "both",... %No specific hypothesis, thus H0 = identical and H1 = different (no direction)
                                                "both",...
                                                "both", "both", "both",...
                                                "both",...
                                                "both", "both"...
                                                ];
%             
            app.testedConditionsTailHypothesisPermutest=app.testedConditionsTailHypothesis;
                app.testedConditionsTailHypothesisPermutest(app.testedConditionsTailHypothesisPermutest=="both")="true";
                app.testedConditionsTailHypothesisPermutest(app.testedConditionsTailHypothesisPermutest~="true")="false";

            
            app.metricsName=[ "Dilation amplitude",
                          "PLR amplitude",
                          "Latency to dilation peak",
                          "Latency to constriction peak",
                          "Dilation velocity",
                          "Mean dilation",
                          "Mean PLR",
                          "Duration of response",
                          "Peak to baseline latency",
                          "Baseline"
                          ];


            app.chanceLevel=0.59;
            app.trialThreshold=7;
            
            app.numberOfClusters=2;
            app.numberOfPermutations=3*10^3;
            app.pThresholdCluster=0.025;
            app.timeWindowForHandmadeCBPT=1;
            
            app.interpolationThreshold=0.25;

            %We set the default filtering parameters for the Hampel method
            app.hampelParameters.adjacentSampleHamp1=30;
            app.hampelParameters.nSigma1=2;
            app.hampelParameters.nTimesHampel1=1;
            app.hampelParameters.adjacentSampleHamp2=30;
            app.hampelParameters.nSigma2=2;
            app.hampelParameters.nTimesHampel2=1;
            app.hampelParameters.adjacentSampleInterp=10;
            
            %Path for saving and loading the saves
            upperDir=fileparts(app.appDir);
            
            %We add the folders containing the functions to the path
            
            app.savePath=strcat(upperDir, filesep, 'save');
%             %%LOAD STATE ?
%             loadState(app);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            %We get the path through a UI
            folderParticipantPath=uigetdir(strcat(fileparts(fileparts(app.appDir)), filesep, 'data', filesep, 'valid'));
            if folderParticipantPath==0
                return
            end
            
            %We extract all subject codes
            app.PathEditField.Value=folderParticipantPath;
            folderParticipant = subjectCodeAndFolder( folderParticipantPath );
    
            %We get the number of participant
            nParticipant=length(folderParticipant);
%             cptYoung=0;
%             cptOld=0;
 
            %We load the data from the .csv exported from meyeAnalysis
            wb1= waitbar(0,'Loading trials... 0%');
            for noSubject=1:nParticipant
                warning('off')

                %We get the subject code so we can choose the ones we want
                %to study 
                subjectCode(noSubject,:)=folderParticipant(noSubject).subjectCode;
                itemsSubjectCode{noSubject}=subjectCode(noSubject,:); %app.ListBox.Items requires a cell vector
                
                %We concatenate some folder names so we can have the full
                %path
                participantPath = [folderParticipant(noSubject).subjectCodeFolder, filesep, subjectCode(noSubject,:)];
                [currentTrials currentTimeBlock currentRawSignalDiameterBlock currentRawSignalBlockIndexes... 
                    currentRawTime currentRawSignalGaze currentRawSignalDiameter currentRawLabel]=singleSubjectAnalysisApp(participantPath, app); 
                pathOfEachSubject(noSubject)=string(participantPath);
                
                %We get the index of who's young and who's old
                if (subjectCode(noSubject,3) =='j')
                    whosYoung(noSubject)=1;
                    whosOld(noSubject)=0;
                else
                    whosYoung(noSubject)=0;
                    whosOld(noSubject)=1;
                end
                   
                %This is the raw trials we display by default
                everyTrial{noSubject}=currentTrials;
                %These serve to compute the processed signals since we use
                %the whole block and not the trials themselves
                everyTimeBlock{noSubject}=currentTimeBlock;
                everyRawSignalDiameterBlock{noSubject}=currentRawSignalDiameterBlock;
                everyRawSignalBlockIndexes{noSubject}=currentRawSignalBlockIndexes;
                everyRawTime{noSubject}= currentRawTime;
                everyRawSignalGaze{noSubject}=currentRawSignalGaze;
                everyRawSignalDiameter{noSubject}=currentRawSignalDiameter;
                everyRawLabel{noSubject}=currentRawLabel;
                
%                 selectedTrialsForEachSubjectAndSignalTypes{noSubject}={};
                medianPupilResponses{noSubject}.raw=NaN( length(currentTrials(1).rawSignalDiameter(:,1)) , length(app.signalTypes) ,  length(app.conditions) );
                medianPupilResponses{noSubject}.clean= medianPupilResponses{noSubject}.raw;
                
                app.recognitionRates{noSubject}=[];
                app.isThisTrialValidOrNot{noSubject}=zeros(1,72);
                
                matrixOfDifferencesBetweenConditionsIntraGroup{noSubject}=NaN( length(currentTrials(1).rawSignalDiameter(:,1)) , length(app.signalTypes) ,  length(app.testedConditions) );
                
                waitbar(noSubject/nParticipant, wb1, sprintf("Loading trials...  %d%%", round(noSubject/nParticipant*100)));
            end
            close(wb1)

   
            %We store data within our class "app"
            app.nParticipant=nParticipant;
            app.pathOfEachSubject=pathOfEachSubject;
            app.everyTrial=everyTrial;
            app.nTrials=length(app.everyTrial{1});
            app.everyTimeBlock=everyTimeBlock;
            app.everyRawSignalDiameterBlock=everyRawSignalDiameterBlock;
            app.everyRawSignalBlockIndexes=everyRawSignalBlockIndexes;
            app.everyRawTime=everyRawTime;
            app.everyRawSignalGaze=everyRawSignalGaze;
            app.everyRawSignalDiameter=everyRawSignalDiameter;
            app.everyRawLabel=everyRawLabel;
            app.whosYoung=whosYoung;
            app.whosOld=whosOld;
            app.medianPupilResponses=medianPupilResponses;
            app.meanResponseTimes=NaN(nParticipant ,  length(app.conditions));
            app.significanceInterGroup=NaN( length(currentTrials(1).rawSignalDiameter(:,1)) , length(app.signalTypes) ,  length(app.conditions));
            app.significanceIntraGroupYoung=NaN( length(currentTrials(1).rawSignalDiameter(:,1)) , length(app.signalTypes) ,  length(app.testedConditions));
            app.significanceIntraGroupOld=app.significanceIntraGroupYoung;
            app.significanceInterGroup_ClustersBasedPermutation=app.significanceIntraGroupYoung;
            app.significanceIntraGroupYoung_ClustersBasedPermutation=app.significanceIntraGroupYoung;
            app.significanceIntraGroupOld_ClustersBasedPermutation=app.significanceIntraGroupYoung;
            app.significanceMatrixOfDifferencesIntraGroupYoung=app.significanceIntraGroupYoung;
            app.significanceMatrixOfDifferencesIntraGroupOld=app.significanceIntraGroupYoung;
            app.significanceMatrixOfDifferencesIntraGroupYoung_CBPT=app.significanceIntraGroupYoung;
            app.significanceMatrixOfDifferencesIntraGroupOld_CBPT=app.significanceIntraGroupYoung;
            
            app.matrixOfDifferencesBetweenConditionsIntraGroupYoung={matrixOfDifferencesBetweenConditionsIntraGroup{find(app.whosYoung)}};
            app.matrixOfDifferencesBetweenConditionsIntraGroupOld={matrixOfDifferencesBetweenConditionsIntraGroup{find(app.whosOld)}};
            
            app.ListBox.Items=itemsSubjectCode;
            app.ListBox.Value=itemsSubjectCode{1};
            app.allTrialsForEachSubjectAndSignalTypes=zeros(app.nParticipant, length(app.signalTypes));
            app.multiSelectForEachSubjectAndSignalTypes=zeros(app.nParticipant, length(app.signalTypes));
            app.selectAllForEachSubjectAndSignalTypes=zeros(app.nParticipant, length(app.signalTypes));
            %This array stores the selected trials for every subject
            app.selectedTrialsForEachSubjectAndSignalTypes=cell(app.nParticipant, length(app.signalTypes));
            for cpt1=1:app.nParticipant
                for cpt2=1:length(app.signalTypes)
                    app.selectedTrialsForEachSubjectAndSignalTypes{cpt1,cpt2}={};
                end
            end
            app.howManyTrialsHaveBeenUsedToComputeMedian=zeros(app.nParticipant,length(app.signalTypes),length(app.conditions));
            
            %We display the number of available trials
            value = app.ListBox.Value;
            selectedSubject = string(value);
            indexSubject = find( selectedSubject == string(app.ListBox.Items) );
            for cpt=1:app.nTrials
                itemsTrials{cpt}=num2str(cpt);
            end
            
            %We display no trial once a subject is chosen
            app.ListBox_2.Items=itemsTrials;
            app.ListBox_2.Value={};
            

        end

        % Button pushed function: FilteringparametersButton
        function FilteringparametersButtonPushed(app, event)
%             app.FilteringparametersButton.Enable="off";
            app.dialogBoxAppObject = DialogBox_filterParam(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
%             %SAVE APP STATE HERE ?
%             if ~isempty(app.pathOfEachSubject) % If no path has been chosen then we don't save
%                 saveState(app)
%             end
            
            %DELETE
            delete(app.dialogBoxAppObject)
            delete(app)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %We get the selected subject based on the listbox value
            value = app.ListBox.Value;
            selectedSubject = string(value);
            %We get his index
            indexSubject = find( selectedSubject == string(app.ListBox.Items) );
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            %We display his available trials 
            for cpt=1:length(app.everyTrial{indexSubject})
                itemsTrials{cpt}=num2str(cpt);
            end            
            app.ListBox_2.Items=itemsTrials;
            %The chosen trials are located in
            %"selectedTrialsForEachSubjectAndSignalTypes" that is modified in ListBox_2ValueChanged
            app.ListBox_2.Value=app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType};
            %mettre à jour allTrial et multiSelect checkbox
            
            app.AlltrialsCheckBox.Value=app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            app.MultiselectCheckBox.Value=app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            
            %Ici mettre fonction update plot
            updatePlotTrials(app)
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            
            %We do that in order to not have to do the ctrl + left click
            %anymore 

            %We get the chosen subject by its index
            indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            newValue = app.ListBox_2.Value;
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes);    
            
            %multiSelectForEachSubjectAndSignalTypes allows us to chosse multiple signals
            if app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType) %the user has the choice to select multiple signals at a time
    
                %The new selected value of ListBox2 is appended to the
                %previous values
                %previousValue = event.PreviousValue;
                previousValue = app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType};

                %isThere contains 1 where newValue is, and 0 where newValue
                %isn't
                isThere=ismember(previousValue,newValue);
                %any(there) = 0 if all 0, and 1 if at least one 1
                %(basically a OR gate)
                if any(isThere)
                    %if it's worth 1 (i.e. newValue is already in previousValue), then we remove the value
                    previousValue=previousValue(~isThere);
                else
                    %if it's worth 0 (i.e. newValue is not in
                    %previousValue), then we append newValue to
                    %previousValue
                    previousValue=[previousValue newValue];
                end
            
                %Then we update the chosen trials on the UI
                app.ListBox_2.Value=previousValue;
            
                %And we save the chosen trials
                app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType}=previousValue;
                
            else %By default, the multi select option is set to 0
                %No appending whatsoever, we just have one single value
                %which is newValue
                app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType}=newValue;
            end
            
%             app.AlltrialsCheckBox.Value=app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
%             app.MultiselectCheckBox.Value=app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType);  
            
            %We update the plot here
            updatePlotTrials(app)
            %We update the median plot here
            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);
        end

        % Value changed function: AlltrialsCheckBox
        function AlltrialsCheckBoxValueChanged(app, event)
            %We get the chosen subject
            indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the previous value
            previousValue=app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            
            %We get the new value of AllTrials button (1 if checked, 0
            %otherwise) for this subject and tab
            value = app.AlltrialsCheckBox.Value;
            
            %If checked
            if value
                %We select everything
                app.ListBox_2.Value=app.ListBox_2.Items;
                app.ListBox_2.Value([18 36 54 72])=[];
            else
                %We unselect everything
                app.ListBox_2.Value={};
            end
            
            %We store the status of the chosen trials
            app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType}=app.ListBox_2.Value;
            app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType)=1-previousValue;
            
            %We update the plot here
            updatePlotTrials(app)
            %We update the medians plot here
            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);

        end

        % Button pushed function: RefreshmedianselectedsubButton
        function RefreshmedianselectedsubButtonPushed(app, event)

            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);

        end

        % Callback function
        function IncongruenttrialsCheckBoxValueChanged(app, event)
            value = app.IncongruenttrialsCheckBox.Value;
            
        end

        % Callback function
        function CongruenttrialsCheckBoxValueChanged(app, event)
            value = app.CongruenttrialsCheckBox.Value;

        end

        % Button pushed function: RefreshtrialsselectedsubButton
        function RefreshtrialsselectedsubButtonPushed(app, event)
            updatePlotTrials(app)
            %updatePlotMedians(app)
        end

        % Value changed function: MultiselectCheckBox
        function MultiselectCheckBoxValueChanged(app, event)
           %We get the chosen subject
            indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the previous value
            previousValue=app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType)=1-previousValue;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if ~isempty(app.pathOfEachSubject) % If no path has been chosen then we don't do any of this 
            
                %We change the values of allTrials, multiSelect, and selected
                %trials based on the selected tab            
                %We get the chosen subject
                indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
                %We get the chosen tab
                chosenTab=string(app.TabGroup.SelectedTab.Title); 
                indexSignalType=find(chosenTab==app.signalTypes); 
                
                %We update the chosen trials
                app.ListBox_2.Value=app.selectedTrialsForEachSubjectAndSignalTypes{indexSubject,indexSignalType};
                app.AlltrialsCheckBox.Value=app.allTrialsForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
                app.MultiselectCheckBox.Value=app.multiSelectForEachSubjectAndSignalTypes(indexSubject,indexSignalType);   
                if app.selectAllForEachSubjectAndSignalTypes(indexSubject,indexSignalType)
                    app.Switch.Value = 'Select all';
                else
                    app.Switch.Value = 'Unselect all';
                end

                
            end

        end

        % Button pushed function: 
        % RunINTERgroupanalysiswithselectedtrialsButton
        function RunINTERgroupanalysiswithselectedtrialsButtonPushed(app, event)
            %We compute the different metrics for pupil response
            pupilMetricsComputation(app)
            isItSignificantInterGroupPupilMetrics(app)

            %We turn the medians into 2 means (young and old groupes)
            medians2mean(app);
            
            %Now let's assess the significance 
            isItSignificantInterGroup(app)
            
            %Now let's assess the significance : classical CBPT
            isItSignificantInterGroupClusters(app)
            
            %Now we plot everything, along with the result of the handmade
            %CBPT
            displayResultsInterGroup(app)
            
        end

        % Button pushed function: 
        % RunINTRAgroupanalysiswithselectedtrialsButton
        function RunINTRAgroupanalysiswithselectedtrialsButtonPushed(app, event)
            

            %We turn the medians into 2 means (young and old groups)
            medians2mean(app);
            
            %Now let's assess the significance 
            isItSignificantIntraGroup(app) 
            %Now let's assess the significance : classical CBPT
            isItSignificantIntraGroupClusters(app) 
            
            %We compute the different metrics for pupil response
            pupilMetricsComputation(app)
            isItSignificantIntraGroupPupilMetrics(app)
            
            %Now we plot everything
            displayResultsIntraGroup(app)
           
        end

        % Button pushed function: ProbetheappButton
        function ProbetheappButtonPushed(app, event)
            
            'Pause begins'
            keyboard
            'Pause ends'
            
        end

        % Callback function
        function SaveButtonPushed(app, event)
            %SAVE APP STATE HERE ?
            if ~isempty(app.pathOfEachSubject) % If no path has been chosen then we don't save
                saveState(app)
            end
        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            %%LOAD STATE ?
            loadState(app);
        end

        % Button down function: UIAxes_LDX_1_processed
        function UIAxes_LDX_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app)
        end

        % Button down function: UIAxes_LDY_1_processed
        function UIAxes_LDY_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app)
        end

        % Button down function: UIAxes_RDX_1_processed
        function UIAxes_RDX_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app)            
        end

        % Button down function: UIAxes_RDY_1_processed
        function UIAxes_RDY_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app) 
        end

        % Button down function: UIAxes_MeanX_1_processed
        function UIAxes_MeanX_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app) 
        end

        % Button down function: UIAxes_MeanY_1_processed
        function UIAxes_MeanY_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app) 
        end

        % Button down function: UIAxes_LSurf_1_processed
        function UIAxes_LSurf_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app) 
        end

        % Button down function: UIAxes_RSurf_1_processed
        function UIAxes_RSurf_1_processedButtonDown(app, event)
            interpolationGraphicalInterface(app) 
        end

        % Button pushed function: ResetinterpolationButton
        function ResetinterpolationButtonPushed(app, event)
               
            %We first get the value of the chosen subject
            selectedSubject = app.ListBox.Value;
            %We then take his alphabetical order (i.e. indexSubject) in order to pick the
            %right trial structure located in "everyTrial"
            indexSubject = find( selectedSubject == string(app.ListBox.Items) );
            selectedSubjectTrial=app.everyTrial{indexSubject};
            
            
            %We get the LAST chosen trials
            indexTrials = app.ListBox_2.Value;
            numberOfChosenTrials=length(indexTrials);
            lastChosenTrial=indexTrials{end};
                       
            %We plot the right type of signal (LDx, RDY, etc..)
            %depending on the chosen tab (which is string typed).
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes);   
            
            app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPointsByInterpolation=zeros(size(app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPointsByInterpolation));
            app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPoints=app.everyTrial{indexSubject}(str2num(lastChosenTrial)).indexModifiedPointsByHampel  ;

            %We update the plot here
            updatePlotTrials(app)
            %We update the medians plot here
            computeMedianFromSelectedTrials(app);
            updatePlotMedians(app);
        end

        % Callback function
        function RunINTERgroupanalysiswithchosentrialsclustersButtonPushed(app, event)

            %We compute the different metrics for pupil response
            pupilMetricsComputation(app)
            isItSignificantInterGroupPupilMetrics(app)
        
            %We turn the medians into 2 means (young and old groupes)
            medians2mean(app);
            
            %Now let's assess the significance : cluster
            isItSignificantInterGroupClusters(app)
            
            
            %Now we plot everything
            %displayResultsInterGroupClusters(app)
            displayResultsInterGroup(app)

        end

        % Callback function
        function RunINTRAgroupanalysiswithchosentrialsclustersButtonPushed(app, event)
            %We compute the different metrics for pupil response
            pupilMetricsComputation(app)
            
            %We turn the medians into 2 means (young and old groupes)
            medians2mean(app);
            
            %Now let's assess the significance : cluster
            isItSignificantIntraGroupClusters(app)
            
            %Now we plot everything
            displayResultsIntraGroupClusters(app)
        end

        % Callback function
        function BehavioralstatisticsButtonPushed(app, event)
            
            f1 = figure; % creating figure
            set(f1, 'Toolbar', 'none', 'Menubar', 'none');
            f1pos = get(f1, 'Position'); % determine position
            framesize = [400 400]; % set framesize
            [hdls] = StartLoadingSpinner('parent', f1, 'size', '32px', 'position', [(f1pos(3)/2)-framesize(1)/2 (f1pos(4)/2)-framesize(1)/2], 'framesize', framesize, 'text', 'Behavioral analysis'); % start spinner

            %Response time
            %We first compute the mean times of response. 
            computeMeanResponseTimes(app);
            
            %%Recognition rate (already computed in
            %%computeMedianFromSelectedTrials)
            computeRecollectionAndFamiliarityRate(app)
            
            %Categorization rate
            %We first compute the categorization times. 
            computeCategorizationTimes(app);
            
            StopLoadingSpinner('hdls', hdls, 'successtext', 'Behavioral analysis finished ! Time to work now !'); % stop spinner
            pause(1)
            close(f1)
        end

        % Button pushed function: RefreshmediansallsubsButton
        function RefreshmediansallsubsButtonPushed(app, event)
            wb1= waitbar(0,'Computing the medians for all subjects without changing the selection');

            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the chosen subject
            initialSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            
            for cptSubject=1:app.nParticipant
                app.ListBox.Value=app.ListBox.Items{cptSubject}
                %We update the medians plot here
                computeMedianFromSelectedTrials(app);


                waitbar(cptSubject/app.nParticipant, wb1);


            end
            
            
            %%%RT LOCKED
            if app.RTlockCheckBox.Value
                'r'
            %We first compute the mean times of response. 
            computeMeanResponseTimes(app);
            
            RT=app.meanResponseTimes;
            medianPupilResponses=app.medianPupilResponses;
            
            cutOffIndex=1800+1; %(6s)
                for cpt=1:numel(medianPupilResponses)
                    
                    %We just cut the signal so we can take into account
                    %each TR on the same plot
                    
                    for cptCondition=1:numel(app.conditions)
                
                        maximumTRforOneCondition=max(RT(:,cptCondition));
                        maximumDelayforOneCondition=floor(app.samplingFrequency*maximumTRforOneCondition/1000); 
                        
                        TR=RT(cpt,cptCondition);
                        delay=floor(app.samplingFrequency*TR/1000);
                        if isnan(delay)
                            cptCondition
                            delay=floor(app.samplingFrequency*RT(cpt,1)/1000);
                        end
                        sig=medianPupilResponses{cpt}.clean(:,indexSignalType,cptCondition);
                        
                        shiftToApplyToSignal=maximumDelayforOneCondition-delay;
                        shiftSig=circshift(sig,shiftToApplyToSignal);
                        shiftSig(1:shiftToApplyToSignal)=sig(1);   
                        app.medianPupilResponses{cpt}.clean(:,indexSignalType,cptCondition)=shiftSig;


                end
            end

            end                 
   
            close(wb1)
            app.ListBox.Value=app.ListBox.Items{initialSubject}
            updatePlotMedians(app);


        end

        % Button pushed function: EXPORTTOCSVButton
        function EXPORTTOCSVButtonPushed(app, event)
            behaviorStatistics(app)
            exportDataToFile(app);
        end

        % Value changed function: RTlockCheckBox
        function RTlockCheckBoxValueChanged(app, event)
            value = app.RTlockCheckBox.Value;


        end

        % Value changed function: CleanedCheckBox
        function CleanedCheckBoxValueChanged(app, event)
            value = app.CleanedCheckBox.Value;
            
         
        end

        % Button pushed function: FULLANALYSISButton
        function FULLANALYSISButtonPushed(app, event)
            tic
            
            app.RunINTERgroupanalysiswithselectedtrialsButtonPushed
            app.RunINTRAgroupanalysiswithselectedtrialsButtonPushed
            
            toc
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            
            %We get the chosen subject
            indexSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            
            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the previous value
            previousValue=app.selectAllForEachSubjectAndSignalTypes(indexSubject,indexSignalType);
            app.selectAllForEachSubjectAndSignalTypes(indexSubject,indexSignalType)=1-previousValue;


            %If checked
            if strcmp(value, 'Select all')
                app.AlltrialsCheckBox.Value=1;               
                app.ListBox_2.Value=app.ListBox_2.Items;
                app.ListBox_2.Value([18 36 54 72])=[];
                %We select everything (all subject and all trials)
                for cptParticipant=1:app.nParticipant
                    app.allTrialsForEachSubjectAndSignalTypes(cptParticipant,indexSignalType)=1;
                    app.selectedTrialsForEachSubjectAndSignalTypes{cptParticipant,indexSignalType}=app.ListBox_2.Value;
                end
            else
                app.AlltrialsCheckBox.Value=0;               
                app.ListBox_2.Value={};
                %We unselect everything (all subject and all trials)
                for cptParticipant=1:app.nParticipant
                    app.allTrialsForEachSubjectAndSignalTypes(cptParticipant,indexSignalType)=0;
                    app.selectedTrialsForEachSubjectAndSignalTypes{cptParticipant,indexSignalType}=app.ListBox_2.Value;
                end
            end
            
            wb1= waitbar(0,'Selecting all subject and computing their median response');

            for cptSubject=1:app.nParticipant
                app.ListBox.Value=app.ListBox.Items{cptSubject};
                %We update the plot here
                updatePlotTrials(app)
                %We update the medians plot here
                computeMedianFromSelectedTrials(app);
                updatePlotMedians(app);
                waitbar(cptSubject/app.nParticipant, wb1);

                                
            end

            close(wb1)
            
            app.ListBox.Value=app.ListBox.Items{indexSubject};
            
            
        end

        % Button pushed function: RefreshtrialsallsubsButton
        function RefreshtrialsallsubsButtonPushed(app, event)
             wb1= waitbar(0,'Computing the trials for all subjects without changing the selection');

            %We get the chosen tab
            chosenTab=string(app.TabGroup.SelectedTab.Title); 
            indexSignalType=find(chosenTab==app.signalTypes); 
            
            %We get the chosen subject
            initialSubject = find( app.ListBox.Value == string(app.ListBox.Items) );
            
            for cptSubject=1:app.nParticipant
                app.ListBox.Value=app.ListBox.Items{cptSubject}
                %We update the medians plot here
                updatePlotTrials(app)
%                 updatePlotMedians(app)                
                app.RefreshmedianselectedsubButtonPushed
            
                waitbar(cptSubject/app.nParticipant, wb1);

            end
   
            close(wb1)
            app.ListBox.Value = app.ListBox.Items{initialSubject}


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'dossier.png';
            app.LoadButton.Text = 'Load';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'No subjects loaded'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'No subjects loaded'};
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'No trials available'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {'No trials available'};
            app.SubjectcodesLabel = uilabel(app.UIFigure);
            app.SubjectcodesLabel.Text = 'Subject codes';
            app.TrialsLabel = uilabel(app.UIFigure);
            app.TrialsLabel.Text = 'Trials';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Editable = 'off';
            app.AlltrialsCheckBox = uicheckbox(app.UIFigure);
            app.AlltrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlltrialsCheckBoxValueChanged, true);
            app.AlltrialsCheckBox.Text = 'All trials';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LeftxdiameterTab = uitab(app.TabGroup);
            app.LeftxdiameterTab.Title = 'Left x diameter';
            app.UIAxes_LDX_2_raw = uiaxes(app.LeftxdiameterTab);
            title(app.UIAxes_LDX_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_LDX_2_raw, 'time (s)')
            ylabel(app.UIAxes_LDX_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_LDX_2_raw, 'Z')
            app.UIAxes_LDX_1_raw = uiaxes(app.LeftxdiameterTab);
            title(app.UIAxes_LDX_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_LDX_1_raw, 'time (s)')
            ylabel(app.UIAxes_LDX_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_LDX_1_raw, 'Z')
            app.UIAxes_LDX_1_processed = uiaxes(app.LeftxdiameterTab);
            title(app.UIAxes_LDX_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_LDX_1_processed, 'time (s)')
            ylabel(app.UIAxes_LDX_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_LDX_1_processed, 'Z')
            app.UIAxes_LDX_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LDX_1_processedButtonDown, true);
            app.UIAxes_LDX_2_processed = uiaxes(app.LeftxdiameterTab);
            title(app.UIAxes_LDX_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_LDX_2_processed, 'time (s)')
            ylabel(app.UIAxes_LDX_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_LDX_2_processed, 'Z')
            app.LeftydiameterTab = uitab(app.TabGroup);
            app.LeftydiameterTab.Title = 'Left y diameter';
            app.UIAxes_LDY_2_raw = uiaxes(app.LeftydiameterTab);
            title(app.UIAxes_LDY_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_LDY_2_raw, 'time (s)')
            ylabel(app.UIAxes_LDY_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_LDY_2_raw, 'Z')
            app.UIAxes_LDY_1_raw = uiaxes(app.LeftydiameterTab);
            title(app.UIAxes_LDY_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_LDY_1_raw, 'time (s)')
            ylabel(app.UIAxes_LDY_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_LDY_1_raw, 'Z')
            app.UIAxes_LDY_1_processed = uiaxes(app.LeftydiameterTab);
            title(app.UIAxes_LDY_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_LDY_1_processed, 'time (s)')
            ylabel(app.UIAxes_LDY_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_LDY_1_processed, 'Z')
            app.UIAxes_LDY_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LDY_1_processedButtonDown, true);
            app.UIAxes_LDY_2_processed = uiaxes(app.LeftydiameterTab);
            title(app.UIAxes_LDY_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_LDY_2_processed, 'time (s)')
            ylabel(app.UIAxes_LDY_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_LDY_2_processed, 'Z')
            app.RightxdiameterTab = uitab(app.TabGroup);
            app.RightxdiameterTab.Title = 'Right x diameter';
            app.UIAxes_RDX_2_raw = uiaxes(app.RightxdiameterTab);
            title(app.UIAxes_RDX_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_RDX_2_raw, 'time (s)')
            ylabel(app.UIAxes_RDX_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_RDX_2_raw, 'Z')
            app.UIAxes_RDX_1_raw = uiaxes(app.RightxdiameterTab);
            title(app.UIAxes_RDX_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_RDX_1_raw, 'time (s)')
            ylabel(app.UIAxes_RDX_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_RDX_1_raw, 'Z')
            app.UIAxes_RDX_1_processed = uiaxes(app.RightxdiameterTab);
            title(app.UIAxes_RDX_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_RDX_1_processed, 'time (s)')
            ylabel(app.UIAxes_RDX_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_RDX_1_processed, 'Z')
            app.UIAxes_RDX_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_RDX_1_processedButtonDown, true);
            app.UIAxes_RDX_2_processed = uiaxes(app.RightxdiameterTab);
            title(app.UIAxes_RDX_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_RDX_2_processed, 'time (s)')
            ylabel(app.UIAxes_RDX_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_RDX_2_processed, 'Z')
            app.RightydiameterTab = uitab(app.TabGroup);
            app.RightydiameterTab.Title = 'Right y diameter';
            app.UIAxes_RDY_2_raw = uiaxes(app.RightydiameterTab);
            title(app.UIAxes_RDY_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_RDY_2_raw, 'time (s)')
            ylabel(app.UIAxes_RDY_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_RDY_2_raw, 'Z')
            app.UIAxes_RDY_1_raw = uiaxes(app.RightydiameterTab);
            title(app.UIAxes_RDY_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_RDY_1_raw, 'time (s)')
            ylabel(app.UIAxes_RDY_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_RDY_1_raw, 'Z')
            app.UIAxes_RDY_1_processed = uiaxes(app.RightydiameterTab);
            title(app.UIAxes_RDY_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_RDY_1_processed, 'time (s)')
            ylabel(app.UIAxes_RDY_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_RDY_1_processed, 'Z')
            app.UIAxes_RDY_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_RDY_1_processedButtonDown, true);
            app.UIAxes_RDY_2_processed = uiaxes(app.RightydiameterTab);
            title(app.UIAxes_RDY_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_RDY_2_processed, 'time (s)')
            ylabel(app.UIAxes_RDY_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_RDY_2_processed, 'Z')
            app.MeanxdiameterTab = uitab(app.TabGroup);
            app.MeanxdiameterTab.Title = 'Mean x diameter';
            app.UIAxes_MeanX_2_raw = uiaxes(app.MeanxdiameterTab);
            title(app.UIAxes_MeanX_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_MeanX_2_raw, 'time (s)')
            ylabel(app.UIAxes_MeanX_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_MeanX_2_raw, 'Z')
            app.UIAxes_MeanX_1_raw = uiaxes(app.MeanxdiameterTab);
            title(app.UIAxes_MeanX_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_MeanX_1_raw, 'time (s)')
            ylabel(app.UIAxes_MeanX_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_MeanX_1_raw, 'Z')
            app.UIAxes_MeanX_1_processed = uiaxes(app.MeanxdiameterTab);
            title(app.UIAxes_MeanX_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_MeanX_1_processed, 'time (s)')
            ylabel(app.UIAxes_MeanX_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_MeanX_1_processed, 'Z')
            app.UIAxes_MeanX_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_MeanX_1_processedButtonDown, true);
            app.UIAxes_MeanX_2_processed = uiaxes(app.MeanxdiameterTab);
            title(app.UIAxes_MeanX_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_MeanX_2_processed, 'time (s)')
            ylabel(app.UIAxes_MeanX_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_MeanX_2_processed, 'Z')
            app.CleanedCheckBox = uicheckbox(app.MeanxdiameterTab);
            app.CleanedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CleanedCheckBoxValueChanged, true);
            app.CleanedCheckBox.Text = 'Cleaned';
            app.MeanydiameterTab = uitab(app.TabGroup);
            app.MeanydiameterTab.Title = 'Mean y diameter';
            app.UIAxes_MeanY_2_raw = uiaxes(app.MeanydiameterTab);
            title(app.UIAxes_MeanY_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_MeanY_2_raw, 'time (s)')
            ylabel(app.UIAxes_MeanY_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_MeanY_2_raw, 'Z')
            app.UIAxes_MeanY_1_raw = uiaxes(app.MeanydiameterTab);
            title(app.UIAxes_MeanY_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_MeanY_1_raw, 'time (s)')
            ylabel(app.UIAxes_MeanY_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_MeanY_1_raw, 'Z')
            app.UIAxes_MeanY_1_processed = uiaxes(app.MeanydiameterTab);
            title(app.UIAxes_MeanY_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_MeanY_1_processed, 'time (s)')
            ylabel(app.UIAxes_MeanY_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_MeanY_1_processed, 'Z')
            app.UIAxes_MeanY_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_MeanY_1_processedButtonDown, true);
            app.UIAxes_MeanY_2_processed = uiaxes(app.MeanydiameterTab);
            title(app.UIAxes_MeanY_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_MeanY_2_processed, 'time (s)')
            ylabel(app.UIAxes_MeanY_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_MeanY_2_processed, 'Z')
            app.CleanedCheckBox_2 = uicheckbox(app.MeanydiameterTab);
            app.CleanedCheckBox_2.Text = 'Cleaned';
            app.LeftsurfaceareaTab = uitab(app.TabGroup);
            app.LeftsurfaceareaTab.Title = 'Left surface area';
            app.UIAxes_LSurf_2_raw = uiaxes(app.LeftsurfaceareaTab);
            title(app.UIAxes_LSurf_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_LSurf_2_raw, 'time (s)')
            ylabel(app.UIAxes_LSurf_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_LSurf_2_raw, 'Z')
            app.UIAxes_LSurf_1_raw = uiaxes(app.LeftsurfaceareaTab);
            title(app.UIAxes_LSurf_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_LSurf_1_raw, 'time (s)')
            ylabel(app.UIAxes_LSurf_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_LSurf_1_raw, 'Z')
            app.UIAxes_LSurf_1_processed = uiaxes(app.LeftsurfaceareaTab);
            title(app.UIAxes_LSurf_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_LSurf_1_processed, 'time (s)')
            ylabel(app.UIAxes_LSurf_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_LSurf_1_processed, 'Z')
            app.UIAxes_LSurf_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LSurf_1_processedButtonDown, true);
            app.UIAxes_LSurf_2_processed = uiaxes(app.LeftsurfaceareaTab);
            title(app.UIAxes_LSurf_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_LSurf_2_processed, 'time (s)')
            ylabel(app.UIAxes_LSurf_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_LSurf_2_processed, 'Z')
            app.CleanedCheckBox_3 = uicheckbox(app.LeftsurfaceareaTab);
            app.CleanedCheckBox_3.Text = 'Cleaned';
            app.RightsurfaceareaTab = uitab(app.TabGroup);
            app.RightsurfaceareaTab.Title = 'Right surface area';
            app.UIAxes_RSurf_2_raw = uiaxes(app.RightsurfaceareaTab);
            title(app.UIAxes_RSurf_2_raw, 'Median of subjects and the average across participants - raw')
            xlabel(app.UIAxes_RSurf_2_raw, 'time (s)')
            ylabel(app.UIAxes_RSurf_2_raw, 'arbitrary units')
            zlabel(app.UIAxes_RSurf_2_raw, 'Z')
            app.UIAxes_RSurf_1_raw = uiaxes(app.RightsurfaceareaTab);
            title(app.UIAxes_RSurf_1_raw, 'Trials of the selected subject and its median - raw')
            xlabel(app.UIAxes_RSurf_1_raw, 'time (s)')
            ylabel(app.UIAxes_RSurf_1_raw, 'arbitrary units')
            zlabel(app.UIAxes_RSurf_1_raw, 'Z')
            app.UIAxes_RSurf_1_processed = uiaxes(app.RightsurfaceareaTab);
            title(app.UIAxes_RSurf_1_processed, 'Trials of the selected subject and its median - processed')
            xlabel(app.UIAxes_RSurf_1_processed, 'time (s)')
            ylabel(app.UIAxes_RSurf_1_processed, 'arbitrary units')
            zlabel(app.UIAxes_RSurf_1_processed, 'Z')
            app.UIAxes_RSurf_1_processed.ButtonDownFcn = createCallbackFcn(app, @UIAxes_RSurf_1_processedButtonDown, true);
            app.UIAxes_RSurf_2_processed = uiaxes(app.RightsurfaceareaTab);
            title(app.UIAxes_RSurf_2_processed, 'Median of subjects and the average across participants - processed')
            xlabel(app.UIAxes_RSurf_2_processed, 'time (s)')
            ylabel(app.UIAxes_RSurf_2_processed, 'arbitrary units')
            zlabel(app.UIAxes_RSurf_2_processed, 'Z')
            app.CleanedCheckBox_4 = uicheckbox(app.RightsurfaceareaTab);
            app.CleanedCheckBox_4.Text = 'Cleaned';
            app.MultiselectCheckBox = uicheckbox(app.UIFigure);
            app.MultiselectCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiselectCheckBoxValueChanged, true);
            app.MultiselectCheckBox.Text = 'Multiselect';
            app.ProcessingmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessingmethodButtonGroup.Title = 'Processing method';
            app.NoprocessingButton = uiradiobutton(app.ProcessingmethodButtonGroup);
            app.NoprocessingButton.Text = 'No processing';
            app.DefaultButton = uiradiobutton(app.ProcessingmethodButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.CustomButton = uiradiobutton(app.ProcessingmethodButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.FilteringparametersButton = uibutton(app.UIFigure, 'push');
            app.FilteringparametersButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringparametersButtonPushed, true);
            app.FilteringparametersButton.Icon = 'settings.png';
            app.FilteringparametersButton.Text = 'Filtering parameters';
            app.RunINTERgroupanalysiswithselectedtrialsButton = uibutton(app.UIFigure, 'push');
            app.RunINTERgroupanalysiswithselectedtrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RunINTERgroupanalysiswithselectedtrialsButtonPushed, true);
            app.RunINTERgroupanalysiswithselectedtrialsButton.Text = 'Run INTER-group analysis with selected trials';
            app.RefreshmedianselectedsubButton = uibutton(app.UIFigure, 'push');
            app.RefreshmedianselectedsubButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshmedianselectedsubButtonPushed, true);
            app.RefreshmedianselectedsubButton.Icon = 'Refresh.png';
            app.RefreshmedianselectedsubButton.Text = {'Refresh median'; '(selected sub)'};
            app.RefreshtrialsselectedsubButton = uibutton(app.UIFigure, 'push');
            app.RefreshtrialsselectedsubButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshtrialsselectedsubButtonPushed, true);
            app.RefreshtrialsselectedsubButton.Icon = 'Refresh.png';
            app.RefreshtrialsselectedsubButton.Text = {'Refresh trials'; '(selected sub)'};
            app.RunINTRAgroupanalysiswithselectedtrialsButton = uibutton(app.UIFigure, 'push');
            app.RunINTRAgroupanalysiswithselectedtrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RunINTRAgroupanalysiswithselectedtrialsButtonPushed, true);
            app.RunINTRAgroupanalysiswithselectedtrialsButton.Text = 'Run INTRA-group analysis with selected trials';
            app.ProbetheappButton = uibutton(app.UIFigure, 'push');
            app.ProbetheappButton.ButtonPushedFcn = createCallbackFcn(app, @ProbetheappButtonPushed, true);
            app.ProbetheappButton.Icon = 'probe.png';
            app.ProbetheappButton.Text = 'Probe the app';
            app.ResetinterpolationButton = uibutton(app.UIFigure, 'push');
            app.ResetinterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetinterpolationButtonPushed, true);
            app.ResetinterpolationButton.Icon = 'curve2.png';
            app.ResetinterpolationButton.Text = 'Reset interpolation';
            app.RefreshmediansallsubsButton = uibutton(app.UIFigure, 'push');
            app.RefreshmediansallsubsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshmediansallsubsButtonPushed, true);
            app.RefreshmediansallsubsButton.Icon = 'Refresh.png';
            app.RefreshmediansallsubsButton.Text = {'Refresh medians'; '(all subs)'};
            app.EXPORTTOCSVButton = uibutton(app.UIFigure, 'push');
            app.EXPORTTOCSVButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTTOCSVButtonPushed, true);
            app.EXPORTTOCSVButton.Icon = 'write.png';
            app.EXPORTTOCSVButton.Text = 'EXPORT TO CSV';
            app.RTlockCheckBox = uicheckbox(app.UIFigure);
            app.RTlockCheckBox.ValueChangedFcn = createCallbackFcn(app, @RTlockCheckBoxValueChanged, true);
            app.RTlockCheckBox.Text = 'RT lock';
            app.FULLANALYSISButton = uibutton(app.UIFigure, 'push');
            app.FULLANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @FULLANALYSISButtonPushed, true);
            app.FULLANALYSISButton.Icon = 'computerscience.png';
            app.FULLANALYSISButton.Text = 'FULL ANALYSIS';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Unselect all', 'Select all'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Unselect all';
            app.TEPRButtonGroup = uibuttongroup(app.UIFigure);
            app.TEPRButtonGroup.Title = 'TEPR';
            app.AbsoluteButton = uiradiobutton(app.TEPRButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.AbsoluteButton.Value = true;
            app.ZscoreButton = uiradiobutton(app.TEPRButtonGroup);
            app.ZscoreButton.Text = 'Z-score';
            app.PercentageButton = uiradiobutton(app.TEPRButtonGroup);
            app.PercentageButton.Text = 'Percentage';
            app.RefreshtrialsallsubsButton = uibutton(app.UIFigure, 'push');
            app.RefreshtrialsallsubsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshtrialsallsubsButtonPushed, true);
            app.RefreshtrialsallsubsButton.Icon = 'Refresh.png';
            app.RefreshtrialsallsubsButton.Text = {'Refresh trials'; '(all subs)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualCategorization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end